% description: Copyright 2016-2020 Luca Della Santina
classdef ObjectFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DetectTab                       matlab.ui.container.Tab
        btnOpenExp                      matlab.ui.control.Button
        btnFindObjects                  matlab.ui.control.Button
        btnValidateObjects              matlab.ui.control.Button
        ObjectDetectionSettingsPanel    matlab.ui.container.Panel
        MaximumLabel                    matlab.ui.control.Label
        MaxSizeXY                       matlab.ui.control.NumericEditField
        Watershed                       matlab.ui.control.CheckBox
        MinimumLabel                    matlab.ui.control.Label
        MinSizeXY                       matlab.ui.control.NumericEditField
        BlockSearch                     matlab.ui.control.CheckBox
        Sphericity                      matlab.ui.control.CheckBox
        ExpectedsizeofobjectsdiametermLabel  matlab.ui.control.Label
        MinObjectIntensityLabel         matlab.ui.control.Label
        MinIntensity                    matlab.ui.control.Spinner
        SingleZDotCut                   matlab.ui.control.CheckBox
        EdgeDotCut                      matlab.ui.control.CheckBox
        xyStableDots                    matlab.ui.control.CheckBox
        ExcludeObjectsLabel             matlab.ui.control.Label
        LocalNoise                      matlab.ui.control.CheckBox
        DetectionAlgorithmLabel         matlab.ui.control.Label
        cmbSegmentation                 matlab.ui.control.DropDown
        BackgroundlevelLabel            matlab.ui.control.Label
        cmbNoiseEstimator               matlab.ui.control.DropDown
        VoxelConnectivityLabel          matlab.ui.control.Label
        cmbConnectedVoxN                matlab.ui.control.DropDown
        btnCalcDiameter                 matlab.ui.control.Button
        btnFilterObjs                   matlab.ui.control.Button
        cmbPreset                       matlab.ui.control.DropDown
        btnPresetRename                 matlab.ui.control.Button
        btnPresetDelete                 matlab.ui.control.Button
        btnPresetImport                 matlab.ui.control.Button
        btnPresetExport                 matlab.ui.control.Button
        btnPresetCreate                 matlab.ui.control.Button
        CurrentObjectsPanel             matlab.ui.container.Panel
        cmbObjsDetected                 matlab.ui.control.DropDown
        cmbSkel                         matlab.ui.control.DropDown
        cmbMask                         matlab.ui.control.DropDown
        cmbPost                         matlab.ui.control.DropDown
        btnRenameObjects                matlab.ui.control.Button
        btnCopyObjects                  matlab.ui.control.Button
        btnDeleteObjects                matlab.ui.control.Button
        btnSkelRename                   matlab.ui.control.Button
        btnSkelDelete                   matlab.ui.control.Button
        btnSkelImport                   matlab.ui.control.Button
        btnImgInfo                      matlab.ui.control.Button
        btnMaskInfo                     matlab.ui.control.Button
        btnImgRender                    matlab.ui.control.Button
        btnMaskRender                   matlab.ui.control.Button
        btnImgMIP                       matlab.ui.control.Button
        btnMaskMIP                      matlab.ui.control.Button
        imgObjects                      matlab.ui.control.Image
        imgPost                         matlab.ui.control.Image
        imgMask                         matlab.ui.control.Image
        imgSkel                         matlab.ui.control.Image
        btnMaskLink                     matlab.ui.control.Button
        btnPlotLocations                matlab.ui.control.Button
        btnPlotSkeleton                 matlab.ui.control.Button
        btnChooseImages                 matlab.ui.control.Button
        cmbInspectorType                matlab.ui.control.DropDown
        imgValidate                     matlab.ui.control.Image
        cmbNeuralNet                    matlab.ui.control.DropDown
        btnPurgeInvalid                 matlab.ui.control.Button
        btnNewExperiment                matlab.ui.control.Button
        btnSimpleSettings               matlab.ui.control.Button
        ReportTab                       matlab.ui.container.Tab
        pnlNearestNeighbor              matlab.ui.container.Panel
        NeighboringobjectsDropDownLabel  matlab.ui.control.Label
        ReportSelObjectsNN              matlab.ui.control.DropDown
        btnCalculateNN                  matlab.ui.control.Button
        btnDeleteNN                     matlab.ui.control.Button
        lblNNdistance                   matlab.ui.control.Label
        lblNNvoxelOverlap               matlab.ui.control.Label
        btnPlotNNdistDist               matlab.ui.control.Button
        btnPlotNNoverlapDist            matlab.ui.control.Button
        FittingreferencedistributionLabel  matlab.ui.control.Label
        ReportDistFitType               matlab.ui.control.DropDown
        tblReport                       matlab.ui.control.Table
        btnPlotDensityByDepth           matlab.ui.control.Button
        btnPlotDensityLinear            matlab.ui.control.Button
        btnPlotHeatmap                  matlab.ui.control.Button
        btnPlotSholl                    matlab.ui.control.Button
        btnSaveReport                   matlab.ui.control.Button
        btnPlotObjSizeDist              matlab.ui.control.Button
        btnPlotObjBrightDist            matlab.ui.control.Button
        btnPlotObjRoundDist             matlab.ui.control.Button
        btnPlotObjMajorAxisDist         matlab.ui.control.Button
        btnCreateObjMask                matlab.ui.control.Button
        btnRecalculateSphericity        matlab.ui.control.Button
        btnCreateObjMaskColored         matlab.ui.control.Button
        AutomateTab                     matlab.ui.container.Tab
        ProcessinglistPanel             matlab.ui.container.Panel
        lstBatch                        matlab.ui.control.ListBox
        btnBatchAdd                     matlab.ui.control.Button
        btnBatchRemove                  matlab.ui.control.Button
        btnBatchUp                      matlab.ui.control.Button
        btnBatchDown                    matlab.ui.control.Button
        btnBatchTop                     matlab.ui.control.Button
        btnBatchBottom                  matlab.ui.control.Button
        btnBatchClear                   matlab.ui.control.Button
        btnBatchAddMultiple             matlab.ui.control.Button
        btnBatchStart                   matlab.ui.control.Button
        BatchLog                        matlab.ui.control.TextArea
        AutomateOperation               matlab.ui.container.TabGroup
        AutoSearchTab                   matlab.ui.container.Tab
        BatchWatershed                  matlab.ui.control.CheckBox
        BatchBlockSearch                matlab.ui.control.CheckBox
        BatchSphericity                 matlab.ui.control.CheckBox
        ExpecteddiameterrangeofobjectsmLabel  matlab.ui.control.Label
        BatchSingleZDotCut              matlab.ui.control.CheckBox
        BatchEdgeDotCut                 matlab.ui.control.CheckBox
        BatchxyStableDots               matlab.ui.control.CheckBox
        ExcludeifLabel_2                matlab.ui.control.Label
        BatchLocalNoise                 matlab.ui.control.CheckBox
        MaxLabel_4                      matlab.ui.control.Label
        BatchMaxSizeXY                  matlab.ui.control.NumericEditField
        MinLabel_4                      matlab.ui.control.Label
        BatchMinSizeXY                  matlab.ui.control.NumericEditField
        txtBatchThreshValue             matlab.ui.control.NumericEditField
        MinimumintensityofobjectstimesthenoiselevelLabel  matlab.ui.control.Label
        BatchMinIntensity               matlab.ui.control.Spinner
        cmbBatchThreshParam             matlab.ui.control.DropDown
        SearchLabel                     matlab.ui.control.Label
        cmbBatchSegmentation            matlab.ui.control.DropDown
        NoiseLabel                      matlab.ui.control.Label
        cmbBatchNoiseEstimator          matlab.ui.control.DropDown
        cmbBatchThresh2Param            matlab.ui.control.DropDown
        txtBatchThresh2Value            matlab.ui.control.NumericEditField
        cmbBatchThreshDir               matlab.ui.control.DropDown
        cmbBatchThresh2Dir              matlab.ui.control.DropDown
        ValidateifLabel                 matlab.ui.control.Label
        ANDLabel                        matlab.ui.control.Label
        cmbAutoDetectImage              matlab.ui.control.DropDown
        lblAutoDetectImage              matlab.ui.control.Label
        lblAutoDetectMask               matlab.ui.control.Label
        cmbAutoDetectMask               matlab.ui.control.DropDown
        AutoColocalizeTab               matlab.ui.container.Tab
        ReferenceObjectsDropDownLabel   matlab.ui.control.Label
        AutoColoRefObjs                 matlab.ui.control.DropDown
        AutoColoChannel                 matlab.ui.control.DropDown
        TocolocalizewithSwitchLabel     matlab.ui.control.Label
        AutoColoMaskOrObjs              matlab.ui.control.Switch
        MustshareatleastSpinnerLabel    matlab.ui.control.Label
        AutoColoVoxNum                  matlab.ui.control.Spinner
        AutoColoSwitchVoxPerc           matlab.ui.control.Switch
        chkAutoColoAll                  matlab.ui.control.CheckBox
        chkAutoColoCenterMustOverlap    matlab.ui.control.CheckBox
        WithinmLabel_2                  matlab.ui.control.Label
        cmbAutoColoThresholdDist        matlab.ui.control.Spinner
        chkAutoSaveColoObjs             matlab.ui.control.CheckBox
        chkAutoSaveNotColoObjs          matlab.ui.control.CheckBox
        chkAutoRecalculateNN            matlab.ui.control.CheckBox
        AutoExportMaskTab               matlab.ui.container.Tab
        chkAutoColoExportAll            matlab.ui.control.CheckBox
        ReferenceObjectsDropDownLabel_2  matlab.ui.control.Label
        AutoColoExportRefObjs           matlab.ui.control.DropDown
        MasktypeLabel                   matlab.ui.control.Label
        cmbAutoExportMaskType           matlab.ui.control.DropDown
        AutoReportTab                   matlab.ui.container.Tab
        chkAutoReportAll                matlab.ui.control.CheckBox
        ReferenceObjectsDropDownLabel_3  matlab.ui.control.Label
        AutoReportRefObjs               matlab.ui.control.DropDown
        ReportstatisticLabel            matlab.ui.control.Label
        AutoReportStatistic             matlab.ui.control.DropDown
        DestinationObjectsLabel         matlab.ui.control.Label
        AutoReportDstObjs               matlab.ui.control.DropDown
        chkAutoReportAllDst             matlab.ui.control.CheckBox
        BinningWidthLabel               matlab.ui.control.Label
        AutoReportBinSize               matlab.ui.control.Spinner
        BinsLabel                       matlab.ui.control.Label
        AutoReportBinNum                matlab.ui.control.Spinner
        DepthLabel                      matlab.ui.control.Label
        AutoReportDepthDir              matlab.ui.control.DropDown
        BatchScheduledTime              matlab.ui.control.Knob
        BatchScheduler                  matlab.ui.control.CheckBox
        lblBatchScheduler               matlab.ui.control.Label
        chkAutoSaveTable                matlab.ui.control.CheckBox
        ColocalizeTab                   matlab.ui.container.Tab
        ReviewcompletedanalysisPanel    matlab.ui.container.Panel
        lstColoDone                     matlab.ui.control.ListBox
        btnColoDoneRemove               matlab.ui.control.Button
        btnColoDoneClear                matlab.ui.control.Button
        btnColoRedoValid                matlab.ui.control.Button
        btnColoRedoDiscarded            matlab.ui.control.Button
        btnColoDonePlotDist             matlab.ui.control.Button
        tblColoDone                     matlab.ui.control.Table
        btnColoDoneSave                 matlab.ui.control.Button
        PerformanewcolocalizationanalysisPanel  matlab.ui.container.Panel
        lstColoObjects                  matlab.ui.control.ListBox
        lstColoImages                   matlab.ui.control.ListBox
        btnColocalizeManual             matlab.ui.control.Button
        btnColocalizeMask               matlab.ui.control.Button
        btnColocalizeNN                 matlab.ui.control.Button
        btnColocalizeEngulf             matlab.ui.control.Button
        ColoSwitchVoxPerc               matlab.ui.control.Switch
        WithinmLabel                    matlab.ui.control.Label
        ColoThresholdDist               matlab.ui.control.Spinner
        ObjectssharingatleastLabel      matlab.ui.control.Label
        ColoSharedVoxNum                matlab.ui.control.Spinner
        chkColoSaveAsNewObjects         matlab.ui.control.CheckBox
        btnColoRefreshObjs              matlab.ui.control.Button
        btnColoRefreshImgs              matlab.ui.control.Button
        chkColoCenterOverlap            matlab.ui.control.CheckBox
        chkNotColoSaveAsNewObjects      matlab.ui.control.CheckBox
        SimulateTab                     matlab.ui.container.Tab
        CompletedSimulationsPanel       matlab.ui.container.Panel
        lstSimObjs                      matlab.ui.control.ListBox
        btnSimListRemove                matlab.ui.control.Button
        btnSimListClear                 matlab.ui.control.Button
        btnSimPlotDist                  matlab.ui.control.Button
        FittingdistributionLabel_2      matlab.ui.control.Label
        cmbSimFittingDist               matlab.ui.control.DropDown
        cmbSimRename                    matlab.ui.control.Button
        btnSimCopy                      matlab.ui.control.Button
        GenerateasimulationPanel        matlab.ui.container.Panel
        btnSimScramble                  matlab.ui.control.Button
        NumberofiterationsSpinnerLabel  matlab.ui.control.Label
        SimIterations                   matlab.ui.control.Spinner
        TestedparameterLabel            matlab.ui.control.Label
        cmbSimParameter                 matlab.ui.control.DropDown
        SetoftestobjectsDropDownLabel   matlab.ui.control.Label
        cmbSimTestObjects               matlab.ui.control.DropDown
        ObjectstoprocessSpinnerLabel    matlab.ui.control.Label
        SimTestHowManyObjs              matlab.ui.control.Spinner
        btnSimAdd                       matlab.ui.control.Button
        btnSimRemove                    matlab.ui.control.Button
        ComputedstatisticLabel          matlab.ui.control.Label
        cmbSimStatistic                 matlab.ui.control.DropDown
        SecondsetoftestobjectsLabel     matlab.ui.control.Label
        cmbSimTestObjects2              matlab.ui.control.DropDown
        btnSimSimulate                  matlab.ui.control.Button
        TypeofsecondtestsetSwitchLabel  matlab.ui.control.Label
        cmbSimTest2type                 matlab.ui.control.Switch
        HypothesistestingPanel          matlab.ui.container.Panel
        SignificancelevelSpinnerLabel   matlab.ui.control.Label
        cmbSimSignificance              matlab.ui.control.Spinner
        NullhypothesisDropDownLabel     matlab.ui.control.Label
        cmbSimNull                      matlab.ui.control.DropDown
        btnSimTest                      matlab.ui.control.Button
        chkSimTestTwoTailed             matlab.ui.control.CheckBox
        TrainNetTab                     matlab.ui.container.Tab
        TrainingDatasetPanel            matlab.ui.container.Panel
        lstTraining                     matlab.ui.control.ListBox
        btnTrainingAdd                  matlab.ui.control.Button
        btnTrainingRemove               matlab.ui.control.Button
        btnTrainingUp                   matlab.ui.control.Button
        btnTrainingDown                 matlab.ui.control.Button
        btnTrainingTop                  matlab.ui.control.Button
        btnTrainingBottom               matlab.ui.control.Button
        btnTrainingClear                matlab.ui.control.Button
        btnTrainingAddMulti             matlab.ui.control.Button
        ObjectsLabel                    matlab.ui.control.Label
        cmbTrainingObjects              matlab.ui.control.DropDown
        chkTrainingObjectsAll           matlab.ui.control.CheckBox
        TrainingsettingsPanel           matlab.ui.container.Panel
        TrainingTestingratioLabel       matlab.ui.control.Label
        TrainSplitRatio                 matlab.ui.control.Spinner
        MiniBatchSizeLabel              matlab.ui.control.Label
        TrainMiniBatchSize              matlab.ui.control.NumericEditField
        MaxEpochsLabel                  matlab.ui.control.Label
        TrainMaxEpochs                  matlab.ui.control.NumericEditField
        MomentumLabel                   matlab.ui.control.Label
        TrainMomentum                   matlab.ui.control.Spinner
        LearningrateLabel               matlab.ui.control.Label
        TrainInitialLearningRate        matlab.ui.control.Spinner
        AugmenttrainingdataLabel        matlab.ui.control.Label
        TrainingAugment                 matlab.ui.control.Switch
        ShuffletrainingdataLabel        matlab.ui.control.Label
        cmbNetShuffle                   matlab.ui.control.DropDown
        ExecutionEnvironmentLabel       matlab.ui.control.Label
        cmbNetExecEnvironment           matlab.ui.control.DropDown
        btnNetTrain                     matlab.ui.control.Button
        GenerateTrainingSetButton       matlab.ui.control.Button
        NeuralNetworksPanel             matlab.ui.container.Panel
        tblNeuralNet                    matlab.ui.control.Table
        TargetLabel                     matlab.ui.control.Label
        cmbNetTarget                    matlab.ui.control.DropDown
        btnNetCreate                    matlab.ui.control.Button
        btnNetDelete                    matlab.ui.control.Button
        btnNetClone                     matlab.ui.control.Button
        btnNetRename                    matlab.ui.control.Button
        btnNetInfo                      matlab.ui.control.Button
        btnNetImport                    matlab.ui.control.Button
        btnNetExport                    matlab.ui.control.Button
        NetTypeDropDownLabel            matlab.ui.control.Label
        cmbNetType                      matlab.ui.control.DropDown
        NetModelDropDownLabel           matlab.ui.control.Label
        cmbNetModel                     matlab.ui.control.DropDown
        btnNetTest                      matlab.ui.control.Button
        AboutTab                        matlab.ui.container.Tab
        lblObjectFinder                 matlab.ui.control.Label
        lblCopyright                    matlab.ui.control.Label
        lblVersion                      matlab.ui.control.Label
        txtLicense                      matlab.ui.control.TextArea
        btnReportBug                    matlab.ui.control.Button
        btnHomePage                     matlab.ui.control.Button
        btnUserManual                   matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    %% ObjectFinder - Recognize 3D structures in image stacks
    %  Copyright (C) 2016,2017,2018 Luca Della Santina
    %
    %  This file is part of ObjectFinder.
    %
    %  ObjectFinder is free software: you can redistribute it and/or modify
    %  it under the terms of the GNU General Public License as published by
    %  the Free Software Foundation, either version 3 of the License, or
    %  (at your option) any later version.
    %
    %  This program is distributed in the hope that it will be useful,
    %  but WITHOUT ANY WARRANTY; without even the implied warranty of
    %  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %  GNU General Public License for more details.
    %
    %  You should have received a copy of the GNU General Public License
    %  along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %
    properties (Access = private)
        Version    = '9.0.8';   % App version
        Settings   = [];        % Application settings
        Post       = [];        % Current image stack
        Mask       = [];        % Current binary mask
        Dots       = [];        % Current Objects
        Sim        = [];        % Current Simulation
        Skel       = [];        % Current skeleton
        Timer      = timer;     % Delayed execution timer
        dlgCalc    = [];        % Dialog window for calculator
        dlgSimpleSettings = []; % Dialog window for simple detection settings
        dlgNewExperiment = [];  % Dialog window for creating new experiment
        NetSelected= [];        % Selected NeuralNet from the table
    end
    
    methods (Access = private)
        
        function RefreshValidObjects(app)
            % Refresh the list of available objects
            [Names, UIDs] = listObjects;
            app.cmbObjsDetected.Items = Names;
            app.cmbObjsDetected.ItemsData = UIDs;
            
            if ~isempty(app.cmbObjsDetected.Items)
                app.lstColoObjects.Items         = app.cmbObjsDetected.Items;
                app.lstColoObjects.ItemsData     = app.cmbObjsDetected.ItemsData;
                app.ReportSelObjectsNN.Items     = app.cmbObjsDetected.Items;
                app.ReportSelObjectsNN.ItemsData = app.cmbObjsDetected.ItemsData;
                app.cmbSimTestObjects.Items      = app.cmbObjsDetected.Items;
                app.cmbSimTestObjects.ItemsData  = app.cmbObjsDetected.ItemsData;
                app.cmbSimTestObjects2.Items     = app.cmbObjsDetected.Items;
                app.cmbSimTestObjects2.ItemsData = app.cmbObjsDetected.ItemsData;
            else
                app.lstColoObjects.Items         = {};
                app.lstColoObjects.ItemsData     = {};
                app.ReportSelObjectsNN.Items     = {};
                app.ReportSelObjectsNN.ItemsData = {};
                app.cmbSimTestObjects.Items      = {};
                app.cmbSimTestObjects.ItemsData  = {};
            end
            
            app.cmbObjsDetectedValueChanged;
            app.cmbSimTestObjectsValueChanged;
        end
        
        function RefreshSkeletons(app)
            [Names, UIDs] = listSkels;
            app.cmbSkel.Items = Names;
            app.cmbSkel.ItemsData = UIDs;
            if isempty(app.cmbSkel.Items)
                app.btnSkelDelete.Enable    = 'off';
                app.btnSkelImport.Enable    = 'on';
                app.btnSkelRename.Enable    = 'off';
                app.btnPlotSkeleton.Enable  = 'off';
            else
                app.btnSkelDelete.Enable    = 'on';
                app.btnSkelImport.Enable    = 'on';
                app.btnSkelRename.Enable    = 'on';
                app.btnPlotSkeleton.Enable  = 'on';
                app.cmbSkelValueChanged;
            end
        end
        
        function RefreshPresets(app)
            [Names, UIDs] = listPresets;
            app.cmbPreset.Items = [{'< custom settings >'}, Names];
            app.cmbPreset.ItemsData = [{''}, UIDs];
            
            if isempty(app.cmbPreset.Items)
                app.btnPresetDelete.Enable = 'off';
                app.btnPresetImport.Enable = 'on';
                app.btnPresetExport.Enable = 'off';
                app.btnPresetRename.Enable = 'off';
            else
                app.btnPresetDelete.Enable = 'on';
                app.btnPresetImport.Enable = 'on';
                app.btnPresetExport.Enable = 'on';
                app.btnPresetRename.Enable = 'on';
                app.cmbPresetValueChanged;
            end
        end
        
        function RefreshNeuralNets(app)
            tblNets = listNetwork;
            app.tblNeuralNet.Data = tblNets;
            if ~isempty(tblNets)
                app.cmbNeuralNet.Items = tblNets{:,1};
                app.cmbNeuralNet.ItemsData = tblNets{:,6};
            else
                app.cmbNeuralNet.Items = {};
                app.cmbNeuralNet.ItemsData = [];
            end
        end
        
        function RefreshImagesList(app, loadSelectedImage)
            if nargin < 2
                loadSelectedImage = true;
            end
            
            ImgList = listImages;
            if isempty(ImgList)
                ImgList = {};
                ImgListWithEmpty = {};
                ImgListWithEmptyData ={};
            else
                ImgListWithEmpty = [{'< no mask >'}, ImgList];
                ImgListWithEmptyData = [{''}, ImgList];
            end
            app.cmbPost.Items               = ImgList;
            app.cmbPost.ItemsData           = ImgList;
            app.cmbMask.Items               = ImgListWithEmpty;
            app.cmbMask.ItemsData           = ImgListWithEmptyData;
            
            if isempty(ImgList)
                app.btnImgInfo.Enable       = 'off';
                app.btnImgRender.Enable     = 'off';
                app.btnImgMIP.Enable        = 'off';
                app.btnMaskInfo.Enable      = 'off';
                app.btnMaskRender.Enable    = 'off';
                app.btnMaskMIP.Enable       = 'off';
                app.btnMaskLink.Enable  = 'off';
                app.btnFindObjects.Enable   = 'off';
            else
                app.btnImgInfo.Enable       = 'on';
                app.btnImgRender.Enable     = 'on';
                app.btnImgMIP.Enable        = 'on';
                app.btnMaskInfo.Enable      = 'on';
                app.btnMaskRender.Enable    = 'on';
                app.btnMaskMIP.Enable       = 'on';
                app.btnMaskLink.Enable  = 'on';
                app.btnFindObjects.Enable   = 'on';
            end
            
            if loadSelectedImage
                app.cmbPostValueChanged;
                app.loadCurrentImage;
                app.cmbMaskValueChanged;
                app.loadCurrentMask;
            end
        end
        
        function RefreshNearestNeighbors(app)
            app.ReportSelObjectsNN.Items = app.cmbObjsDetected.Items;
            app.ReportSelObjectsNN.ItemsData = app.cmbObjsDetected.ItemsData;
            app.ReportSelObjectsNNValueChanged;
        end
        
        function RefreshSimulations(app)
            [app.lstSimObjs.Items, app.lstSimObjs.ItemsData] = listSimulations;
            
            if ~isempty(app.lstSimObjs.Items)
                app.btnSimListRemove.Enable = 'on';
                app.btnSimListClear.Enable  = 'on';
            else
                app.btnSimListRemove.Enable = 'off';
                app.btnSimListClear.Enable  = 'off';
                app.btnSimPlotDist.Enable   = 'off';
                app.btnSimTest.Enable       = 'off';
            end
        end
        
        function RefreshColoDone(app)
            app.lstColoDone.Items = {};
            app.tblColoDone.Data = [];
            
            % Refresh list of processed channels
            if ~isempty(app.lstColoObjects.Value)
                switch numel(app.lstColoObjects.Value)
                    case 1
                        srcDots = loadObjects(app.lstColoObjects.Value{:}, {'UID', 'Coloc'});
                    otherwise
                        app.lstColoDone.Items;
                        return
                end
                
                if ~isempty(fieldnames(srcDots.Coloc))
                    for i=1:numel(srcDots.Coloc)
                        if isempty(app.lstColoImages.Items)
                            app.lstColoDone.Items = {[srcDots.Coloc(i).Fish1 '.tif']};
                        else
                            app.lstColoDone.Items{end+1} = [srcDots.Coloc(i).Fish1 '.tif'];
                        end
                    end
                end
            end
            app.lstColoDoneValueChanged;
        end
        
        function RefreshAutomateTab(app)
            app.AutoColoRefObjs.Items           = {};
            app.AutoColoRefObjs.ItemsData       = [];
            app.AutoColoExportRefObjs.Items     = {};
            app.AutoColoExportRefObjs.ItemsData = [];
            app.AutoReportRefObjs.Items         = {};
            app.AutoReportRefObjs.ItemsData     = [];
            app.AutoReportDstObjs.Items         = {};
            app.AutoReportDstObjs.ItemsData     = [];
            app.AutoColoChannel.Items           = {};
            app.AutoColoChannel.ItemsData       = [];
            if isempty(app.lstBatch.Items)
                return
            end
            
            % Populate sets of reference objects available for batch colocalization / export
            [Names, ~] = listObjects(app.lstBatch.Items{1});
            app.AutoColoRefObjs.Items           = Names;
            app.AutoColoExportRefObjs.Items     = Names;
            app.AutoReportRefObjs.Items         = Names;
            app.AutoReportDstObjs.Items         = Names;
            
            % Populate list of images available for objects detection
            app.cmbAutoDetectImage.ItemsData = [];
            app.cmbAutoDetectMask.ItemsData = [];
            
            ImgList = listImages;
            if isempty(ImgList)
                ImgList = {};
                ImgListWithEmpty = {};
                ImgListWithEmptyData ={};
            else
                ImgListWithEmpty = [{'< no mask >'}, ImgList];
                ImgListWithEmptyData = [{''}, ImgList];
            end
            app.cmbAutoDetectImage.Items     = ImgList;
            app.cmbAutoDetectImage.ItemsData = ImgList;
            app.cmbAutoDetectMask.Items      = ImgListWithEmpty;
            app.cmbAutoDetectMask.ItemsData  = ImgListWithEmptyData;
            
            switch app.AutoColoMaskOrObjs.Value
                case 'Mask'
                    app.AutoColoChannel.Items = ImgList;
                    app.AutoColoChannel.ItemsData = ImgList;
                    app.cmbAutoColoThresholdDist.Enable = 'off';
                    
                case 'Objects'
                    % Populate sets of destination objects available for colocalization
                    app.AutoColoChannel.Items  = app.AutoColoRefObjs.Items;
                    app.AutoColoChannel.ItemsData = app.AutoColoRefObjs.ItemsData;
                    app.cmbAutoColoThresholdDist.Enable = 'on';
            end
        end
        
        function RefreshColoImages(app)
            % Populate list of images for colocalization
            lstColoNew = {};
            fileList = dir([pwd filesep 'I' filesep '*.tif']);
            for i=1:numel(fileList)
                if isempty(lstColoNew)
                    lstColoNew = {fileList(i).name};
                else
                    lstColoNew{end+1} = fileList(i).name; %#ok
                end
            end
            app.lstColoImages.Items = lstColoNew;
        end
        
        function ReportObjectStats(app)
            if isempty(app.Dots)
                app.tblReport.Data = [];
                app.btnPlotDensityByDepth.Enable    = 'off';
                app.btnPlotObjBrightDist.Enable     = 'off';
                app.btnPlotObjSizeDist.Enable       = 'off';
                app.btnPlotHeatmap.Enable           = 'off';
                app.btnPlotHeatmap.Enable           = 'off';
                app.btnPlotDensityLinear.Enable     = 'off';
                app.btnPlotSkeleton.Enable          = 'off';
                app.btnPlotSholl.Enable             = 'off';
                app.btnPlotObjRoundDist.Enable      = 'off';
                app.btnPlotObjMajorAxisDist.Enable  = 'off';
                return
            end
            
            app.loadCurrentMask;
            dlg = uiprogressdlg(app.UIFigure,'Title','Calculating summary statistics...','Indeterminate', 'on');
            
            % Calculate objects properties
            Objs                             = getFilteredObjects(app.Dots, app.Dots.Filter);
            ObjsNum                          = numel(find(app.Dots.Filter.passF));
            ObjsTotNum                       = numel(app.Dots.Filter.passF);
            ObjsPerc                         = 100*ObjsNum/ObjsTotNum;
            VoxelVolume                      = Objs.Settings.ImInfo.xyum * Objs.Settings.ImInfo.xyum * Objs.Settings.ImInfo.zum;
            
            % Calculate image Properties
            SrcImgRes     = [app.Dots.Settings.ImInfo.xyum, app.Dots.Settings.ImInfo.xyum, app.Dots.Settings.ImInfo.zum];
            SrcImgSizePx  = [app.Dots.Settings.ImInfo.xNumVox, app.Dots.Settings.ImInfo.yNumVox, app.Dots.Settings.ImInfo.zNumVox];
            SrcImgSizeUm  = SrcImgSizePx.*SrcImgRes;
            SrcImgVolPx   = SrcImgSizePx(1)*SrcImgSizePx(2)*SrcImgSizePx(3);
            if ~isempty(app.Mask)
                MaskSizePx    = numel(find(app.Mask.I));
                MaskSizeUm    = MaskSizePx * (SrcImgRes(1) * SrcImgRes(2) * SrcImgRes(3));
            end
            ObjsMask      = zeros(Objs.Settings.ImInfo.yNumVox, Objs.Settings.ImInfo.xNumVox, Objs.Settings.ImInfo.zNumVox, 'uint8');
            for i = 1:numel(Objs.Vox)
                ObjsMask(Objs.Vox(i).Ind) = 1;
            end
            
            % Create Report table
            tblSummary = table({'Current Objects'},  {app.Dots.Name});
            tblSummary = [tblSummary; table({'Valid objects #'},{num2str(ObjsNum)})];
            tblSummary = [tblSummary; table({'Valid objects %'},{num2str(ObjsPerc)})];
            tblSummary = [tblSummary; table({'Total objects #'},{num2str(ObjsTotNum)})];
            tblSummary = [tblSummary; table({'Average brightness'},{[num2str(round(mean(Objs.MeanBright))) ' ' char(177) ' ' num2str(round(std(Objs.MeanBright)))] })];
            tblSummary = [tblSummary; table({'Average volume (µm^3)'},{[sprintf('%0.2f', mean(Objs.Vol)*VoxelVolume) ' ' char(177) ' ' sprintf('%0.2f', std(Objs.Vol)*VoxelVolume)] })];
            tblSummary = [tblSummary; table({'Source image'},{app.Dots.Settings.ImInfo.PostChName})];
            tblSummary = [tblSummary; table({'Voxel volume (µm)'},{[num2str(SrcImgRes(1)) ' x ' num2str(SrcImgRes(2)) ' x ' num2str(SrcImgRes(3))] })];
            tblSummary = [tblSummary; table({'Image volume (µm^3)'},{num2str(round(SrcImgSizeUm(1)*SrcImgSizeUm(2)*SrcImgSizeUm(3)))})];
            if ~isempty(app.Mask)
                tblSummary = [tblSummary; table({'Masked volume (µm^3)'},{num2str(round(MaskSizeUm))})];
                tblSummary = [tblSummary; table({'Masked volume (% of image)'},{num2str(100* MaskSizePx / SrcImgVolPx)})];
                tblSummary = [tblSummary; table({'Objects volume occupancy (% of mask)'},{num2str(100*numel(find(ObjsMask(app.Mask.I==1)))/MaskSizePx)})];
            end
            tblSummary = [tblSummary; table({'Objects volume occupancy (% of image)'},{num2str(100*sum(Objs.Vol)/SrcImgVolPx)})];
            app.tblReport.Data = tblSummary;
            
            app.btnPlotDensityByDepth.Enable    = 'on';
            app.btnPlotObjBrightDist.Enable     = 'on';
            app.btnPlotObjSizeDist.Enable       = 'on';
            app.btnPlotHeatmap.Enable           = 'on';
            app.btnSaveReport.Enable            = 'on';
            
            if ~isempty(app.Skel)
                app.btnPlotSholl.Enable         = 'on';
                app.btnPlotHeatmap.Enable       = 'on';
                app.btnPlotDensityLinear.Enable = 'on';
            else
                app.btnPlotSholl.Enable         = 'off';
                app.btnPlotHeatmap.Enable       = 'off';
                app.btnPlotDensityLinear.Enable = 'off';
            end
            
            if ~isempty(fieldnames(app.Dots.Shape))
                app.btnPlotObjRoundDist.Enable      = 'on';
                app.btnPlotObjMajorAxisDist.Enable  = 'on';
            else
                app.btnPlotObjRoundDist.Enable      = 'off';
                app.btnPlotObjMajorAxisDist.Enable  = 'off';
            end
            
            app.ReportSelObjectsNNValueChanged;
            close(dlg);
        end
        
        function NN = calcNNDistance(~, ObjSrc, ObjDst, CalculateOverlap)
            % Calculate nearest neighbor's distance and overlap
            xyum        = ObjSrc.Settings.ImInfo.xyum;
            zum         = ObjSrc.Settings.ImInfo.zum;
            tmpPts1     = ObjSrc.Pos;
            tmpVol1     = ObjSrc.Vol;
            tmpPts2     = ObjDst.Pos;
            tmpPts1N    = size(tmpPts1,1);
            NN          = struct;
            NN.Name     = ObjDst.Name;
            IncludeSelf = ~strcmp(ObjSrc.Name, ObjDst.Name); % Do not include self if calculating NN of objects against themselves
            disp([' Calculating pairwise nearest neighbors (' ObjSrc.Name '->' ObjDst.Name ')']);
            for p1 = tmpPts1N:-1:1
                % Do not process the current source object if invalid
                if ~ObjSrc.Filter.passF(p1)
                    NN.Dist(p1) = NaN;
                    NN.NeighborIdx(p1) = NaN;
                    NN.VoxOverlap(p1) = 0;
                    NN.VoxOverlapPerc(p1) = 0;
                    continue
                end
                
                % Calculate NN distance between set of points (Nearest p2 to each p1)
                xyDist = hypot( (tmpPts1(p1,1)-tmpPts2(:,1))*xyum, (tmpPts1(p1,2)-tmpPts2(:,2))*xyum);
                xyzDist = hypot( xyDist, (tmpPts1(p1,3)-tmpPts2(:,3))*zum); % calculate separately along Z because this dimension has different pixel size
                xyzDist(~ObjDst.Filter.passF) = NaN; % Do not store any distance for invalid destination objects
                if IncludeSelf
                    p2Distance        = min(xyzDist);                 % Distance to nearest neighbor
                else
                    p2Distance = min(setdiff(xyzDist(:), min(xyzDist(:)))); % Distance to 2nd nearest neighbor (1st is self)
                end
                p2                = find(xyzDist == p2Distance,1);    % Index of the nearest neighbor
                if numel(p2) >1
                    % There is more than one neighbor at minimum distance, pick the one with most overlap
                    for k = 1:numel(p2)
                        p2overlap(k) = numel(intersect(srcObjs.Vox(p1).Ind, dstObjs.Vox(p2(k)).Ind));
                    end
                    p2 = p2(find(p2overlap == max(p2overlap),1));
                end
                NN.Dist(p1)       = p2Distance;                       % Store calibrated distance
                NN.NeighborIdx(p1)= p2;                               % Store neighbor's index
                
                % Calculate voxel overlap between neareast neighbors (optional)
                if CalculateOverlap
                    NN.VoxOverlap(p1) = numel(intersect(ObjSrc.Vox(p1).Ind, ObjDst.Vox(p2).Ind));
                    NN.VoxOverlapPerc(p1) = 100 * NN.VoxOverlap(p1) / tmpVol1(p1); % Store voxel overlap as percent of source object volume
                else
                    NN.VoxOverlap(p1) = 0;
                    NN.VoxOverlapPerc(p1) = 0;
                end
            end
        end
        
        function Objs = calcObjDensity(app, Objs)
            % If the cell has a skeleton proceed with these additional steps
            if isempty(app.Skel)
                % Compute and plot object distribution as function of volume depth
                Objs.Density = calcDotDensityAlongZ(Objs.Settings, getFilteredObjects(Objs, Objs.Filter), [], 100, false);
            else
                app.Skel = calcSkelPathLength(app.Skel, false);
                saveSkel(app.Skel);
                
                Objs = distDotsToCellBody(Objs, app.Skel, Objs.Settings);
                Objs = distDotsToSkel(Objs, app.Skel, Objs.Settings);
                % Generate heatmaps of object density
                Objs.Density = calcDensity(Objs.Settings, Objs, app.Skel, false);
                % Calculate linear density along the skeleton segments
                Objs.Density.PathLengthStats = calcPathLengthStats(Objs, app.Skel, false);
            end
        end
        
        function loadImages(app, PostCh, MaskCh)
            % Load automatically images from the I folder, given the pass ID number of PostCh and MaskCh
            
            app.Settings = struct;
            % Read images to be used for analysis
            fNames=dir([pwd filesep 'I' filesep '*.tif']);
            if isempty(fNames)
                msgbox('No TIF image available in the image folder.', 'File Error', 'error');
                return;
            end
            
            % Read all TIF images from I folder
            Images = struct;
            for i = numel(fNames):-1:1
                [Images(i).Iraw, Images(i).ImInfo, Images(i).MIP, Images(i).ImRes] = loadImage(fNames(i).name);
                zs(i) = numel(Images(i).ImInfo);
                xs(i) = Images(i).ImInfo.Width;
                ys(i) = Images(i).ImInfo.Height;
            end
            
            app.Settings.ImInfo.xNumVox     = xs(PostCh);
            app.Settings.ImInfo.yNumVox     = ys(PostCh);
            app.Settings.ImInfo.zNumVox     = zs(PostCh);
            app.Settings.ImInfo.PostCh      = PostCh;
            app.Settings.ImInfo.PostChName  = fNames(PostCh).name;
            app.Settings.ImInfo.MaskCh      = MaskCh;
            app.Settings.ImInfo.xyum        = Images(i).ImRes(1);
            app.Settings.ImInfo.zum         = Images(i).ImRes(3);
            app.Settings.debug              = false;
            
            % Assign Channels into app variables
            if app.Settings.ImInfo.PostCh
                app.Post.I = Images(app.Settings.ImInfo.PostCh).Iraw;
                app.Post.MIP = Images(app.Settings.ImInfo.PostCh).MIP;
                app.Post.ImInfo = Images(app.Settings.ImInfo.PostCh).ImInfo;
                app.Post.ImRes = Images(app.Settings.ImInfo.PostCh).ImRes;
                app.Post.Name = fNames(app.Settings.ImInfo.PostCh).name;
                app.Settings.ImInfo.PostChName = fNames(app.Settings.ImInfo.PostCh).name;
                app.cmbPost.Value = fNames(app.Settings.ImInfo.PostCh).name;
            end
            
            if app.Settings.ImInfo.MaskCh
                app.Mask.I = Images(app.Settings.ImInfo.MaskCh).Iraw(:,:,:);
                app.Mask.I = app.Mask.I / max(app.Mask.I(:)); % Normalize mask max value to 1
                app.Mask.MIP = Images(app.Settings.ImInfo.MaskCh).MIP;
                app.Mask.ImInfo = Images(app.Settings.ImInfo.MaskCh).ImInfo;
                app.Mask.ImRes = Images(app.Settings.ImInfo.MaskCh).ImRes;
                app.Mask.Name = fNames(app.Settings.ImInfo.MaskCh).name;
                app.Settings.ImInfo.MaskChName = fNames(app.Settings.ImInfo.MaskCh).name;
                app.cmbMask.Value = fNames(app.Settings.ImInfo.MaskCh).name;
            else
                % Create dummy mask with all ones to process the entire image
                app.Mask = [];
                app.Settings.ImInfo.MaskChName = '';
                app.cmbMask.Value = '';
            end
            close all;
            
            saveSettings(app.Settings);
            
            app.btnFindObjects.Enable   = 'on';
            app.btnCalcDiameter.Enable  = 'on';
        end
        
        
        function Preset = generatePreset(app)
            Preset.maxDotSize    = app.MaxSizeXY.Value;
            Preset.minDotSize    = app.MinSizeXY.Value;
            Preset.watershed     = app.Watershed.Value;
            Preset.sphericity    = app.Sphericity.Value;
            Preset.blockSearch   = app.BlockSearch.Value;
            Preset.minIntensity  = app.MinIntensity.Value;
            Preset.localNoise    = app.LocalNoise.Value;
            Preset.connectedVoxN = str2double(app.cmbConnectedVoxN.Value);
            switch app.cmbNoiseEstimator.Value
                case 'Most common intensity', Preset.noiseEstimator = 'mode';
                case 'Standard deviation',    Preset.noiseEstimator = 'std';
                case 'Minimum intensity',     Preset.noiseEstimator = 'min';
            end
            switch app.cmbSegmentation.Value
                case 'Iterative thresholding', Preset.method = 'IterativeThresholding';
                case 'Simple thresholding',    Preset.method = 'SimpleThresholding';
            end
            Preset.SingleZDotCut = app.SingleZDotCut.Value;
            Preset.EdgeDotCut    = app.EdgeDotCut.Value;
            Preset.xyStableDots  = app.xyStableDots.Value;
        end
        
        function applyPreset(app, Preset)
            if isempty(Preset)
                return
            end
            
            app.MaxSizeXY.Value     = Preset.maxDotSize;
            app.MinSizeXY.Value     = Preset.minDotSize;
            app.Watershed.Value     = Preset.watershed;
            app.Sphericity.Value    = Preset.sphericity;
            app.BlockSearch.Value   = Preset.blockSearch;
            if app.BlockSearch.Value
                app.LocalNoise.Enable   = 'on';
            else
                app.LocalNoise.Enable   = 'off';
            end
            app.MinIntensity.Value  = Preset.minIntensity;
            app.LocalNoise.Value    = Preset.localNoise;
            app.cmbConnectedVoxN.Value = num2str(Preset.connectedVoxN);
            switch Preset.noiseEstimator
                case 'mode', app.cmbNoiseEstimator.Value = 'Most common intensity';
                case 'std',  app.cmbNoiseEstimator.Value = 'Standard deviation';
                case 'min',  app.cmbNoiseEstimator.Value = 'Minimum intensity';
            end
            switch Preset.method
                case 'IterativeThresholding', app.cmbSegmentation.Value = 'Iterative thresholding';
                case 'SimpleThresholding',    app.cmbSegmentation.Value = 'Simple thresholding';
            end
            app.SingleZDotCut.Value = Preset.SingleZDotCut;
            app.EdgeDotCut.Value    = Preset.EdgeDotCut;
            app.xyStableDots.Value  = Preset.xyStableDots;
        end
        
        function loadCurrentImage(app)
            if isempty(app.cmbPost.Value)
                app.Post = [];
                app.Settings.ImInfo.PostChName = '';
                app.Settings.ImInfo.xyum     = 1;
                app.Settings.ImInfo.zum      = 1;
                app.Settings.ImInfo.yNumVox  = 0;
                app.Settings.ImInfo.xNumVox  = 0;
                app.Settings.ImInfo.zNumVox  = 0;
                app.btnSimpleSettings.Enable = 'off';
                app.btnFindObjects.Enable    = 'off';
                app.btnCalcDiameter.Enable   = 'off';
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading image...','Message', app.cmbPost.Value,'Indeterminate', 'on');
            
            % Load the image only if not already loaded
            if isempty(app.Post) || ~strcmp(app.cmbPost.Value, app.Post.Name)
                [app.Post.I, app.Post.ImInfo, app.Post.MIP, app.Post.ImRes] = loadImage(app.cmbPost.Value);
                app.Post.Name                   = app.cmbPost.Value;
                app.Settings.ImInfo.PostChName  = app.cmbPost.Value;
                app.Settings.ImInfo.xyum        = app.Post.ImRes(1);
                app.Settings.ImInfo.zum         = app.Post.ImRes(3);
                app.Settings.ImInfo.yNumVox     = size(app.Post.I,1);
                app.Settings.ImInfo.xNumVox     = size(app.Post.I,2);
                app.Settings.ImInfo.zNumVox     = size(app.Post.I,3);
                app.btnSimpleSettings.Enable    = 'on';
                app.btnFindObjects.Enable       = 'on';
                app.btnCalcDiameter.Enable      = 'on';
            end
            close(dlg);
        end
        
        function loadCurrentMask(app)
            if isempty(app.cmbMask.Value)
                app.Mask = [];
                app.Settings.ImInfo.MaskChName = '';
                return
            end
            
            if isempty(app.Mask) || ~strcmp(app.cmbMask.Value, app.Mask.Name)
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading mask...','Message', app.cmbMask.Value,'Indeterminate', 'on');
                [app.Mask.I, app.Mask.ImInfo, app.Mask.MIP, app.Mask.ImRes] = loadImage(app.cmbMask.Value);
                MaxMask = max(unique(app.Mask.I));
                if MaxMask > 1
                    app.Mask.I(app.Mask.I == MaxMask) = 1;
                end
                app.Mask.Name = app.cmbMask.Value;
                app.Settings.ImInfo.MaskChName = app.cmbMask.Value;
                close(dlg);
            end
        end
    end
    
    methods (Access = public)
        
        function diam = convertVoxelsToDiameter(app, vox, xyRes, zRes)
            % Calculate the diameter of the sphere having volume equal to the passed number of voxels
            if nargin == 2
                if ~isempty(app.Post)
                    xyRes = app.Post.ImRes(1);
                    zRes = app.Post.ImRes(3);
                else
                    xyRes = app.Settings.ImInfo.xyum;
                    zRes = app.Settings.ImInfo.zum;
                end
            end
            diam = ((vox*3*8*xyRes^2*zRes)/(4*pi))^(1/3);
        end
        
        function vox = convertDiameterToVoxels(app, diam, xyRes, zRes)
            % Calculate volume of a sphere in vogel given its diameter in calibrated units
            if nargin == 2
                if ~isempty(app.Post)
                    xyRes = app.Post.ImRes(1);
                    zRes = app.Post.ImRes(3);
                else
                    xyRes = app.Settings.ImInfo.xyum;
                    zRes = app.Settings.ImInfo.zum;
                end
            end
            vox = ceil((4/3*pi*(diam/2)^3) / (xyRes^2*zRes));
        end
        
        function NN = generateNeuralNet(~, NetName, NetTarget, NetType, ModelName, Net, layers)
            % Generates an empty neural network structure
            NN.UID       = generateUID;
            NN.Name      = NetName;
            NN.Date      = datetime;   % Current date
            NN.Target    = NetTarget;  % Net Targer: Eyelid / Follicles
            NN.Type      = NetType;    % Pixel classifier / Object detector / Object classifier
            NN.Model     = ModelName;  % Name of the network model (alexnet, googlenet, ...)
            NN.Net       = Net;        % Network model
            NN.Layers    = layers;
            NN.Trained   = ~isempty(Net);
        end
        
        function RefreshHomeFolder(app, ForceConversion, RefreshAll)
            if nargin < 3
                RefreshAll = false;
            elseif nargin < 2
                ForceConversion = false;
            end
            
            % Check if current working directory is a valid ObjectFinder analysis folder and load data
            if ~isfolder('I')
                app.btnChooseImages.Enable      = 'off';
                app.btnFindObjects.Enable       = 'off';
                app.btnFilterObjs.Enable        = 'off';
                app.btnValidateObjects.Enable   = 'off';
                app.RefreshValidObjects;
                app.RefreshSkeletons;
                app.RefreshImagesList;
                return;
            end
            
            % Ensure current experiment has all necessary subfolders
            if ~isfolder([pwd filesep 'objects']), mkdir([pwd filesep 'objects']); end
            if ~isfolder([pwd filesep 'simulations']), mkdir([pwd filesep 'simulations']); end
            if ~isfolder([pwd filesep 'skeletons']), mkdir([pwd filesep 'skeletons']); end
            
            app.btnChooseImages.Enable = 'on';
            
            app.Post = [];
            app.Mask = [];
            app.Dots = [];
            app.Sim  = [];
            app.Skel = [];
            app.Settings = [];
            
            % Convert folder format if experiment was created with an older OBjectFinder version
            if ForceConversion
                answer = 'Yes';
            end
            
            if exist('find','dir')
                if ~ForceConversion
                    answer = questdlg('Data was analyzed with Objectfinder version 3, do you want to convert it?','Old data format detected', 'Yes', 'No', 'Yes');
                end
                switch answer
                    case 'Yes'
                        convertObjectFinderDataV3toV4;
                        convertObjectFinderDataV4toV5;
                        convertObjectFinderDataV5toV7;
                        convertObjectFinderDataV7toV8;
                end
                
            elseif exist('Density.mat','file')
                if ~ForceConversion
                    answer = questdlg('Data was analyzed with Objectfinder version 4, do you want to convert it?', 'Old data format detected', 'Yes', 'No', 'Yes');
                end
                switch answer
                    case 'Yes'
                        convertObjectFinderDataV4toV5;
                        convertObjectFinderDataV5toV7;
                        convertObjectFinderDataV7toV8;
                end
                
            elseif exist('Dots.mat','file')
                if ~ForceConversion
                    answer = questdlg('Data was analyzed with Objectfinder version 5 or 6, do you want to convert it?', 'Old data format detected', 'Yes', 'No', 'Yes');
                end
                switch answer
                    case 'Yes'
                        convertObjectFinderDataV5toV7;
                        convertObjectFinderDataV7toV8;
                end
                
            elseif exist('Skel.mat','file') || isfolder([pwd filesep 'data']) || isfolder([pwd filesep 'images'])
                if ~ForceConversion
                    answer = questdlg('Data was analyzed with Objectfinder version 7, do you want to convert it?', 'Old data format detected', 'Yes', 'No', 'Yes');
                end
                switch answer
                    case 'Yes'
                        convertObjectFinderDataV7toV8;
                end
            end
            
            if RefreshAll
                app.RefreshSkeletons;
                app.RefreshImagesList(false);
                app.RefreshValidObjects;
                if isempty(app.Post)
                    app.cmbPostValueChanged;
                end
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the dialog on main window
            posParent = get(0,'ScreenSize');
            pos = app.UIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.UIFigure.Position = pos;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','ObjectFinder','Indeterminate', 'on');
            dlg.Message = 'Initializing...';
            pause(0.2);
            app.UIFigure.Name = ['Object Finder ' app.Version];
            app.lblVersion.Text = ['Version ' app.Version];
            
            % Ensure presence of local folder in MATLAB's user path to store ObjectFinder's settings
            ObjFinderFolder = [userpath filesep 'Object Finder'];
            NeuralNetsFolder = [userpath filesep 'Object Finder' filesep 'NeuralNets'];
            PresetsFolder = [userpath filesep 'ObjectFinder' filesep 'Presets'];
            if ~isfolder(ObjFinderFolder), mkdir(ObjFinderFolder); end
            if ~isfolder(NeuralNetsFolder), mkdir(NeuralNetsFolder); end
            if ~isfolder(PresetsFolder), mkdir(PresetsFolder); end
            
            % Refresh GUI components
            app.RefreshPresets;
            app.RefreshNeuralNets;
            app.RefreshHomeFolder(false, true);
            close(dlg);
        end

        % Button pushed function: btnChooseImages
        function btnChooseImagesPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading images...','Value', 0, 'Cancelable', 'on');
            
            app.Settings = struct;
            
            % Read images to be used for analysis
            fNames = dir([pwd filesep 'I' filesep '*.tif']);
            if isempty(fNames)
                msgbox('No TIF image available in the image folder.', 'File Error', 'error');
                return;
            end
            
            % Read all TIF images from I folder
            Images = struct;
            for i = numel(fNames):-1:1
                dlg.Title = ['Loading image ' num2str(numel(fNames)-i+1) '/' num2str(numel(fNames)) ' ...'];
                dlg.Message = fNames(i).name;
                dlg.Value = (numel(fNames)-i+1)/numel(fNames);
                
                [Images(i).Iraw, Images(i).ImInfo, Images(i).MIP, Images(i).ImRes] = loadImage(fNames(i).name);
                zs(i) = numel(Images(i).ImInfo);
                xs(i) = Images(i).ImInfo.Width;
                ys(i) = Images(i).ImInfo.Height;
                
                % Interrupt batch processing at this point if user pressed the cancel button
                if dlg.CancelRequested
                    close(dlg);
                    return
                end
            end
            
            % Show MIPs in a Figure with multiple panels (N*(2 x 3) table)
            figure('Name','Available images','NumberTitle','off', 'units', 'normalized', 'MenuBar', 'none', 'ToolBar', 'none',  'position', [0.05 0.05 0.9 0.9]);
            rows = max(ceil(numel(Images)/3),1);
            cols = ceil(numel(Images)/rows);
            for i = 1:numel(Images)
                subplot(rows,cols,i);
                MIP = Images(i).MIP;
                if numel(unique(MIP)) == 2
                    % MIP is a binary mask, stretch from 0 to 255
                    image(255*(MIP/max(MIP(:))));
                else
                    % MIP is from a regular 8-bit image
                    image(MIP);
                end
                title({['Image # ' num2str(i)]; fNames(i).name}, 'interpreter', 'none');
                set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
                daspect([size(MIP,1) size(MIP,2) 1]); % Maintain aspect ratio of original image
            end
            colormap gray(256);
            
            % Ask user for image identity settings
            tmpPrompt = {'Objects channel #:','Mask channel # (0 = no mask):','XY resolution :','Z resolution :'};
            tmpAns = inputdlg(tmpPrompt, 'Assign channels', 1,{'1', '0', num2str(Images(1).ImRes(1)), num2str(Images(1).ImRes(3))});
            close all;
            app.UIFigure.Visible = 'off'; % Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  % Fixes glitch by which app loses focus
            
            if isempty(tmpAns)
                close(dlg);
                return
            end
            
            app.Settings.ImInfo.xNumVox     = xs(str2double(tmpAns(1)));
            app.Settings.ImInfo.yNumVox     = ys(str2double(tmpAns(1)));
            app.Settings.ImInfo.zNumVox     = zs(str2double(tmpAns(1)));
            app.Settings.ImInfo.PostCh      = str2double(tmpAns(1));
            app.Settings.ImInfo.MaskCh      = str2double(tmpAns(2));
            app.Settings.ImInfo.xyum        = str2double(tmpAns(3));
            app.Settings.ImInfo.zum         = str2double(tmpAns(4));
            app.Settings.debug              = false;
            
            % Write Channels into matlab files
            dlg.Title = 'Saving selected images...';
            dlg.Indeterminate = 'on';
            dlg.Cancelable = 'off';
            
            if app.Settings.ImInfo.PostCh
                app.Post.I = Images(app.Settings.ImInfo.PostCh).Iraw;
                app.Post.MIP = Images(app.Settings.ImInfo.PostCh).MIP;
                app.Post.ImInfo = Images(app.Settings.ImInfo.PostCh).ImInfo;
                app.Post.ImRes = Images(app.Settings.ImInfo.PostCh).ImRes;
                app.Post.Name = fNames(app.Settings.ImInfo.PostCh).name;
                app.Settings.ImInfo.PostChName = fNames(app.Settings.ImInfo.PostCh).name;
                app.cmbPost.Value = fNames(app.Settings.ImInfo.PostCh).name;
            end
            
            if app.Settings.ImInfo.MaskCh
                app.Mask.I = Images(app.Settings.ImInfo.MaskCh).Iraw(:,:,:);
                app.Mask.I = app.Mask.I / max(app.Mask.I(:)); % Normalize mask max value to 1
                app.Mask.MIP = Images(app.Settings.ImInfo.MaskCh).MIP;
                app.Mask.ImInfo = Images(app.Settings.ImInfo.MaskCh).ImInfo;
                app.Mask.ImRes = Images(app.Settings.ImInfo.MaskCh).ImRes;
                app.Mask.Name = fNames(app.Settings.ImInfo.MaskCh).name;
                app.cmbMask.Value = fNames(app.Settings.ImInfo.MaskCh).name;
            else
                app.Mask = [];
                app.Settings.ImInfo.MaskChName  = '';
                app.cmbMask.Value = '';
            end
            
            saveSettings(app.Settings);
            
            app.btnFindObjects.Enable    = 'on';
            app.btnSimpleSettings.Enable = 'on';
            app.btnCalcDiameter.Enable   = 'on';
            close(dlg);
        end

        % Button pushed function: btnFindObjects
        function btnFindObjectsPushed(app, event)
            app.loadCurrentImage;
            app.loadCurrentMask;
            
            if ~isempty(app.Mask) && (size(app.Mask.I,1) ~= size(app.Post.I,1) || size(app.Mask.I,2) ~= size(app.Post.I,2) || size(app.Mask.I,3) ~= size(app.Post.I,3))
                msgbox('Image and Mask are of different size', 'Error', "error");
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Searching objects...','Indeterminate', 'on');
            
            % Warn the user if a set of objects with the same name already exists
            [~, Name, ~]  = fileparts(app.Settings.ImInfo.PostChName);
            [ObjNames, ObjUIDs] = listObjects;
            for i = 1 : numel(ObjNames)
                if strcmp(ObjNames{i}, Name)
                    switch questdlg({'A set of objects with the same name already exists.';'Do you want to overwrite it?';' ';
                            ['Existing set name: ' ObjNames{i}];'If not sure, select "No" and rename it before proceeding.'},...
                            'Overwrite objects?', 'Yes', 'No', 'No')
                        case 'Yes'
                            break;
                        case 'No'
                            return
                    end
                end
            end
            
            % Calculate volume of the minimum / maximum object sizes allowed, assuming they are spheres
            % largest CtBP2 = elipsoid 1um-xy/2um-z diameter = ~330 voxels (.103x.103x0.3 um pixel size)
            
            MaxDotSize = app.convertDiameterToVoxels(app.MaxSizeXY.Value, app.Post.ImRes(1), app.Post.ImRes(3));
            MinDotSize = app.convertDiameterToVoxels(app.MinSizeXY.Value, app.Post.ImRes(1), app.Post.ImRes(3));
            
            app.Settings.objfinder.blockBuffer      = ceil(1.1 * app.MinSizeXY.Value/app.Settings.ImInfo.xyum);  % Overlapping buffer region between search block should be 1.1*big as the biggest dot to avoid artifacts.
            app.Settings.objfinder.blockSize        = max(64, ceil(3*app.MaxSizeXY.Value/app.Settings.ImInfo.xyum));
            app.Settings.objfinder.maxDotSize       = MaxDotSize;             % max dot size exclusion criteria.
            app.Settings.objfinder.minDotSize       = MinDotSize;             % min dot size exclusion criteria.
            app.Settings.objfinder.watershed        = app.Watershed.Value;    % False if you want to skip watershed segmentation during object search
            app.Settings.objfinder.sphericity       = app.Sphericity.Value;   % True if you want to calculare sphericity
            app.Settings.objfinder.blockSearch      = app.BlockSearch.Value;  % True to enable block search with multithreading
            app.Settings.objfinder.minIntensity     = app.MinIntensity.Value; % Minumum allowed voxel intensity (# times the local noise of the block)
            app.Settings.objfinder.localNoise       = app.LocalNoise.Value;   % If true noise level will be computer for each block, if false only one noise level per entire image
            switch app.cmbNoiseEstimator.Value
                case 'Most common intensity' , app.Settings.objfinder.noiseEstimator = 'mode';
                case 'Standard deviation',     app.Settings.objfinder.noiseEstimator = 'std';
                case 'Minimum intensity',      app.Settings.objfinder.noiseEstimator = 'min';
            end
            
            % Seach objects inside the masked volume
            switch app.cmbSegmentation.Value
                case 'Iterative thresholding'
                    app.Settings.objfinder.method = 'IterativeThresholding';
                    app.Settings.objfinder.connectedVoxN = str2double(app.cmbConnectedVoxN.Value);
                    if isempty(app.Mask)
                        Objs = findObjects(app.Post.I, app.Settings);
                    else
                        Objs = findObjects(app.Post.I.*app.Mask.I, app.Settings);
                    end
                case 'Simple thresholding'
                    app.Settings.objfinder.method = 'SimpleThresholding';
                    app.Settings.objfinder.connectedVoxN = str2double(app.cmbConnectedVoxN.Value);
                    if isempty(app.Mask)
                        Objs = findObjectsSimple(app.Post.I, app.Settings);
                    else
                        Objs = findObjectsSimple(app.Post.I.*app.Mask.I, app.Settings);
                    end
            end
            if isempty(Objs)
                close(dlg);
                return
            end
            app.UIFigure.Visible = 'off'; % Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  % Fixes glitch by which app loses focus
            
            % Create fields about sphericity of objects (Rounding)
            Objs.Shape = struct;
            if app.Sphericity.Value
                dlg.Title = 'Calculating shape of objects...';
                Objs = fitSphere(Objs, app.Settings);
            end
            
            % Assign a name to Objects base on the raw image filename
            try
                [~,Objs.Name,~] = fileparts(app.Settings.ImInfo.PostChName);
            catch
                Objs.Name = 'NewObjects';
            end
            
            % Filter all objects
            app.Settings.Filter.EdgeDotCut               = app.EdgeDotCut.Value;
            app.Settings.Filter.SingleZDotCut            = app.SingleZDotCut.Value;
            app.Settings.Filter.xyStableDots             = app.xyStableDots.Value;
            app.Settings.Filter.Thresholds.ITMax         = 0;  % custom thresholds disabled
            app.Settings.Filter.Thresholds.Vol           = 0;  % custom thresholds disabled
            app.Settings.Filter.Thresholds.MeanBright    = 0;  % custom thresholds disabled
            app.Settings.Filter.Thresholds.ITMaxDir      = 1;  % 1 means ">= than threshold"
            app.Settings.Filter.Thresholds.VolDir        = 1;  % 1 means ">= than threshold"
            app.Settings.Filter.Thresholds.MeanBrightDir = 1;  % 1 means ">= than threshold"
            
            dlg.Title = 'Applying exclusion criteria...';
            Objs.Settings = app.Settings;
            Objs.Filter = filterObjects(Objs, app.Settings.Filter);
            
            % Add structs placeholder for parameters calculated later
            Objs.Density = struct; % Density measurements
            Objs.NN      = struct; % Nearest neighbor measurements
            Objs.Coloc   = struct; % Colocalization measurements
            Objs.Skel    = struct; % Skeleton measurements (e.g distance to cell body)
            
            % If a set of objects with the same name already exists overwrite them, otherwise append
            dlg.Title = 'Saving objects...';
            AlreadyPresent  = false;
            for i = 1:numel(ObjNames)
                if strcmp(ObjNames{i}, Objs.Name)
                    Objs.UID = ObjUIDs{i};
                    app.Dots = Objs;
                    AlreadyPresent = true;
                    break
                end
            end
            
            if ~AlreadyPresent
                Objs.UID = generateUID;
                app.Dots = Objs;
            end
            
            saveObjects(app.Dots);
            saveSettings(app.Settings);
            app.RefreshValidObjects;
            
            app.btnFilterObjs.Enable = 'on';
            close(dlg);
        end

        % Button pushed function: btnFilterObjs
        function btnFilterObjsPushed(app, event)
            % Retrieve index of current objects
            if isempty(app.Dots)
                return
            end
            
            % Filter objects according to the following post-processing criteria (SG)
            dlg = uiprogressdlg(app.UIFigure,'Title','Applying exclusion filters...','Indeterminate', 'on');
            
            app.Dots.Settings.Filter.EdgeDotCut               = app.EdgeDotCut.Value;
            app.Dots.Settings.Filter.SingleZDotCut            = app.SingleZDotCut.Value;
            app.Dots.Settings.Filter.xyStableDots             = app.xyStableDots.Value;
            app.Dots.Settings.Filter.Thresholds.ITMax         = 0;  % custom thresholds disabled
            app.Dots.Settings.Filter.Thresholds.Vol           = 0;  % custom thresholds disabled
            app.Dots.Settings.Filter.Thresholds.MeanBright    = 0;  % custom thresholds disabled
            app.Dots.Settings.Filter.Thresholds.Oblong        = 0;  % custom thresholds disabled
            app.Dots.Settings.Filter.Thresholds.PrincipalAxisLen = 0;  % custom thresholds disabled
            app.Dots.Settings.Filter.Thresholds.ITMaxDir      = 1;  % 1 means ">= than threshold"
            app.Dots.Settings.Filter.Thresholds.VolDir        = 1;  % 1 means ">= than threshold"
            app.Dots.Settings.Filter.Thresholds.MeanBrightDir = 1;  % 1 means ">= than threshold"
            app.Dots.Settings.Filter.Thresholds.OblongDir     = 1;  % 1 means ">= than threshold"
            app.Dots.Settings.Filter.Thresholds.PrincipalAxisLenDir = 1;  % 1 means ">= than threshold"
            
            app.Dots.Filter = filterObjects(app.Dots, app.Dots.Settings.Filter);
            saveObjects(app.Dots, 'Filter');
            saveObjects(app.Dots, 'Settings');
            
            app.cmbObjsDetectedValueChanged;
            app.btnValidateObjects.Enable = 'on';
            close(dlg);
        end

        % Button pushed function: btnValidateObjects
        function btnValidateObjectsPushed(app, event)
            app.loadCurrentImage;
            app.loadCurrentMask;
            
            dlg = uiprogressdlg(app.UIFigure,'Title',['Validating objects with ' app.cmbInspectorType.Value '...'],'Indeterminate', 'on');
            
            if ~isempty(app.Dots)
                switch app.cmbInspectorType.Value
                    case 'Sliced Volume Inspector'
                        if isempty(app.Mask)
                            inspectVolume2D(app.Post.I, app.Dots, app.Dots.Filter);
                        else
                            inspectVolume2D(app.Post.I.*app.Mask.I, app.Dots, app.Dots.Filter);
                        end
                    case '3D Volume Inspector'
                        if isempty(app.Mask)
                            inspectVolume3D(app.Post, app.Dots, app.Dots.Filter);
                        else
                            MaskedPost = app.Post;
                            MaskedPost.I = MaskedPost.I.*app.Mask.I;
                            inspectVolume3D(MaskedPost, app.Dots, app.Dots.Filter);
                        end
                    case 'Bitplane Imaris (7.2.3 or older)'
                        exportObjectsToImaris7(app.Dots); % Transfer objects to Imaris
                        
                    case 'Bitplane Imaris (7.2.4 or newer)'
                        exportObjectsToImaris8(app.Dots); % Transfer objects to Imaris
                        
                    case 'Neural Net'
                        NN = loadNetwork(app.cmbNeuralNet.Value);
                        Filter = validateObjectWithNeuralNet(app.Dots, NN);
                        save([pwd filesep 'Filter.mat'], 'Filter');
                end
                
                % Reload the filter from file as user might have changed it using the volume inspector
                dlg.Title = 'Updating validation status of objects...';
                if exist([pwd filesep 'Filter.mat'], 'file')
                    load([pwd filesep 'Filter.mat'], 'Filter');
                    app.Dots.Filter = Filter;
                    delete 'Filter.mat';
                end
                saveObjects(app.Dots);
                
                % Calculate density of validated objects
                dlg.Title = 'Calculating density of objects...';
                if isempty(app.Skel)
                    % Compute and plot object distribution as function of volume depth
                    app.Dots.Density = calcDotDensityAlongZ(app.Dots.Settings, getFilteredObjects(app.Dots, app.Dots.Filter), app.Mask, 100, false);
                    % If the cell has a skeleton proceed with these additional steps
                else
                    %                     app.Skel = calcSkelPathLength(app.Skel, false);
                    %                     saveSkel(app.Skel);
                    %
                    app.Dots = distDotsToCellBody(app.Dots, app.Skel, app.Dots.Settings);
                    app.Dots = distDotsToSkel(app.Dots, app.Skel, app.Dots.Settings);
                    saveObjects(app.Dots, 'Skel');
                    
                    % Generate heatmaps of object density
                    app.Dots.Density = calcDensity(app.Dots.Settings, app.Dots, app.Skel, false);
                    % Calculate linear density along the skeleton segments
                    assignin('base', 'Dots', app.Dots);
                    assignin('base', 'Skel', app.Skel);
                    app.Dots.Density.PathLengthStats = calcPathLengthStats(app.Dots, app.Skel, false);
                end
                saveObjects(app.Dots, 'Density');
                app.RefreshValidObjects;
                app.cmbObjsDetectedValueChanged;
                close(dlg);
            end
        end

        % Button pushed function: btnOpenExp
        function btnOpenExpPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if tmpDir
                cd(tmpDir);
                app.RefreshHomeFolder(false, true);
            end
            
            close(dlg);
        end

        % Button pushed function: btnPlotLocations
        function btnPlotLocationsPushed(app, event)
            if isempty(app.Dots)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects location...','Indeterminate', 'on');
            
            % Plot en-face view
            Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
            PosXY = zeros(numel(Objs.Vol), 2);
            for i = 1:numel(Objs.Vol)
                PosXY(i,:)= mean(Objs.Vox(i).Pos(:,1:2));
            end
            
            figure('Name', 'Objects locations', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
            subplot(1,2,1);
            hold on;
            scatter(PosXY(:,1), PosXY(:,2), 50, 'o', 'MarkerEdgeColor', [0 0 0], 'MarkerFaceColor', [0 1 0]);
            xlim([0 app.Dots.Settings.ImInfo.xNumVox]);
            ylim([0 app.Dots.Settings.ImInfo.yNumVox]);
            box on;
            set(gca, 'XTick', [], 'YTick', []);
            
            % Plot side-view
            PosYZ = zeros(numel(Objs.Vol), 2);
            for i=1:numel(Objs.Vol)
                PosYZ(i,:)= mean(Objs.Vox(i).Pos(:,2:3));
            end
            
            subplot(1,2,2);
            hold on;
            scatter(PosYZ(:,1), PosYZ(:,2), 50, 'o', 'MarkerEdgeColor', [0 0 0], 'MarkerFaceColor', [0 1 0]);
            xlim([0 app.Dots.Settings.ImInfo.yNumVox]);
            ylim([0 app.Dots.Settings.ImInfo.zNumVox]);
            box on;
            set(gca, 'XTick', [], 'YTick', []);
            
            close(dlg);
        end

        % Button pushed function: btnPlotSkeleton
        function btnPlotSkeletonPushed(app, event)
            if isempty(app.Skel)
                msgbox('No skeleton available.', 'Plot Error', 'error');
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting skeleton...','Indeterminate', 'on');
            
            % Load image info (size, resolution)
            if isempty(app.Dots)
                tmpAns = inputdlg({'Image size X (px):','Image size Y (px):','Image size Z (px):','XY resolution (um/px) :','Z resolution (um/px):'}, 'Skeleton Info', 1,{'2048','2048','50','1','1'});
                if isempty(tmpAns)
                    close all;
                    return
                else
                    app.Settings.ImInfo.xNumVox     = str2double(tmpAns(1));
                    app.Settings.ImInfo.yNumVox     = str2double(tmpAns(2));
                    app.Settings.ImInfo.zNumVox     = str2double(tmpAns(3));
                    app.Settings.ImInfo.xyum        = str2double(tmpAns(4));
                    app.Settings.ImInfo.zum         = str2double(tmpAns(5));
                    app.Settings.debug              = false;
                end
                ImInfo = app.Settings.ImInfo;
            else
                ImInfo = app.Dots.Settings.ImInfo;
            end
            app.loadCurrentMask;
            
            figure('units', 'normalized', 'position', [0.1 0.1 0.8 0.7]);
            tiledlayout(1,2);
            
            % En-face view plot
            nexttile;
            hold on;
            % Plot mask MIP if available
            if ~isempty(app.Mask)
                MIP = squeeze(max(app.Mask.I,[],3)); % Create a MIP of each image to display
                imshow(255*(MIP/max(MIP(:))));       % MIP is a binary mask, stretch from 0 to 255
                set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
                daspect([size(MIP,1) size(MIP,2) 1]); % Maintain aspect ratio of original image
                colormap gray(256);
            end
            
            % Plot Soma position as a circle
            if isfield(app.Skel.FilStats, 'SomaPtXYZ') && isfield(app.Skel.FilStats, 'SomaPtRad')
                SomaPos = app.Skel.FilStats.SomaPtXYZ/ImInfo.xyum;
                SomaRad = app.Skel.FilStats.SomaPtRad;
                scatter(SomaPos(1), SomaPos(2), SomaRad, 'o', 'LineWidth', 1.5, 'MarkerFaceColor',[0 .7 .7], 'MarkerEdgeColor', [0 .5 .5]);
            else
                SomaPos = app.Skel.FilStats.aXYZ(1,:)/ImInfo.xyum;
                scatter(SomaPos(1), SomaPos(2), 1000, 'o', 'LineWidth', 1.5, 'MarkerFaceColor',[0 .7 .7], 'MarkerEdgeColor', [0 .5 .5]);
            end
            
            % Plot skeleton en-face
            if isfield(app.Skel.branches, 'XYZ')
                for i = 1:numel(app.Skel.branches)
                    plot(app.Skel.branches(i).XYZ(:,2)/ImInfo.xyum, app.Skel.branches(i).XYZ(:,1)/ImInfo.xyum, 'r-', 'LineWidth', 1.5);
                end
            else
                for i = 1:numel(app.Skel.branches)
                    plot(app.Skel.branches(i).points(:,4)/ImInfo.xyum, app.Skel.branches(i).points(:,5)/ImInfo.xyum, 'r-', 'LineWidth', 1.5);
                end
            end
            xlim([1 ImInfo.xNumVox]);
            ylim([1 ImInfo.yNumVox]);
            title('En-face');
            box on;
            set(gca, 'XTick', [], 'YTick', []);
            pbaspect([1 1 1]);
            
            % Side-view plot
            nexttile;
            hold on;
            % Plot mask MIP if available
            if ~isempty(app.Mask)
                MIP = squeeze(max(app.Mask.I,[],2)); % Create a MIP of each image to display
                MIP = MIP';
                imshow(255*(MIP/max(MIP(:))));       % MIP is a binary mask, stretch from 0 to 255
                daspect([1 1 1]);
                colormap gray(256);
            end
            
            % Plot Soma position as a circle
            if isfield(app.Skel.FilStats, 'SomaPtXYZ') && isfield(app.Skel.FilStats, 'SomaPtRad')
                SomaPos = app.Skel.FilStats.SomaPtXYZ/ImInfo.xyum;
                SomaRad = app.Skel.FilStats.SomaPtRad;
                scatter(SomaPos(2), SomaPos(3), SomaRad, 'o', 'LineWidth', 1.5, 'MarkerFaceColor',[0 .7 .7], 'MarkerEdgeColor', [0 .5 .5]);
            else
                SomaPos = app.Skel.FilStats.aXYZ(1,:)/ImInfo.xyum;
                scatter(SomaPos(2), SomaPos(3), 1000, 'o', 'LineWidth', 1.5, 'MarkerFaceColor',[0 .7 .7], 'MarkerEdgeColor', [0 .5 .5]);
            end
            
            % Plot skeleton side-view
            if isfield(app.Skel.branches, 'XYZ')
                for i = 1:numel(app.Skel.branches)
                    plot(app.Skel.branches(i).XYZ(:,1)/ImInfo.xyum, app.Skel.branches(i).XYZ(:,3)/ImInfo.zum, 'r-', 'LineWidth', 1.5);
                end
            else
                for i = 1:numel(app.Skel.branches)
                    plot(app.Skel.branches(i).points(:,5)/ImInfo.xyum, app.Skel.branches(i).points(:,6)/ImInfo.zum, 'r-', 'LineWidth', 1.5);
                end
            end
            xlim([1 ImInfo.xNumVox]);
            ylim([1 ImInfo.zNumVox]);
            title('Side-view');
            box on;
            set(gca, 'XTick', [], 'YTick', []);
            pbaspect([1 1 1]);
            
            close(dlg);
        end

        % Button pushed function: btnPlotDensityByDepth
        function btnPlotDensityByDepthPushed(app, event)
            % Compute and plot object distribution as function of volume depth
            if isempty(app.Dots)
                return
            end
            
            app.loadCurrentMask;
            
            S.NumberOfBins = 100;
            S = editStruct(S);
            if isempty(S)
                return
            end
            
            % Depth direction in the volume, usually Z=wholemount Y=Sections
            DepthDir = 'Z';
            switch uiconfirm(app.UIFigure, 'Which direction is depth in your volume?','Depth direction','Options', {'Z', 'Y'}, 'DefaultOption',1,'CancelOption',1)
                case 'Y', DepthDir = 'Y';
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Calculating linear density...','Indeterminate', 'on');
            switch DepthDir
                case 'Z', app.Dots.Density = calcDotDensityAlongZ(app.Dots.Settings, getFilteredObjects(app.Dots, app.Dots.Filter), app.Mask, S.NumberOfBins, false);
                case 'Y', app.Dots.Density = calcDotDensityAlongY(app.Dots.Settings, getFilteredObjects(app.Dots, app.Dots.Filter), app.Mask, S.NumberOfBins, false);
            end
            BinsPercent = (1:app.Dots.Density.binNum)*100/app.Dots.Density.binNum;
            
            figure('Name', ['Density of ' app.Dots.Name ' by depth'], 'NumberTitle', 'off', 'Units',"normalized", 'Position', [0.2, 0.2, 0.6, 0.6]);
            tiledlayout(1,2);
            
            % Plot absolute number of dot by depth
            nexttile;
            switch DepthDir
                case 'Z', BinSize = app.Dots.Settings.ImInfo.zum * app.Dots.Density.binSize;
                case 'Y', BinSize = app.Dots.Settings.ImInfo.xyum * app.Dots.Density.binSize;
            end
            ObjectsNum = app.Dots.Density.densityPerc;
            plot(BinsPercent, ObjectsNum, 'k', 'MarkerSize', 8);
            
            if ~isempty(app.Mask)
                hold on;
                plot(BinsPercent, app.Dots.Density.densityPercMask, 'r', 'MarkerSize', 8);
                legend({'Full volume', 'Within mask'},"Box","off");
            end
            
            box off;
            xlim([0 100]);
            set(gca, 'XTick', 0:10:100);
            set(gca, 'YTickMode', 'auto');
            set(gca, 'color', 'none',  'TickDir','out');
            ylabel('Number of objects');
            xlabel(['Depth percentage (' num2str(100/app.Dots.Density.binNum) '% bin size: ' num2str(BinSize, 2) 'um)']);
            
            % Plot dot density by depth
            nexttile;
            
            switch DepthDir
                case 'Z'
                    BinVolume = app.Dots.Settings.ImInfo.xyum * app.Dots.Settings.ImInfo.xNumVox...
                        *app.Dots.Settings.ImInfo.xyum * app.Dots.Settings.ImInfo.yNumVox...
                        *app.Dots.Settings.ImInfo.zum  * app.Dots.Density.binSize;
                case 'Y'
                    BinVolume = app.Dots.Settings.ImInfo.xyum * app.Dots.Settings.ImInfo.xNumVox...
                        *app.Dots.Settings.ImInfo.xyum * app.Dots.Density.binSize...
                        *app.Dots.Settings.ImInfo.zum  * app.Dots.Settings.ImInfo.zNumVox;
            end
            ObjectsDensity = app.Dots.Density.densityPerc / BinVolume;
            plot(BinsPercent, ObjectsDensity, 'k', 'MarkerSize', 8);
            
            DensityPercMask = app.Dots.Density.densityPercMask;
            if ~isempty(app.Mask)
                hold on;
                MaskedVoxN = numel(find(app.Mask.I));
                MaskBinVolume = app.Dots.Settings.ImInfo.xyum *app.Dots.Settings.ImInfo.xyum *app.Dots.Settings.ImInfo.zum ...
                    * MaskedVoxN / app.Dots.Density.binNum;
                DensityPercMask = app.Dots.Density.densityPercMask / MaskBinVolume;
                plot(BinsPercent, DensityPercMask, 'r', 'MarkerSize', 8);
                legend({'Full volume', 'Within mask'},"Box","off");
            end
            
            box off;
            xlim([0 100]);
            set(gca, 'XTick', 0:10:100);
            set(gca, 'YTickMode', 'auto');
            set(gca, 'color', 'none',  'TickDir','out');
            ylabel('Density (objects/µm^3)');
            xlabel(['Depth percentage (' num2str(100/app.Dots.Density.binNum) '% bin size: ' num2str(BinSize, 2) 'um)']);
            
            % Export data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_DensityByDepth.csv'];
            DepthPerc   = BinsPercent';                     % Number of object on first column
            ObjectsNum  = ObjectsNum';
            ObjectsDensity = ObjectsDensity';
            ObjectsNumInMask = app.Dots.Density.densityPercMask';
            ObjectsDensityInMask = DensityPercMask';
            TableData   = table(DepthPerc, ObjectsNum, ObjectsDensity, ObjectsNumInMask, ObjectsDensityInMask);
            writetable(TableData, FileName);            % Write an Excel table with Depth-Density pairs
            
            close(dlg);
        end

        % Button pushed function: btnPlotDensityLinear
        function btnPlotDensityLinearPushed(app, event)
            
            if isempty(app.Dots) || isempty(app.Skel)
                msgbox('No skeleton or objects available.', 'Plot Error', 'error');
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Calculating linear density...','Indeterminate', 'on');
            Objs                = getFilteredObjects(app.Dots, app.Dots.Filter);
            DotPathLengthList   = app.Skel.FilStats.SkelPathLength2Soma(Objs.Skel.ClosestSkelIDs); % For each object length of path to soma
            EdgePathLengthList  = app.Skel.FilStats.EdgePathLength2Soma; % For each skeleton segment, path length to soma
            EdgeLengthList      = app.Skel.SegStats.Lengths; % List of lengths of each skeleton segment
            EdgePathLengthMax   = ceil(max(EdgePathLengthList));
            
            DistBin             = 10; % Bin distance is 10 micron by default
            DistFromSoma        = 5:1:EdgePathLengthMax;
            NumDots             = zeros(1, length(DistFromSoma));
            EdgeLengths         = zeros(1, length(DistFromSoma));
            for i = 1 : length(DistFromSoma)
                NumDots(i)      = length(find((DotPathLengthList>DistFromSoma(i)-DistBin/2) & (DotPathLengthList<=DistFromSoma(i)+DistBin/2)));
                EdgeLengths(i)  = sum(EdgeLengthList((EdgePathLengthList>DistFromSoma(i)-DistBin/2) & (EdgePathLengthList<=DistFromSoma(i)+DistBin/2)));
            end
            LinearDensity       = NumDots./EdgeLengths;
            
            DepthBin = 2; % Data is binned every two z slices
            LinDensityByDepth = zeros(1,Objs.Settings.ImInfo.zNumVox);
            
            for z = 1 : Objs.Settings.ImInfo.zNumVox
                MaskZ = (Objs.Pos(:,3) > z-DepthBin/2) & (Objs.Pos(:,3) <= z+DepthBin/2);
                for i = 1 : length(DistFromSoma)
                    NumDots(i)      = length(find((DotPathLengthList(MaskZ)>DistFromSoma(i)-DistBin/2) & (DotPathLengthList(MaskZ)<=DistFromSoma(i)+DistBin/2)));
                    EdgeLengths(i)  = sum(EdgeLengthList((EdgePathLengthList>DistFromSoma(i)-DistBin/2) & (EdgePathLengthList<=DistFromSoma(i)+DistBin/2)));
                end
                LinDensity          = NumDots./EdgeLengths;
                LinDensityByDepth(z)= mean(LinDensity>0); % As some distances from soma might not exist at the current Z depth, average only those for which there is a non-zero density
            end
            
            figure('Name', ['Density of ' Objs.Name ' along skeleton'], 'NumberTitle', 'off', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
            
            % Left panel: objects density by distance from cell body measured along skel
            subplot(1,2,1);
            plot(DistFromSoma, LinearDensity, 'b', 'LineWidth', 2);
            xlim([0 EdgePathLengthMax]);
            ylim([0 max(1, max(LinearDensity))]);
            xlabel(['Distance from cell body (µm, binning = ' num2str(DistBin) ')']);
            ylabel('Linear density (average objects / µm)');
            
            % Right panel: objects density by depth in the volume
            subplot(1,2,2);
            plot((1:Objs.Settings.ImInfo.zNumVox).*Objs.Settings.ImInfo.zum, LinDensityByDepth, 'b', 'LineWidth', 2);
            xlabel(['Depth (µm), binning = ' num2str(DepthBin*Objs.Settings.ImInfo.zum) ' µm']);
            xlim([0 Objs.Settings.ImInfo.zNumVox*Objs.Settings.ImInfo.zum]);
            ylim([0 max(1, max(LinDensityByDepth))]);
            
            % Export data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_DensityAlongSkel.xls'];
            Distance    = DistFromSoma';                % Distance from the skeleton's origin point (usually cell soma)
            Density     = LinearDensity';               % Objects density at each distance from skeleton origin point
            TableData   = table(Distance, Density);
            writetable(TableData, FileName);            % Write an Excel table with Distance-Density pairs
            
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_DensityAlongSkelByDepth.xls'];
            SliceNum    = (1:numel(LinDensityByDepth))';    % Depth in the volume (slice number)
            DepthMicron = SliceNum*Objs.Settings.ImInfo.zum;% Depth in the volume (micron)
            Density     = LinDensityByDepth';               % Objects linear density by depth
            TableData   = table(SliceNum, DepthMicron, Density);
            writetable(TableData, FileName);                % Write an Excel table with Distance-Density pairs
            close(dlg);
        end

        % Button pushed function: btnPlotSholl
        function btnPlotShollPushed(app, event)
            if isempty(app.Skel)
                msgbox('No skeleton available.', 'Plot Error', 'error');
                return
            end
            
            % Ask usre what stepping size to use for the analysis
            tmpAns = inputdlg('Sholl stepping size (um):', 'Sholl settings', 1,{'1'});
            if isempty(tmpAns)
                return
            else
                ShollSteps = str2double(tmpAns(1));
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Calculating Sholl analysis...','Indeterminate', 'on');
            
            % Calculate Sholl analysis
            app.Skel.Sholl = shollAnalysis(app.Skel, ShollSteps, false);
            
            % Sholl Plot
            figure;
            Distance = app.Skel.Sholl.intersections(:,1);
            Intersections = app.Skel.Sholl.intersections(:,2);
            plot(Distance, Intersections, 'b', 'LineWidth', 2);
            xlim([0 Distance(end)]);
            ylim([0 max(1,max(Intersections))]);
            xlabel('Distance from cell body (µm)');
            ylabel('Sholl intersections');
            
            % Export data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName        = [pwd filesep 'results' filesep 'Skeleton_ShollAnalysis.xls'];
            TableData       = table(Distance, Intersections);
            writetable(TableData, FileName);                      % Write an Excel table with Distance-Intersections pairs
            close(dlg);
        end

        % Button pushed function: btnPlotHeatmap
        function btnPlotHeatmapPushed(app, event)
            % Drap heatmap of linear density (objects over skeleton filament)
            if isempty(app.Dots) || isempty(app.Skel)
                msgbox('No skeleton available.', 'Plot Error', 'error');
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Calculating density heatmap...','Indeterminate', 'on');
            
            assignin('base','Dots',app.Dots);
            assignin('base','Skel',app.Skel);
            Density = calcDensity(app.Dots.Settings, app.Dots, app.Skel, false);
            LinearDensity = Density.DotDist ./ Density.DendDist;
            
            % Ask user for a upper limit to plotted density color
            % Any value upper than limit will be drawn in pure red
            S.MaxDensityLimit = max(LinearDensity(:));
            S = editStruct(S, 'Upper Limit');
            
            figure;
            image(LinearDensity * 256 / S.MaxDensityLimit);
            
            % Define a heatmap colormap with black for zero
            cmap = jet(256);
            cmap(1,:) = 0;
            colormap(cmap);
            
            title(['Linear density (objects/µm, blue=0 red=' num2str(S.MaxDensityLimit)]);
            colorbar('Ytick',[]);
            axis image; % adding color bar changes image x y dimention, so re-square the main panel
            axis off    % remove the axis
            close(dlg);
        end

        % Callback function
        function SchedulerValueChanged(app, event)
            if app.Scheduler.Value
                app.ScheduledTime.Enable = 'on';
            else
                app.ScheduledTime.Enable = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.Timer)
                stop(app.Timer);
            end
            delete(app);
        end

        % Button pushed function: btnBatchAdd
        function btnBatchAddButtonPushed(app, event)
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if ~tmpDir
                return
            end
            
            % Ensure the experiment data format matches current ObjectFinder version
            cd(tmpDir);
            if exist('find','dir')
                convertObjectFinderDataV3toV4;
                convertObjectFinderDataV4toV5;
                convertObjectFinderDataV5toV7;
                convertObjectFinderDataV7toV8;
            elseif exist('Density.mat','file')
                convertObjectFinderDataV4toV5;
                convertObjectFinderDataV5toV7;
                convertObjectFinderDataV7toV8;
            elseif ~exist('objects','dir') && exist('Dots.mat','file')
                convertObjectFinderDataV5toV7;
                convertObjectFinderDataV7toV8;
            elseif exist('Skel.mat','file') || isfolder([pwd filesep 'data']) || isfolder([pwd filesep 'images'])
                convertObjectFinderDataV7toV8;
            end
            
            if isempty(app.lstBatch.Items)
                app.lstBatch.Items = {tmpDir};
            else
                app.lstBatch.Items{end+1} = tmpDir;
            end
            
            app.RefreshAutomateTab;
            app.lstBatchValueChanged;
        end

        % Button pushed function: btnBatchRemove
        function btnBatchRemoveButtonPushed(app, event)
            idx = strcmp(app.lstBatch.Items, app.lstBatch.Value);
            app.lstBatch.Items(idx) = [];
            app.RefreshAutomateTab;
            app.lstBatchValueChanged;
        end

        % Button pushed function: btnBatchClear
        function btnBatchClearButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Remove all items from list?','Delete items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    app.lstBatch.Items = {};
                    app.lstBatchValueChanged;
                    app.RefreshAutomateTab;
            end
        end

        % Button pushed function: btnBatchDown
        function btnBatchDownButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx < numel(app.lstBatch.Items)
                app.lstBatch.Items(idx) = app.lstBatch.Items(idx+1);
                app.lstBatch.Items{idx+1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchUp
        function btnBatchUpButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx > 1
                app.lstBatch.Items(idx) = app.lstBatch.Items(idx-1);
                app.lstBatch.Items{idx-1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchBottom
        function btnBatchBottomButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx < numel(app.lstBatch.Items)
                app.lstBatch.Items(idx) = [];
                app.lstBatch.Items{end+1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchTop
        function btnBatchTopButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx > 1
                app.lstBatch.Items(idx) = [];
                app.lstBatch.Items = [{val}, app.lstBatch.Items];
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchStart
        function btnBatchStartButtonPushed(app, event)
            % Batch process directories added to batch list
            % Use the table *TableData* to write any info you want to
            % accumulate and save at the end of the batch processing
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Batch processing...','Value', 0, 'Cancelable', 'on');
            
            % Delay execution of batch processing if timer is enabled
            if app.BatchScheduler.Value
                if app.BatchScheduledTime.Value > hour(datetime)
                    TimerDelay = round(app.BatchScheduledTime.Value*60 - hour(datetime)*60 - minute(datetime)); % Custom time is today
                else
                    TimerDelay = round(app.BatchScheduledTime.Value+24)*60 - hour(datetime)*60 - minute(datetime); % Custom time is tomorrow
                end
                app.BatchLog.Value{end+1} = ['Waiting ' num2str(TimerDelay) ' minutes, then start searching for objects... '];
                app.Timer = timer('TimerFcn',@(~,~)disp('Time is up.'),'StartDelay',TimerDelay*60);
                start(app.Timer);
                wait(app.Timer);
                delete(app.Timer);
                app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'Timer is up! starting analysis.'];
            end
            
            app.BatchLog.Value = {['-- Starting batch on ' datestr(now)]};
            app.BatchLog.Value{end+1} = '';
            TableData = table;
            RootFolder = pwd;
            
            for i = 1:numel(app.lstBatch.Items)
                path = regexp(app.lstBatch.Items{i},filesep,'split');
                app.BatchLog.Value{end+1} = ['Processing item # ' num2str(i) ': ' path{end}];
                disp(['Processing item # ' num2str(i) ': ' path{end}]);
                dlg.Title = ['Processing folder ' num2str(i) '/' num2str(numel(app.lstBatch.Items)) ' : ' path{end}];
                dlg.Value = i/numel(app.lstBatch.Items);
                
                % Load files of the current item to process
                app.lstBatch.Value = app.lstBatch.Items{i}; pause(0.2);
                cd(app.lstBatch.Items{i});
                if ~isfolder([pwd filesep 'I'])
                    app.BatchLog.Value{end+1} = ['Error: Working folder not valid for analysis ' pwd];
                    continue;
                end
                
                % Process current folder depending on selected batch task
                if app.AutomateOperation.SelectedTab == app.AutoSearchTab
                    % ---- Batch Search Objects in Image stack ----
                    app.Settings  = struct;
                    app.Settings.ImInfo.PostChName = app.cmbAutoDetectImage.Value;
                    app.Settings.ImInfo.PostCh = [];
                    app.Settings.ImInfo.MaskChName = app.cmbAutoDetectMask.Value;
                    app.Settings.ImInfo.MaskCh = [];
                    app.Dots = [];
                    app.Mask = [];
                    app.Post = [];
                    
                    % Load image to analyze
                    [app.Post.I, app.Post.ImInfo, app.Post.MIP, app.Post.ImRes] = loadImage(app.cmbAutoDetectImage.Value);
                    app.Post.Name = app.cmbAutoDetectImage.Value;
                    app.Settings.ImInfo.xyum = app.Post.ImRes(1);
                    app.Settings.ImInfo.zum = app.Post.ImRes(3);
                    app.Settings.ImInfo.xNumVox = size(app.Post.I,2);
                    app.Settings.ImInfo.yNumVox = size(app.Post.I,1);
                    app.Settings.ImInfo.zNumVox = size(app.Post.I,3);
                    saveSettings(app.Settings);
                    
                    % Load mask to analyze
                    if ~isempty(app.Settings.ImInfo.MaskChName)
                        [app.Mask.I, app.Mask.ImInfo, app.Mask.MIP, app.Mask.ImRes] = loadImage(app.cmbAutoDetectMask.Value);
                        app.Mask.Name = app.cmbAutoDetectMask.Value;
                        app.Mask.I = app.Mask.I / max(app.Mask.I(:)); % Normalize mask max value to 1
                    end
                    
                    % Calculate volume of the minimum / maximum object sizes allowed, assuming they are spheres
                    MaxDotSize = app.convertDiameterToVoxels(app.BatchMaxSizeXY.Value, app.Post.ImRes(1), app.Post.ImRes(3));
                    MinDotSize = app.convertDiameterToVoxels(app.BatchMinSizeXY.Value, app.Post.ImRes(1), app.Post.ImRes(3));
                    
                    app.Settings.objfinder.blockBuffer      = ceil(1.1*app.BatchMinSizeXY.Value/app.Settings.ImInfo.xyum); % Overlapping buffer region between search block should be at least as big as the smallest dot we want to measure to make sure we are not missing any.
                    app.Settings.objfinder.blockSize        = max(64, ceil(3*app.BatchMaxSizeXY.Value/app.Settings.ImInfo.xyum));
                    app.Settings.objfinder.maxDotSize       = MaxDotSize;                   % max dot size exclusion criteria.
                    app.Settings.objfinder.minDotSize       = MinDotSize;                   % min dot size exclusion criteria.
                    app.Settings.objfinder.watershed        = app.BatchWatershed.Value;     % False if you want to skip watershed segmentation during object search
                    app.Settings.objfinder.blockSearch      = app.BatchBlockSearch.Value;   % True to enable searching in blocks for multithreaded speed
                    app.Settings.objfinder.sphericity       = app.BatchSphericity.Value;    % True if you want to calculare sphericity
                    app.Settings.objfinder.minIntensity     = app.BatchMinIntensity.Value;  % Minumum allowed voxel intensity (# times the local noise of the block)
                    app.Settings.objfinder.localNoise       = app.BatchLocalNoise.Value;    % If true noise level will be computer for each block, if false only one noise level per entire image
                    switch app.cmbBatchNoiseEstimator.Value
                        case 'Most common intensity', app.Settings.objfinder.noiseEstimator = 'mode';
                        case 'Standard deviation',    app.Settings.objfinder.noiseEstimator = 'std';
                        case 'Minimum intensity',     app.Settings.objfinder.noiseEstimator = 'min';
                    end
                    
                    % Seach objects inside the masked volume
                    app.BatchLog.Value{end+1}               = 'Searching objects... '; pause(0.2);
                    switch app.cmbBatchSegmentation.Value
                        case 'Iterative thresholding'
                            app.Settings.objfinder.method = 'IterativeThresholding';
                            if isempty(app.Mask)
                                Objs = findObjects(app.Post.I, app.Settings);
                            else
                                Objs = findObjects(app.Post.I.*app.Mask.I, app.Settings);
                            end
                        case 'Simple thresholding'
                            app.Settings.objfinder.method = 'SimpleThresholding';
                            if isempty(app.Mask)
                                Objs = findObjectsSimple(app.Post.I, app.Settings);
                            else
                                Objs = findObjectsSimple(app.Post.I.*app.Mask.I, app.Settings);
                            end
                    end
                    app.BatchLog.Value{end}                 = [app.BatchLog.Value{end} 'DONE'];
                    
                    % Create fields about sphericity of objects (Rounding)
                    Objs.Shape = struct;
                    if app.BatchSphericity.Value
                        app.BatchLog.Value{end+1}           = 'Calculating sphericity of objects... ';
                        Objs                                = fitSphere(Objs, app.Settings);
                        app.BatchLog.Value{end}             = [app.BatchLog.Value{end} 'DONE'];
                    end
                    
                    % Assign a name to Objects base on the raw image filename
                    try
                        [~,Objs.Name,~] = fileparts(app.Settings.ImInfo.PostChName);
                    catch
                        Objs.Name = 'MyObjects';
                    end
                    
                    % Filter objects according to filter panel settings
                    app.Settings.Filter.EdgeDotCut              = app.BatchEdgeDotCut.Value;
                    app.Settings.Filter.SingleZDotCut           = app.BatchSingleZDotCut.Value;
                    app.Settings.Filter.xyStableDots            = app.BatchxyStableDots.Value;
                    app.Settings.Filter.Thresholds.ITMax        = 0;
                    app.Settings.Filter.Thresholds.ITMaxDir     = 1;
                    app.Settings.Filter.Thresholds.Vol          = 0;
                    app.Settings.Filter.Thresholds.VolDir       = 1;
                    app.Settings.Filter.Thresholds.MeanBright   = 0;
                    app.Settings.Filter.Thresholds.MeanBrightDir = 1;
                    app.Settings.Filter.Thresholds.Oblong       = 0;
                    app.Settings.Filter.Thresholds.OblongDir    = 1;
                    app.Settings.Filter.Thresholds.PrincipalAxisLen = 0;
                    app.Settings.Filter.Thresholds.PrincipalAxisLenhDir = 1;
                    app.Settings.Filter.Thresholds.Zposition    = 0;
                    app.Settings.Filter.Thresholds.ZpositionDir = 1;
                    app.Settings.Filter.Thresholds2.ITMax       = 0;
                    app.Settings.Filter.Thresholds2.ITMaxDir    = 1;
                    app.Settings.Filter.Thresholds2.Vol         = 0;
                    app.Settings.Filter.Thresholds2.VolDir      = 1;
                    app.Settings.Filter.Thresholds2.MeanBright  = 0;
                    app.Settings.Filter.Thresholds2.MeanBrightDir = 1;
                    app.Settings.Filter.Thresholds2.Oblong      = 0;
                    app.Settings.Filter.Thresholds2.OblongDir   = 1;
                    app.Settings.Filter.Thresholds2.PrincipalAxisLen = 0;
                    app.Settings.Filter.Thresholds2.PrincipalAxisLenhDir = 1;
                    app.Settings.Filter.Thresholds2.Zposition   = 0;
                    app.Settings.Filter.Thresholds2.ZpositionDir = 1;
                    
                    switch app.cmbBatchThreshDir.Value
                        case '>=', filterDir = 1;
                        case '<=', filterDir = 2;
                    end
                    switch app.cmbBatchThreshParam.Value
                        case 'Score'
                            app.Settings.Filter.Thresholds.ITMax         = app.txtBatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.ITMaxDir      = filterDir;
                        case 'Size'
                            app.Settings.Filter.Thresholds.Vol           = app.txtBatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.VolDir        = filterDir;
                        case 'Brightness'
                            app.Settings.Filter.Thresholds.MeanBright    = app.txtBatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.MeanBrightDir = filterDir;
                        case 'Roundness'
                            app.Settings.Filter.Thresholds.Oblong        = app.txtBatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.OblongDir     = filterDir;
                        case 'Major Axis Lenght'
                            app.Settings.Filter.Thresholds.PrincipalAxisLen     = app.txtBatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.PrincipalAxisLenhDir = filterDir;
                        case 'Z Position'
                            app.Settings.Filter.Thresholds.Zposition     = app.txtBatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.ZpositionDir  = filterDir;
                    end
                    
                    switch app.cmbBatchThresh2Dir.Value
                        case '>=', filter2Dir = 1;
                        case '<=', filter2Dir = 2;
                    end
                    switch app.cmbBatchThresh2Param.Value
                        case 'Score'
                            app.Settings.Filter.Thresholds2.ITMax         = app.txtBatchThresh2Value.Value;
                            app.Settings.Filter.Thresholds2.ITMaxDir      = filter2Dir;
                        case 'Size'
                            app.Settings.Filter.Thresholds2.Vol           = app.txtBatchThresh2Value.Value;
                            app.Settings.Filter.Thresholds2.VolDir        = filter2Dir;
                        case 'Brightness'
                            app.Settings.Filter.Thresholds2.MeanBright    = app.txtBatchThresh2Value.Value;
                            app.Settings.Filter.Thresholds2.MeanBrightDir = filter2Dir;
                        case 'Roundness'
                            app.Settings.Filter.Thresholds2.Oblong        = app.txtBatchThresh2Value.Value;
                            app.Settings.Filter.Thresholds2.OblongDir     = filter2Dir;
                        case 'Major Axis Lenght'
                            app.Settings.Filter.Thresholds2.PrincipalAxisLen     = app.txtBatchThresh2Value.Value;
                            app.Settings.Filter.Thresholds2.PrincipalAxisLenhDir = filter2Dir;
                        case 'Z Position'
                            app.Settings.Filter.Thresholds2.Zposition     = app.txtBatchThresh2Value.Value;
                            app.Settings.Filter.Thresholds2.ZpositionDir  = filter2Dir;
                    end
                    
                    app.BatchLog.Value{end+1} = 'Filtering objects... '; pause(0.2);
                    Objs.Settings = app.Settings;
                    Objs.Filter = filterObjects(Objs, app.Settings.Filter);
                    
                    % Add structs placeholder for parameters calculated later
                    Objs.Density = struct; % Density measurements
                    Objs.NN      = struct; % Nearest neighbor measurements
                    Objs.Coloc   = struct; % Colocalization measurements
                    Objs.Skel    = struct; % Skeleton measurements (e.g distance to cell body)
                    
                    % If a set of objects with the same name already exists overwrite them, otherwise append
                    [ObjNames, ObjUIDs] = listObjects;
                    AlreadyPresent  = false;
                    for j = 1:numel(ObjNames)
                        if strcmp(ObjNames{j}, Objs.Name)
                            Objs.UID = ObjUIDs{j};
                            app.Dots = Objs;
                            AlreadyPresent = true;
                            break
                        end
                    end
                    
                    if ~AlreadyPresent
                        Objs.UID = generateUID;
                        app.Dots = Objs;
                    end
                    saveObjects(app.Dots);
                    saveSettings(app.Settings);
                    
                    % If selected by user, create a table with statistics
                    if app.chkAutoSaveTable.Value
                        FolderName  = strsplit(pwd,filesep);
                        TotalObjs   = numel(Objs.Filter.passF);
                        ValidObjs   = numel(find(Objs.Filter.passF == 1));
                        ValidPerc   = 100*ValidObjs/TotalObjs;
                        VoxelVolume = Objs.Settings.ImInfo.xyum * Objs.Settings.ImInfo.xyum * Objs.Settings.ImInfo.zum;
                        
                        % Calculate image Properties
                        SrcImgRes     = [Objs.Settings.ImInfo.xyum, Objs.Settings.ImInfo.xyum, Objs.Settings.ImInfo.zum];
                        SrcImgSizePx  = [Objs.Settings.ImInfo.xNumVox, Objs.Settings.ImInfo.yNumVox, Objs.Settings.ImInfo.zNumVox];
                        SrcImgSizeUm  = SrcImgSizePx.*SrcImgRes;
                        SrcImgVolPx   = SrcImgSizePx(1)*SrcImgSizePx(2)*SrcImgSizePx(3);
                        if isempty(app.Mask)
                            MaskSizePx    = 0;
                        else
                            MaskSizePx    = numel(find(app.Mask.I));
                        end
                        MaskSizeUm    = MaskSizePx * (SrcImgRes(1) * SrcImgRes(2) * SrcImgRes(3));
                        ObjsMask      = zeros(Objs.Settings.ImInfo.yNumVox, Objs.Settings.ImInfo.xNumVox, Objs.Settings.ImInfo.zNumVox, 'uint8');
                        for obj = 1:numel(Objs.Vox)
                            ObjsMask(Objs.Vox(obj).Ind) = 1;
                        end
                        
                        % Calculate Objects properties
                        BrightnessAvg = round(mean(Objs.MeanBright));
                        BrightnessSD  = round(std(Objs.MeanBright));
                        ObjSizeAvg    = mean(Objs.Vol)*VoxelVolume;
                        ObjSizeSD     = std(Objs.Vol)*VoxelVolume;
                        MaskSizePerc  = 100* MaskSizePx / SrcImgVolPx;
                        ImgVolOccup   = 100*sum(Objs.Vol)/SrcImgVolPx;
                        DensityImg    = ValidObjs / SrcImgSizeUm;
                        if isempty(app.Mask)
                            MaskVolOccup = 0;
                            DensityMask = 0;
                        else
                            MaskVolOccup  = 100*numel(find(ObjsMask(app.Mask.I==1)))/MaskSizePx;
                            DensityMask = ValidObjs / MaskSizeUm;
                        end
                        
                        RowData = table(FolderName(end), {Objs.Name}, TotalObjs, ValidObjs, ValidPerc, BrightnessAvg, BrightnessSD, ObjSizeAvg, ObjSizeSD, MaskSizePerc, ImgVolOccup, MaskVolOccup, DensityImg, DensityMask, SrcImgSizeUm, MaskSizeUm,...
                            'VariableNames', {'Folder', 'Objects', 'TotalNum', 'ValidNum', 'ValidPerc', 'BrightnessAvg', 'BrightnessSD', 'ObjSizeAvg', 'ObjSizeSD', 'MaskSizePerc', 'PercObjOccupancyImg', 'PercObjOccupancyMask', 'DesityInImg', 'DensityInMask', 'SrcImgSizeUm','MaskSizeUm'});
                        
                        % Accumulate data of current mask size as table rows
                        if isempty(TableData)
                            TableData = RowData;
                        else
                            TableData = [TableData; RowData]; %#ok
                        end
                    end
                    
                    app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                    app.BatchLog.Value{end+1} = ['Number of valid objects: ' num2str(numel(find(Objs.Filter.passF)))];
                    app.BatchLog.Value{end+1} = ' '; pause(0.5);
                end
                
                if app.AutomateOperation.SelectedTab == app.AutoExportMaskTab
                    % ---- Batch Export Objects Mask ----
                    
                    % Find out which objects names and UID to process
                    if app.chkAutoColoExportAll.Value
                        [~, srcObjUIDs] = listObjects;
                    else
                        [srcObjNames, srcObjUIDs] = listObjects;
                        srcIdx = strcmp(app.AutoColoExportRefObjs.Value, srcObjNames);
                        srcObjUIDs = srcObjUIDs(srcIdx);
                    end
                    
                    for idx = 1:numel(srcObjUIDs)
                        srcDots = loadObjects(srcObjUIDs{idx});
                        
                        if isempty(srcDots)
                            continue
                        end
                        switch app.cmbAutoReportMaskType.Value
                            case 'Binary Mask'
                                % Create and save binary mask of objects voxels
                                Objs = getFilteredObjects(srcDots, srcDots.Filter);
                                ImInfo = srcDots.Settings.ImInfo;
                                ObjsMask = zeros([ImInfo.xNumVox ImInfo.yNumVox ImInfo.zNumVox], 'uint8');
                                for objIdx = 1:numel(Objs.Vox)
                                    ObjsMask(Objs.Vox(objIdx).Ind) = 1;
                                end
                                saveastiff(ObjsMask, [pwd filesep 'I' filesep srcDots.Name '_objects_mask.tif']);
                                
                            case 'Colored Mask'
                                Objs = getFilteredObjects(srcDots, srcDots.Filter);
                                ImInfo = srcDots.Settings.ImInfo;
                                Ir = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
                                Ig = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
                                Ib = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
                                
                                for objIdx = 1:numel(Objs.Vox)
                                    Ir(Objs.Vox(objIdx).Ind) = randi([0 255]);
                                    Ig(Objs.Vox(objIdx).Ind) = randi([0 255]);
                                    Ib(Objs.Vox(objIdx).Ind) = randi([0 255]);
                                end
                                
                                % Write into a 3D RGB Tiff fle
                                opts.color = true;
                                opts.compression = 'no';
                                opts.overwrite = true;
                                I = cat(4, Ir, Ig, Ib);
                                I = permute(I, [1 2 4 3]);
                                saveastiff(I, [pwd filesep 'I' filesep srcDots.Name '_objects_mask_colored.tif'], opts);
                                
                        end
                    end
                end
                
                if app.AutomateOperation.SelectedTab == app.AutoReportTab
                    % ---- Batch Report Statistic ----
                    
                    % Find out which objects names and UID to process
                    if app.chkAutoReportAll.Value
                        [~, srcObjUIDs] = listObjects;
                    else
                        [srcObjNames, srcObjUIDs] = listObjects;
                        srcIdx = strcmp(app.AutoReportRefObjs.Value, srcObjNames);
                        srcObjUIDs = srcObjUIDs(srcIdx);
                    end
                    
                    if app.chkAutoReportAllDst.Value
                        [~, dstObjUIDs] = listObjects;
                    elseif app.AutoReportDstObjs.Enable
                        [dstObjNames, dstObjUIDs] = listObjects;
                        dstIdx = strcmp(app.AutoReportDstObjs.Value, dstObjNames);
                        dstObjUIDs = dstObjUIDs(dstIdx);
                    else
                    end
                    
                    for idx = 1:numel(srcObjUIDs)
                        dlg.Message = 'Loading objects...';
                        srcDots = loadObjects(srcObjUIDs{idx});
                        if isempty(srcDots)
                            continue
                        end
                        
                        switch app.AutoReportStatistic.Value
                            case 'Summary statistics'
                                % Report average statistics of objects
                                dlg.Message = ['Calculating summary statistics of ' srcDots.Name];
                                
                                % Calculate Volume distribution according to binning preferences
                                Objs        = getFilteredObjects(srcDots, srcDots.Filter);
                                ObjsNum     = numel(find(srcDots.Filter.passF));
                                ObjsTotNum  = numel(srcDots.Filter.passF);
                                ObjsPerc    = 100*ObjsNum/ObjsTotNum;
                                VoxelVolume = Objs.Settings.ImInfo.xyum * Objs.Settings.ImInfo.xyum * Objs.Settings.ImInfo.zum;
                                
                                % Calculate image Properties
                                SrcImgRes     = [Objs.Settings.ImInfo.xyum, Objs.Settings.ImInfo.xyum, Objs.Settings.ImInfo.zum];
                                SrcImgSizePx  = [Objs.Settings.ImInfo.xNumVox, Objs.Settings.ImInfo.yNumVox, Objs.Settings.ImInfo.zNumVox];
                                SrcImgSizeUm  = SrcImgSizePx.*SrcImgRes;
                                SrcImgVolPx   = SrcImgSizePx(1)*SrcImgSizePx(2)*SrcImgSizePx(3);
                                SrcImgVolUm   = SrcImgSizeUm(1)*SrcImgSizeUm(2)*SrcImgSizeUm(3);
                                DensityImg    = ObjsNum / SrcImgVolUm;
                                
                                % Create a binary mask of objects
                                ObjsMask = zeros(Objs.Settings.ImInfo.yNumVox, Objs.Settings.ImInfo.xNumVox, Objs.Settings.ImInfo.zNumVox, 'uint8');
                                for vox = 1:numel(Objs.Vox)
                                    ObjsMask(Objs.Vox(vox).Ind) = 1;
                                end
                                ObjsOccupancyImg  = 100*sum(Objs.Vol)/SrcImgVolPx;
                                ObjsOccupancyMask = ObjsOccupancyImg;
                                
                                % Load mask if present
                                MaskVolPx    = SrcImgVolPx;
                                MaskVolUm    = SrcImgVolUm;
                                DensityMask  = 0;
                                if isfield(Objs.Settings.ImInfo, 'MaskChName') && ~isempty(Objs.Settings.ImInfo.MaskChName)
                                    [M.I, M.ImInfo, M.MIP, M.ImRes] = loadImage(Objs.Settings.ImInfo.MaskChName);
                                    MaxMask = max(unique(M.I));
                                    if MaxMask > 1
                                        M.I(M.I == MaxMask) = 1;
                                    end
                                    M.Name = app.cmbMask.Value;
                                    
                                    MaskVolPx    = numel(find(M.I));
                                    MaskVolUm    = MaskVolPx * (SrcImgRes(1) * SrcImgRes(2) * SrcImgRes(3));
                                    ObjsOccupancyMask = 100*numel(find(ObjsMask(M.I==1)))/MaskVolPx;
                                    DensityMask = ObjsNum / MaskVolUm;

                                end
                                MaskVolPerc = 100* MaskVolPx / SrcImgVolPx;
                                
                                
                                % Populate the grand table of batch analysis
                                VarNames = {'Folder', 'Objects', 'ValidNumber', 'ValidPercent', 'TotalNumber', 'BrightnessAvg', 'BrightnessSD', 'VolumeAvg', 'VolumeSD', 'SourceImage', 'VoxelVolume', 'ImageVolume', 'MaskedVolume', 'MaskedVolumePercent', 'DensityInImage', 'DensityInMask', 'ObjVolumeOccupancyOfMask', 'ObjVolumeOccupancyOfImage'};
                                RowData = table({pwd}, {Objs.Name}, ObjsNum, ObjsPerc, ObjsTotNum, mean(Objs.MeanBright), std(Objs.MeanBright), mean(Objs.Vol)*VoxelVolume, std(Objs.Vol)*VoxelVolume, {Objs.Settings.ImInfo.PostChName}, VoxelVolume, SrcImgVolUm, MaskVolUm, MaskVolPerc, DensityImg, DensityMask, ObjsOccupancyMask, ObjsOccupancyImg, 'VariableNames', VarNames);
                                if isempty(TableData)
                                    TableData = RowData;
                                else
                                    TableData = [TableData; RowData]; %#ok
                                end
                                
                            case 'Colocalization rate'
                                dlg.Message = ['Measuring colocalization rates for ' srcDots.Name];
                                
                                switch numel(srcDots.Coloc)
                                    case 1
                                        % Retrieve position of colocalized and non-colocalized validated objects
                                        ValidIdx = find(srcDots.Filter.passF);
                                        Colo1idx    = intersect(find(srcDots.Coloc.ColocFlag == 1), ValidIdx);
                                        NonColo1idx = intersect(find(srcDots.Coloc.ColocFlag == 2), ValidIdx);
                                        
                                        % Populate the grand table of batch analysis
                                        VarNames = {'Folder', 'Objects', 'Colocalized with', 'Percent', 'Number'};
                                        RowData = table({pwd}, {srcDots.Name}, {srcDots.Coloc.Fish1}, 100*numel(Colo1idx)/numel(ValidIdx), numel(Colo1idx), 'VariableNames', VarNames);
                                        if isempty(TableData)
                                            TableData = RowData;
                                        else
                                            TableData = [TableData; RowData]; %#ok
                                        end
                                        RowData = table({pwd}, {srcDots.Name}, {'None'}, 100*numel(NonColo1idx)/numel(ValidIdx), numel(NonColo1idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        
                                    case 2
                                        % Retrieve position of colocalized and non-colocalized validated objects
                                        ValidIdx    = find(srcDots.Filter.passF);
                                        Colo1idx    = intersect(find(srcDots.Coloc(1).ColocFlag == 1), ValidIdx);
                                        NonColo1idx = intersect(find(srcDots.Coloc(1).ColocFlag == 2), ValidIdx);
                                        Colo2idx    = intersect(find(srcDots.Coloc(2).ColocFlag == 1), ValidIdx);
                                        NonColo2idx = intersect(find(srcDots.Coloc(2).ColocFlag == 2), ValidIdx);
                                        
                                        Colo12idx = intersect(Colo1idx, Colo2idx); % Colocalized with both set#1 and #2
                                        Colo1not2idx = setdiff(Colo1idx, Colo2idx); % Colocalized with set#1 but not #2
                                        Colo2not1idx = setdiff(Colo2idx, Colo1idx); % Colocalized with set#2 but not #1
                                        Colonot1not2idx = intersect(NonColo1idx, NonColo2idx); % Not colocalized with either
                                        
                                        % Populate the grand table of batch analysis
                                        VarNames = {'Folder', 'Objects', 'Colocalized with', 'Percent', 'Number'};
                                        RowData = table({pwd}, {srcDots.Name}, {srcDots.Coloc(1).Fish1}, 100*numel(Colo1idx)/numel(ValidIdx), numel(Colo1idx), 'VariableNames', VarNames);
                                        if isempty(TableData)
                                            TableData = RowData;
                                        else
                                            TableData = [TableData; RowData]; %#ok
                                        end
                                        RowData = table({pwd}, {srcDots.Name}, {['Not ' srcDots.Coloc(1).Fish1]}, 100*numel(NonColo1idx)/numel(ValidIdx), numel(NonColo1idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        RowData = table({pwd}, {srcDots.Name}, {srcDots.Coloc(2).Fish1}, 100*numel(Colo2idx)/numel(ValidIdx), numel(Colo2idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        RowData = table({pwd}, {srcDots.Name}, {['Not ' srcDots.Coloc(2).Fish1]}, 100*numel(NonColo2idx)/numel(ValidIdx), numel(NonColo2idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        RowData = table({pwd}, {srcDots.Name}, {[srcDots.Coloc(1).Fish1 'AND ' srcDots.Coloc(2).Fish1]}, 100*numel(Colo12idx)/numel(ValidIdx), numel(Colo12idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        RowData = table({pwd}, {srcDots.Name}, {[srcDots.Coloc(1).Fish1 'but NOT ' srcDots.Coloc(2).Fish1]}, 100*numel(Colo1not2idx)/numel(ValidIdx), numel(Colo1not2idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        RowData = table({pwd}, {srcDots.Name}, {[srcDots.Coloc(2).Fish1 'but NOT ' srcDots.Coloc(1).Fish1]}, 100*numel(Colo2not1idx)/numel(ValidIdx), numel(Colo2not1idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                        RowData = table({pwd}, {srcDots.Name}, {'None'},  100*numel(Colonot1not2idx)/numel(ValidIdx) , numel(Colonot1not2idx), 'VariableNames', VarNames);
                                        TableData = [TableData; RowData]; %#ok
                                end
                                
                            case {'Density by depth', 'Volume by depth'}
                                dlg.Message = ['Calculating density of ' srcDots.Name];
                                M = [];
                                if isfield(srcDots.Settings.ImInfo, 'MaskChName') && ~isempty(srcDots.Settings.ImInfo.MaskChName)
                                    M = struct;
                                    [M.I, M.ImInfo, M.MIP, M.ImRes] = loadImage(srcDots.Settings.ImInfo.MaskChName);
                                end
                                
                                % Calculate Density by depth in percent increments
                                switch app.AutoReportDepthDir.Value
                                    case 'Z'
                                        srcDots.Density = calcDotDensityAlongZ(srcDots.Settings, getFilteredObjects(srcDots, srcDots.Filter), M, app.AutoReportBinNum.Value, false);
                                        BinsPercent = (1:srcDots.Density.binNum)*100/srcDots.Density.binNum;
                                        sizeBin = srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.xNumVox...
                                            *srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.yNumVox...
                                            *srcDots.Settings.ImInfo.zum  * srcDots.Density.binSize;
                                    case 'Y'
                                        srcDots.Density = calcDotDensityAlongY(srcDots.Settings, getFilteredObjects(srcDots, srcDots.Filter), M, app.AutoReportBinNum.Value, false);
                                        BinsPercent = (1:srcDots.Density.binNum)*100/srcDots.Density.binNum;
                                        sizeBin = srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.xNumVox...
                                            *srcDots.Settings.ImInfo.xyum * srcDots.Density.binSize...
                                            *srcDots.Settings.ImInfo.zum  * srcDots.Settings.ImInfo.zNumVox;
                                end
                                
                                switch app.AutoReportStatistic.Value
                                    case 'Density by depth'
                                        Measurement = 'Density';
                                        tmpY = srcDots.Density.densityPerc / sizeBin;
                                        tmpX = BinsPercent;
                                        
                                        % Export data as Excel table inside the results folder
                                        if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                                        FileName    = [pwd filesep 'results' filesep srcDots.Name '_' Measurement 'ByDepth.xls'];
                                        DepthPerc   = (tmpX)'; % Number of object on first column
                                        DensityPerc  = tmpY';  % Volume of each object on second column
                                        
                                        if ~isempty(M)
                                            MaskedVoxN = numel(find(M.I));
                                            MaskBinVolume = srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.zum * MaskedVoxN / app.AutoReportBinNum.Value;
                                            DensityPercMask = srcDots.Density.densityPercMask' / MaskBinVolume;
                                        else
                                            DensityPercMask = srcDots.Density.densityPercMask';
                                        end
                                        
                                        TblObjsData = table(DepthPerc, DensityPerc, DensityPercMask);
                                        writetable(TblObjsData, FileName);          % Write an Excel table with Depth-Density pairs
                                        
                                        % Populate the grand table of batch analysis
                                        RowData = [table({pwd}, {srcDots.Name}, {'Entire volume'}, DensityPerc', 'VariableNames', {'Folder', 'Objects', 'DensityWithin', 'Bin'});...
                                            table({pwd}, {srcDots.Name}, {'Masked volume'}, DensityPercMask', 'VariableNames', {'Folder', 'Objects', 'DensityWithin', 'Bin'})];
                                        
                                    case 'Volume by depth'
                                        Measurement = 'Volume';
                                        tmpY = srcDots.Density.volumePerc;
                                        tmpX = BinsPercent;
                                        
                                        % Export data as Excel table inside the results folder
                                        if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                                        FileName    = [pwd filesep 'results' filesep srcDots.Name '_' Measurement 'ByDepth.xls'];
                                        DepthPerc   = (tmpX)'; % Number of object on first column
                                        VolumePerc  = tmpY';  % Volume of each object on second column
                                        VolumePercMask = srcDots.Density.volumePercMask';
                                        
                                        TblObjsData = table(DepthPerc, VolumePerc, VolumePercMask);
                                        writetable(TblObjsData, FileName);          % Write an Excel table with Depth-Density pairs
                                        
                                        % Populate the grand table of batch analysis
                                        RowData = [table({pwd}, {srcDots.Name}, {'Entire volume'}, VolumePerc', 'VariableNames', {'Folder', 'Objects', 'AverageVolume', 'Percent'});...
                                            table({pwd}, {srcDots.Name}, {'Masked volume'}, VolumePercMask', 'VariableNames', {'Folder', 'Objects', 'AverageVolume', 'Percent'})];
                                        
                                end
                                
                                if isempty(TableData)
                                    TableData = RowData;
                                else
                                    TableData = [TableData; RowData]; %#ok
                                end
                                
                            case {'Volume','Brightness', 'Sphericity', 'Major Axis Length'}
                                MeasurementName = app.AutoReportStatistic.Value;
                                dlg.Message = ['Calculating ' MeasurementName ' of ' srcDots.Name];
                                
                                % Calculate Volume distribution according to binning preferences
                                Objs        = getFilteredObjects(srcDots, srcDots.Filter);
                                VoxelVolume = srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.xyum * srcDots.Settings.ImInfo.zum;
                                
                                switch app.AutoReportStatistic.Value
                                    case 'Volume'
                                        Data = Objs.Vol.*VoxelVolume; % Convert volumes from voxels to micron^3
                                        BinEdges = 0: app.AutoReportBinSize.Value: app.AutoReportBinSize.Value*app.AutoReportBinNum.Value;
                                        BinEdges(end) = inf;
                                        [Count, Bin]  = histcounts(Data, BinEdges, 'Normalization', 'count');
                                        [CumCount, ~] = histcounts(Data, BinEdges, 'Normalization', 'cumcount');
                                        
                                        Bin = Bin(1:end-1);      % Report the left edge value of each bin
                                        CumCount = CumCount / max(CumCount); % Express cumulative count as percent
                                        
                                    case 'Brightness'
                                        Data = Objs.MeanBright;
                                        BinEdges = 0:1:256;
                                        BinEdges(end) = inf;
                                        [Count, Bin]  = histcounts(Data, BinEdges, 'Normalization', 'count');
                                        [CumCount, ~] = histcounts(Data, BinEdges, 'Normalization', 'cumcount');
                                        
                                        Bin = Bin(1:end-1);      % Report the left edge value of each bin
                                        CumCount = CumCount / max(CumCount); % Express cumulative count as percent
                                        
                                    case 'Sphericity'
                                        if ~isfield(Objs.Shape, 'Oblong')
                                            Objs.Settings.objfinder.sphericity = true;
                                            Objs = fitSphere(Objs, Objs.Settings);
                                        end
                                        Data = Objs.Shape.Oblong;
                                        BinEdges = 0:0.01:1;
                                        BinEdges(end) = inf;
                                        [Count, Bin]  = histcounts(Data, BinEdges, 'Normalization', 'count');
                                        [CumCount, ~] = histcounts(Data, BinEdges, 'Normalization', 'cumcount');
                                        Bin = Bin(1:end-1);      % Report the left edge value of each bin
                                        CumCount = CumCount / max(CumCount); % Express cumulative count as percent
                                        
                                    case 'Major Axis Length'
                                        if ~isfield(Objs.Shape, 'PrincipalAxisLen')
                                            Objs.Settings.objfinder.sphericity = true;
                                            Objs = fitSphere(Objs, Objs.Settings);
                                        end
                                        Data = Objs.Shape.PrincipalAxisLen;
                                        BinEdges = 0: app.AutoReportBinSize.Value: app.AutoReportBinSize.Value*app.AutoReportBinNum.Value;
                                        BinEdges(end) = inf;
                                        [Count, Bin]  = histcounts(Data, BinEdges, 'Normalization', 'count');
                                        [CumCount, ~] = histcounts(Data, BinEdges, 'Normalization', 'cumcount');
                                        Bin = Bin(1:end-1);      % Report the left edge value of each bin
                                        CumCount = CumCount / max(CumCount); % Express cumulative count as percent
                                end
                                % assignin('base', 'NNdistBin', NNdistBin);
                                % assignin('base', 'Count', Count);
                                % assignin('base', 'CumCount', CumCount);
                                
                                % Export data for current NN pair as Excel table inside the results folder
                                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                                FileName = [pwd filesep 'results' filesep srcDots.Name '_' MeasurementName '_distribution.xls'];
                                TblCount = table(Bin', Count', CumCount');
                                writetable(TblCount, FileName);          % Write an Excel table with Depth-Density pairs
                                
                                % Populate the grand table of batch analysis
                                if isempty(TableData)
                                    TableData = table({''}, {''}, {'Bin_LeftEdge'}, Bin, 'VariableNames', {'Folder', 'Objects', 'Measurement', 'Bin'});
                                end
                                RowData = table({pwd}, {srcDots.Name}, {[MeasurementName '_count']}, Count, 'VariableNames', {'Folder', 'Objects', 'Measurement', 'Bin'});
                                TableData = [TableData; RowData]; %#ok
                                RowData = table({pwd}, {srcDots.Name}, {[MeasurementName '_cumcount']}, CumCount, 'VariableNames', {'Folder', 'Objects', 'Measurement', 'Bin'});
                                TableData = [TableData; RowData]; %#ok
                                
                            case {'Nearest Neighbor Distance', 'Nearest Neighbor Overlap'}
                                for idxDst = 1:numel(dstObjUIDs)
                                    
                                    % Load destination objects dstDots
                                    dstDots = loadObjects(dstObjUIDs{idxDst});
                                    if isempty(dstDots)
                                        continue
                                    end
                                    
                                    % Check if a nearest neighbor analysis is done between srcDots and dstDots
                                    idx_NN  = 0;
                                    for nn = 1: numel(srcDots.NN)
                                        if numel(fieldnames(srcDots.NN(nn))) > 0
                                            if strcmp(srcDots.NN(nn).Name,  dstDots.Name)
                                                idx_NN = nn; % Found the matching item
                                                break
                                            end
                                        end
                                    end
                                    
                                    % If not alreadt done, calculate nearest neighbor distance
                                    if idx_NN == 0
                                        NN = app.calcNNDistance(srcDots, dstDots, true);
                                        if isempty(fieldnames(srcDots.NN))
                                            srcDots.NN = NN;
                                        else
                                            % Check if we need to replace a previously done analysis with same mask
                                            FoundColocManual  = false;
                                            for n = 1:numel(srcDots.NN)
                                                if strcmp(srcDots.NN(n).Name, NN.Name)
                                                    srcDots.NN(n) = NN;
                                                    FoundColocManual = true;
                                                    break
                                                end
                                            end
                                            
                                            % If nothing to replace, just append to the list
                                            if ~FoundColocManual
                                                srcDots.NN(end+1) = NN;
                                            end
                                        end
                                        saveObjects(srcDots);
                                        
                                        for nn = 1: numel(srcDots.NN)
                                            if numel(fieldnames(srcDots.NN(nn))) > 0
                                                if strcmp(srcDots.NN(nn).Name,  dstDots.Name)
                                                    idx_NN = nn; % Found the matching item
                                                    break
                                                end
                                            end
                                        end
                                    end
                                    
                                    % Calculate histogram count and cumulative count
                                    idx_valid = find(srcDots.Filter.passF);
                                    BinEdges = 0: app.AutoReportBinSize.Value: app.AutoReportBinSize.Value*app.AutoReportBinNum.Value;
                                    BinEdges(end) = inf;
                                    
                                    switch app.AutoReportStatistic.Value
                                        case 'Nearest Neighbor Distance'
                                            MeasurementName = 'Distance';
                                            Avg = mean(srcDots.NN(idx_NN).Dist(idx_valid));
                                            SD  = std(srcDots.NN(idx_NN).Dist(idx_valid));
                                            [Count, NNdistBin] = histcounts(srcDots.NN(idx_NN).Dist(idx_valid), BinEdges, 'Normalization', 'count');
                                            [CumCount, ~     ] = histcounts(srcDots.NN(idx_NN).Dist(idx_valid), BinEdges, 'Normalization', 'cumcount');
                                        case 'Nearest Neighbor Overlap'
                                            MeasurementName = 'OverlapPerc';
                                            Avg = mean(srcDots.NN(idx_NN).VoxOverlapPerc(idx_valid));
                                            SD  = std(srcDots.NN(idx_NN).VoxOverlapPerc(idx_valid));
                                            [Count, NNdistBin] = histcounts(srcDots.NN(idx_NN).VoxOverlapPerc(idx_valid), BinEdges, 'Normalization', 'count');
                                            [CumCount, ~     ] = histcounts(srcDots.NN(idx_NN).VoxOverlapPerc(idx_valid), BinEdges, 'Normalization', 'cumcount');
                                    end
                                    NNdistBin = NNdistBin(1:end-1);      % Report the left edge value of each bin
                                    CumCount = CumCount / max(CumCount); % Express cumulative count as percent
                                    %                                     assignin('base', 'NNdistBin', NNdistBin);
                                    %                                     assignin('base', 'Count', Count);
                                    %                                     assignin('base', 'CumCount', CumCount);
                                    
                                    % Export data for current NN pair as Excel table inside the results folder
                                    if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                                    FileName = [pwd filesep 'results' filesep srcDots.Name '-' srcDots.NN(idx_NN).Name '_NN_' MeasurementName '_distribution.xls'];
                                    TblCount = table(NNdistBin', Count', CumCount');
                                    writetable(TblCount, FileName);          % Write an Excel table with Depth-Density pairs
                                    
                                    % Populate the grand table of batch analysis
                                    if isempty(TableData)
                                        TableData = table({''}, {''}, {''}, 0, 0, {'Bin_LeftEdge'}, NNdistBin, 'VariableNames', {'Folder', 'refObjects', 'dstObjects', 'Average', 'SD','Measurement', 'Bin'});
                                    end
                                    RowData = table({pwd}, {srcDots.Name}, {dstDots.Name}, Avg, SD, {[MeasurementName '_count']}, Count, 'VariableNames', {'Folder', 'refObjects', 'dstObjects', 'Average', 'SD', 'Measurement', 'Bin'});
                                    TableData = [TableData; RowData]; %#ok
                                    RowData = table({pwd}, {srcDots.Name}, {dstDots.Name}, Avg, SD, {[MeasurementName '_cumcount']}, CumCount, 'VariableNames', {'Folder', 'refObjects', 'dstObjects', 'Average', 'SD', 'Measurement', 'Bin'});
                                    TableData = [TableData; RowData]; %#ok
                                end
                        end
                    end
                end
                
                if app.AutomateOperation.SelectedTab == app.AutoColocalizeTab
                    % ---- Batch Colocalization Analysis ----
                    switch app.AutoColoSwitchVoxPerc.Value
                        case 'Voxels'
                            NumVoxOverlap  = app.AutoColoVoxNum.Value;
                            NumPercOverlap = 0;
                        case 'Percent'
                            NumVoxOverlap  = 0;
                            NumPercOverlap = app.AutoColoVoxNum.Value;
                    end
                    
                    % Find out which objects names and UID to process
                    if app.chkAutoColoAll.Value
                        [~, srcObjUIDs] = listObjects;
                    else
                        [srcObjNames, srcObjUIDs] = listObjects;
                        srcIdx = strcmp(app.AutoColoRefObjs.Value, srcObjNames);
                        srcObjUIDs = srcObjUIDs(srcIdx);
                    end
                    
                    % Perform the selected colocalization for selected Objects
                    for idx = 1:numel(srcObjUIDs)
                        srcDots = loadObjects(srcObjUIDs{idx});
                        switch app.AutoColoMaskOrObjs.Value
                            
                            case 'Mask'
                                PathName = [pwd filesep 'I' filesep];
                                FileName = app.AutoColoChannel.Value;
                                if isempty(FileName)
                                    return
                                end
                                
                                Colo = loadImage(FileName);
                                if numel(unique(Colo)) == 2     % Ensure that loaded image is indeed a binary mask before proceeding
                                    Colo = Colo./max(Colo(:));  % Normalize mask values to 0-1 (ImageJ uses 0-255)
                                    ColocAuto = colocAutoMask(srcDots, Colo, [PathName FileName], NumVoxOverlap, NumPercOverlap, app.chkAutoColoCenterMustOverlap.Value);
                                else
                                    disp('Selected image is not a binary mask');
                                    continue
                                end
                                
                            case 'Objects'
                                [dstObjNames, dstObjUIDs] = listObjects;
                                dstIdx = strcmp(dstObjNames, app.AutoColoChannel.Value);
                                dstObjUID = dstObjUIDs(dstIdx);
                                dstDots = loadObjects(dstObjUID);
                                
                                % Seach for nearest neighbor analysis done between srcDots and dstDots
                                idx_NN  = 0;
                                for nn = 1: numel(srcDots.NN)
                                    if numel(fieldnames(srcDots.NN(nn))) > 0
                                        if strcmp(srcDots.NN(nn).Name,  dstDots.Name)
                                            idx_NN = nn; % Found the matching item
                                            break
                                        end
                                    end
                                end
                                
                                % Calculate nearest neighbor distance if not avaiable
                                if app.chkAutoRecalculateNN.Value || (idx_NN == 0 && ~isempty(srcDots) && ~isempty(dstDots))
                                    app.BatchLog.Value{end+1} = ['Calculating pairwise nearest neighbors (' srcDots.Name '->' dstDots.Name ')'];
                                    NN = app.calcNNDistance(srcDots, dstDots, true);
                                    if isempty(fieldnames(srcDots.NN))
                                        srcDots.NN = NN;
                                    else
                                        % Check if we need to replace a previously done analysis with same mask
                                        FoundColocManual  = false;
                                        for n = 1:numel(srcDots.NN)
                                            if strcmp(srcDots.NN(n).Name, NN.Name)
                                                srcDots.NN(n) = NN;
                                                FoundColocManual = true;
                                                break
                                            end
                                        end
                                        
                                        % If nothing to replace, just append to the list
                                        if ~FoundColocManual
                                            srcDots.NN(end+1) = NN;
                                        end
                                    end
                                    saveObjects(srcDots, 'NN');
                                    
                                    for n = 1: numel(srcDots.NN)
                                        if numel(fieldnames(srcDots.NN(n))) > 0
                                            if strcmp(srcDots.NN(n).Name,  dstDots.Name)
                                                idx_NN = n; % Found the matching item
                                                break
                                            end
                                        end
                                    end
                                    
                                    app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                                end
                                
                                if idx_NN > 0
                                    % Assign colocalized status to nearest neighbor objects sharing more than NumVoxOverlap AND NumPercOverlap, AND WithinDist
                                    ColocAuto = colocAutoNN(srcDots, dstDots, srcDots.NN(idx_NN), NumVoxOverlap, NumPercOverlap, app.cmbAutoColoThresholdDist.Value, app.chkAutoColoCenterMustOverlap.Value);
                                else
                                    disp('Nearest neighbor distances are not calculated');
                                end
                        end
                        
                        % Store colocalization results in Objs.Coloc struct array
                        if isempty(fieldnames(srcDots.Coloc))
                            srcDots.Coloc = ColocAuto;
                        else
                            % Check if we need to replace a previously done analysis with same mask
                            FoundColoc  = false;
                            for j = 1:numel(srcDots.Coloc)
                                if strcmp(srcDots.Coloc(j).Source, ColocAuto.Source) && strcmp(srcDots.Coloc(j).Fish1, ColocAuto.Fish1)
                                    %                                     assignin('base', 'Coloc', srcDots.Coloc);
                                    %                                     assignin('base', 'ColocAuto', ColocAuto);
                                    srcDots.Coloc(j) = ColocAuto;
                                    FoundColoc = true;
                                    break
                                end
                            end
                            
                            % If nothing to replace, just append to the list
                            if ~FoundColoc
                                srcDots.Coloc(end+1) = ColocAuto; % Coloc is saved later
                            end
                        end
                        saveObjects(srcDots, 'Coloc');
                        
                        % If selected by user, also save colocalized objects as a new set
                        if app.chkAutoSaveColoObjs.Value
                            srcColoDots = srcDots;
                            srcColoDots.UID = generateUID;
                            srcColoDots.Name = [srcDots.Name ' colocalized with ' ColocAuto.Fish1];
                            srcColoDots.Filter.passF = logical(ColocAuto.ColocFlag == 1);
                            srcColoDots.Coloc = struct;
                            srcColoDots = app.calcObjDensity(srcColoDots);
                            srcColoDots.Settings.ImInfo.MaskChName = app.AutoColoChannel.Value; 
                            saveObjects(srcColoDots);
                        end
                        
                        % If selected by user, also save Not colocalized objects as a new set
                        if app.chkAutoSaveNotColoObjs.Value
                            srcNonColoDots = srcDots;
                            srcNonColoDots.UID = generateUID;
                            srcNonColoDots.Name = [srcDots.Name ' not colocalized with ' ColocAuto.Fish1];
                            srcNonColoDots.Filter.passF = logical(ColocAuto.ColocFlag == 2);
                            srcNonColoDots.Coloc = struct;
                            srcNonColoDots = app.calcObjDensity(srcNonColoDots);
                            saveObjects(srcNonColoDots);
                        end
                        
                        % If selected by user, create a table with colocalization statistics
                        if app.chkAutoSaveTable.Value
                            FolderName  = strsplit(pwd,filesep);
                            ColocObjs   = numel(find(ColocAuto.ColocFlag == 1));
                            % Bugfix: make sure that ColocFlag contains all objects (valid+invalid) otherwise catch error because user is processing old data
                            try
                                TotalObjs = numel(ColocAuto.ColocFlag(srcDots.Filter.passF));
                            catch
                                TotalObjs = numel(ColocAuto.ColocFlag);
                            end
                            ColocRate   = 100*ColocObjs/TotalObjs;
                            RowData = table(FolderName(end), {ColocAuto.Source}, {ColocAuto.Fish1}, TotalObjs, ColocObjs, ColocRate,...
                                'VariableNames', {'Folder', 'SourceObjs', 'Destination', 'ValidObjs', 'ColocObjs', 'ColocRate'});
                            
                            % Accumulate data of current mask size as table rows
                            if isempty(TableData)
                                TableData = RowData;
                            else
                                TableData = [TableData; RowData]; %#ok
                            end
                        end
                    end
                end
                
                % Interrupt batch processing at this point if user pressed the cancel button
                if dlg.CancelRequested
                    app.BatchLog.Value{end+1}   = '-- Processing interrupted by user.';
                    break
                end
            end
            
            % Write results table to disk as an Excel spreadsheet
            if ~isempty(TableData) && app.chkAutoSaveTable.Value
                %assignin('base','TableData', TableData);
                disp(['Writing results to ' RootFolder filesep 'AutomatedMeasurements.csv']);
                writetable(TableData, [RootFolder filesep 'AutomatedMeasurements.csv']);
            end
            
            app.BatchLog.Value{end+1} = ['-- Batch complete! ' datestr(now) ' ---'];
            cd(RootFolder);
            close(dlg);
            app.RefreshHomeFolder(false, true);
        end

        % Value changed function: BatchScheduler
        function BatchSchedulerValueChanged(app, event)
            if app.BatchScheduler.Value
                app.BatchScheduledTime.Enable = 'on';
                app.lblBatchScheduler.Enable  = 'on';
            else
                app.BatchScheduledTime.Enable = 'off';
                app.lblBatchScheduler.Enable = 'off';
            end
        end

        % Callback function
        function btnColoAddButtonPushed(app, event)
            [FileName, ~] = uigetfile([pwd filesep 'I' filesep '*.tif']);
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if FileName
                if isempty(app.lstColoImages.Items)
                    app.lstColoImages.Items = {FileName};
                else
                    app.lstColoImages.Items{end+1} = FileName;
                end
                app.lstColoImages.Value = FileName;
            end
            
        end

        % Callback function
        function btnColoRemoveButtonPushed(app, event)
            idx = strcmp(app.lstColoImages.Items, app.lstColoImages.Value);
            app.lstColoImages.Items(idx) = [];
        end

        % Button pushed function: btnColoDoneRemove
        function btnColoDoneRemoveButtonPushed(app, event)
            
            switch uiconfirm(app.UIFigure, 'Permanently delete selected analysis?','Delete objects','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting colocalization analysis...','Indeterminate', 'on');
                    srcDots = loadObjects(app.lstColoObjects.Value);
                    idx = strcmp(app.lstColoDone.Items, app.lstColoDone.Value);
                    
                    if numel(srcDots.Coloc) >= 1
                        srcDots.Coloc(idx) = [];
                        saveObjects(srcDots);
                    end
                    app.RefreshColoDone;
                    close(dlg);
            end
        end

        % Button pushed function: btnColoDoneClear
        function btnColoDoneClearButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Permanently delete all analysis for selected objects?','Delete objects','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting colocalization analysis...','Indeterminate', 'on');
                    srcDots = loadObjects(app.lstColoObjects.Value);
                    srcDots.Coloc = struct;
                    saveObjects(srcDots);
                    app.RefreshColoDone;
                    close(dlg);
            end
        end

        % Button pushed function: btnColoRedoValid
        function btnColoRedoValidButtonPushed(app, event)
            app.loadCurrentImage;
            dlg = uiprogressdlg(app.UIFigure,'Title','Manually colocalizing...','Indeterminate', 'on');
            
            % Reload objects image if different than the one stored in memory
            srcDots = loadObjects(app.lstColoObjects.Value);
            if ~strcmp(app.Settings.ImInfo.PostChName, srcDots.Settings.ImInfo.PostChName)
                [app.Post.I, app.Post.ImInfo, app.Post.MIP, app.Post.ImRes] = loadImage(srcDots.Settings.ImInfo.PostChName);
                app.Post.Name = srcDots.Settings.ImInfo.PostChName;
            end
            
            switch numel(app.lstColoDone.Value)
                case 1 % User selected only one item on screen, display colocalization stats
                    FileName = app.lstColoDone.Value{1};
                    if ~isempty(FileName)
                        Colo        = loadImage(FileName);
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = srcDots.Coloc(idx(1));
                        ColocManual = colocManualCheckDouble(srcDots, srcDots.Filter, app.Post.I, Colo, ColocManual, [], []);
                    end
                case 2 % User selected two items from list, display double colocalization stats
                    % Load colocalizing channels' images
                    FileName1       = app.lstColoDone.Value{1};
                    FileName2       = app.lstColoDone.Value{2};
                    if ~isempty(FileName1) && ~isempty(FileName2)
                        Colo        = loadImage(FileName1);
                        Colo2       = loadImage(FileName2);
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = srcDots.Coloc(idx(1));
                        ColocManual2= srcDots.Coloc(idx(2));
                        ColocManual = colocManualCheckDouble(srcDots, srcDots.Filter, app.Post.I, Colo, ColocManual, Colo2, ColocManual2);
                    end
            end
            
            % Check whether the user aborted the manual colocalization before finishing
            if isempty(ColocManual) || isempty(fieldnames(ColocManual))
                disp('User aborted manual colocalization inspection, values are not saved to disk')
                return
            end
            
            if isempty(fieldnames(srcDots.Coloc))
                srcDots.Coloc = ColocManual;
            else
                % Check if we need to replace a previously done analysis with same mask
                for j = 1:numel(ColocManual)
                    FoundColocManual  = false;
                    for i=1:numel(srcDots.Coloc)
                        if strcmp(srcDots.Coloc(i).Source, ColocManual(j).Source) && strcmp(srcDots.Coloc(i).Fish1, ColocManual(j).Fish1)
                            srcDots.Coloc(i) = ColocManual(j);
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        srcDots.Coloc(end+1) = ColocManual(j); % Coloc is saved later
                    end
                end
            end
            saveObjects(srcDots, 'Coloc');
            app.RefreshColoDone;
            close(dlg);
        end

        % Value changed function: lstColoDone
        function lstColoDoneValueChanged(app, event)
            % Get index of selected values
            %idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value),1);
            [~, idx] = ismember(app.lstColoDone.Value, app.lstColoDone.Items);
            if idx > 0
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading colocalization analysis...','Indeterminate', 'on');
                srcDots = loadObjects(app.lstColoObjects.Value, {'UID', 'Num', 'Coloc', 'Filter'});
            else
                return
            end
            
            switch numel(app.lstColoDone.Value)
                case 1 % User selected only one item on screen, display colocalization stats
                    Colocalized1 = numel(find(srcDots.Coloc(idx).ColocFlag == 1));
                    
                    % Bugfix: make sure that ColocFlag contains all objects (valid+invalid) otherwise catch error because user is processing old data
                    try
                        TotalObjects = numel(srcDots.Coloc(idx).ColocFlag(srcDots.Filter.passF));
                    catch
                        TotalObjects = numel(srcDots.Coloc(idx).ColocFlag);
                    end
                    
                    app.btnColoRedoDiscarded.Enable = 'on';
                    app.btnColoRedoValid.Enable     = 'on';
                    app.btnColoDonePlotDist.Enable  = 'on';
                    app.btnColoDoneRemove.Enable    = 'on';
                    app.btnColoDoneSave.Enable      = 'on';
                    
                    % Update colocalization table
                    app.tblColoDone.Data = [];
                    app.tblColoDone.Data = table({'Reference objects'},  {srcDots.Coloc(idx).Source});
                    app.tblColoDone.Data = [app.tblColoDone.Data; table({'Valid ref objects #'},{TotalObjects})];
                    app.tblColoDone.Data = [app.tblColoDone.Data; table({'Test objects'}, {srcDots.Coloc(idx).Fish1})];
                    if contains(srcDots.Coloc(idx).Method, 'AutoEngulf')
                        Colocalized1 = numel(find(srcDots.Coloc(idx).ColocFlag > 0));
                        EngulfedAvg = mean(srcDots.Coloc(idx).ColocFlag(srcDots.Filter.passF));
                        EngulfedSD  = std(single(srcDots.Coloc(idx).ColocFlag(srcDots.Filter.passF)));
                        app.tblColoDone.Data = [app.tblColoDone.Data; table({['Average enfulfed ' srcDots.Coloc(idx).Fish1 ' per ' srcDots.Coloc(idx).Source]}, {[num2str(EngulfedAvg) char(177) num2str(EngulfedSD)]})];
                    end
                    
                    app.tblColoDone.Data = [app.tblColoDone.Data; table({'Colocalized #'}, {Colocalized1})];
                    app.tblColoDone.Data = [app.tblColoDone.Data; table({'Colocalization rate %'}, {100*Colocalized1/TotalObjects})];
                    
                    if isfield(srcDots.Coloc(idx), 'Method') && contains(srcDots.Coloc(idx).Method, 'Auto')
                        app.tblColoDone.Data = [app.tblColoDone.Data; table({'Method'}, {srcDots.Coloc(idx).Method})];
                        app.tblColoDone.Data = [app.tblColoDone.Data; table({'Threshold Voxels'}, {srcDots.Coloc(idx).NumVoxOverlap})];
                        app.tblColoDone.Data = [app.tblColoDone.Data; table({'Threshold Percent'}, {srcDots.Coloc(idx).NumPercOverlap})];
                    end
                    
                case 2 % User selected two items from list, display double colocalization stats
                    Colocalized1 = numel(find(srcDots.Coloc(idx(1)).ColocFlag == 1));
                    Colocalized2 = numel(find(srcDots.Coloc(idx(2)).ColocFlag == 1));
                    
                    % Bugfix: make sure that ColocFlag contains all objects (valid+invalid) otherwise catch error because user is processing old data
                    if numel(srcDots.Coloc(idx(1)).ColocFlag) == srcDots.Num
                        Colo1Flag = srcDots.Coloc(idx(1)).ColocFlag(srcDots.Filter.passF);
                        TotalObjects = numel(srcDots.Coloc(idx(1)).ColocFlag(srcDots.Filter.passF));
                    else
                        Colo1Flag = srcDots.Coloc(idx(1)).ColocFlag;
                        TotalObjects = numel(srcDots.Coloc(idx(1)).ColocFlag);
                    end
                    
                    if numel(srcDots.Coloc(idx(2)).ColocFlag) == srcDots.Num
                        Colo2Flag = srcDots.Coloc(idx(2)).ColocFlag(srcDots.Filter.passF);
                    else
                        Colo2Flag = srcDots.Coloc(idx(2)).ColocFlag;
                    end
                    
                    DoubleColoc  = numel(find(Colo1Flag==1 & Colo2Flag==1));
                    
                    app.btnColoRedoDiscarded.Enable = 'on';
                    app.btnColoRedoValid.Enable     = 'on';
                    app.btnColoDonePlotDist.Enable  = 'on';
                    app.btnColoDoneRemove.Enable    = 'off';
                    app.btnColoDoneSave.Enable      = 'on';
                    
                    % Update colocalization table
                    tblSummary = table({'Reference objects'},  {srcDots.Coloc(idx(1)).Source});
                    tblSummary = [tblSummary; table({'Valid ref objects #'},{TotalObjects})];
                    tblSummary = [tblSummary; table({'First test objects'}, {srcDots.Coloc(idx(1)).Fish1})];
                    tblSummary = [tblSummary; table({'Colocalized #'}, {Colocalized1})];
                    tblSummary = [tblSummary; table({'Colocalization rate %'}, {100*Colocalized1/TotalObjects})];
                    tblSummary = [tblSummary; table({'Second test objects'}, {srcDots.Coloc(idx(2)).Fish1})];
                    tblSummary = [tblSummary; table({'Colocalized #'}, {Colocalized2})];
                    tblSummary = [tblSummary; table({'Colocalization rate %'}, {100*Colocalized2/TotalObjects})];
                    tblSummary = [tblSummary; table({'Double colocalized #'}, {DoubleColoc})];
                    tblSummary = [tblSummary; table({'Double colocalization rate %'}, {100*DoubleColoc /TotalObjects})];
                    app.tblColoDone.Data = tblSummary;
                    
                otherwise % Nothing selected or more than two items selected, do not display any info
                    app.tblColoDone.Data = [];
                    app.btnColoRedoDiscarded.Enable = 'off';
                    app.btnColoRedoValid.Enable     = 'off';
                    app.btnColoDonePlotDist.Enable  = 'off';
                    app.btnColoDoneRemove.Enable    = 'off';
                    app.btnColoDoneSave.Enable      = 'off';
            end
            close(dlg);
        end

        % Button pushed function: btnColoRedoDiscarded
        function btnColoRedoDiscardedButtonPushed(app, event)
            app.loadCurrentImage;
            dlg = uiprogressdlg(app.UIFigure,'Title','Manually colocalizing...','Indeterminate', 'on');
            
            % Reload objects image if different than the one stored in memory
            srcDots = loadObjects(app.lstColoObjects.Value);
            if ~strcmp(app.Settings.ImInfo.PostChName, srcDots.Settings.ImInfo.PostChName)
                [app.Post.I, app.Post.ImInfo, app.Post.MIP, app.Post.ImRes] = loadImage(srcDots.Settings.ImInfo.PostChName);
                app.Post.Name = srcDots.Settings.ImInfo.PostChName;
            end
            
            switch numel(app.lstColoDone.Value)
                case 1 % User selected only one item on screen, display colocalization stats
                    FileName = app.lstColoDone.Value{1};
                    if ~isempty(FileName)
                        Colo        = loadImage(FileName);
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = srcDots.Coloc(idx(1));
                        ColocManual = colocManualCheckNotColoc(srcDots, srcDots.Filter, app.Post.I, Colo, ColocManual, [], []);
                    end
                case 2 % User selected two items from list, display double colocalization stats
                    % Load colocalizing channels' images
                    FileName1       = app.lstColoDone.Value{1};
                    FileName2       = app.lstColoDone.Value{2};
                    if ~isempty(FileName1) && ~isempty(FileName2)
                        Colo        = loadImage(FileName1);
                        Colo2       = loadImage(FileName2);
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = srcDots.Coloc(idx(1));
                        ColocManual2= srcDots.Coloc(idx(2));
                        ColocManual = colocManualCheckNotColoc(srcDots, srcDots.Filter, app.Post.I, Colo, ColocManual, Colo2, ColocManual2);
                    end
            end
            
            % Check whether the user aborted the manual colocalization before finishing
            if isempty(ColocManual) || isempty(fieldnames(ColocManual))
                disp('User aborted manual colocalization inspection, values are not saved to disk')
                return
            end
            
            if isempty(fieldnames(srcDots.Coloc))
                srcDots.Coloc = ColocManual;
            else
                % Check if we need to replace a previously done analysis with same mask
                for j = 1:numel(ColocManual)
                    FoundColocManual  = false;
                    for i = 1:numel(app.Dots(idx_src).Coloc)
                        if strcmp(srcDots.Coloc(i).Source, ColocManual(j).Source) && strcmp(srcDots.Coloc(i).Fish1, ColocManual(j).Fish1)
                            srcDots.Coloc(i) = ColocManual(j);
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        srcDots.Coloc(end+1) = ColocManual(j); % Coloc is saved later
                    end
                end
            end
            saveObjects(srcDots, 'Coloc');
            app.RefreshColoDone;
            close(dlg);
        end

        % Value changed function: lstColoImages
        function lstColoImagesValueChanged(app, event)
            switch numel(app.lstColoObjects.Value)
                case 1
                    switch numel(app.lstColoImages.Value)
                        case 1
                            app.btnColocalizeManual.Enable  = 'on';
                            app.btnColocalizeMask.Enable    = 'on';
                            app.btnColocalizeNN.Enable      = 'off';
                            app.btnColocalizeEngulf.Enable  = 'off';
                            app.ColoSharedVoxNum.Enable     = 'on';
                            app.ColoSwitchVoxPerc.Enable    = 'on';
                            app.btnColoRefreshImgs.Enable   = 'on';
                            app.chkColoCenterOverlap.Enable = 'on';
                        case 2
                            app.btnColocalizeManual.Enable  = 'on';
                            app.btnColocalizeMask.Enable    = 'off';
                            app.btnColocalizeNN.Enable      = 'off';
                            app.btnColocalizeEngulf.Enable  = 'off';
                            app.ColoSharedVoxNum.Enable     = 'off';
                            app.ColoSwitchVoxPerc.Enable    = 'off';
                            app.btnColoRefreshImgs.Enable   = 'on';
                            app.chkColoCenterOverlap.Enable = 'off';
                        otherwise
                            app.btnColocalizeManual.Enable  = 'off';
                            app.btnColocalizeMask.Enable    = 'off';
                            app.btnColocalizeNN.Enable      = 'off';
                            app.btnColocalizeEngulf.Enable  = 'off';
                            app.ColoSharedVoxNum.Enable     = 'off';
                            app.ColoSwitchVoxPerc.Enable    = 'off';
                            app.btnColoRefreshImgs.Enable   = 'on';
                            app.chkColoCenterOverlap.Enable = 'off';
                    end
                    
                case 0
                    app.btnColocalizeManual.Enable          = 'off';
                    app.btnColocalizeMask.Enable            = 'off';
                    app.btnColocalizeNN.Enable              = 'off';
                    app.btnColocalizeEngulf.Enable          = 'off';
                    app.ColoSharedVoxNum.Enable             = 'off';
                    app.ColoSwitchVoxPerc.Enable            = 'off';
                    app.chkColoCenterOverlap.Enable         = 'off';
                    if numel(app.lstColoImages.Value) > 0
                        app.btnColoRefreshImgs.Enable       = 'on';
                    end
                    
                otherwise
                    if isempty(app.lstColoObjects.Value)
                        app.btnColocalizeManual.Enable      = 'off';
                        app.btnColocalizeMask.Enable        = 'off';
                        app.btnColocalizeNN.Enable          = 'off';
                        app.btnColocalizeEngulf.Enable      = 'off';
                        app.ColoSharedVoxNum.Enable         = 'off';
                        app.ColoSwitchVoxPerc.Enable        = 'off';
                    end
            end
        end

        % Button pushed function: btnColocalizeManual
        function btnColocalizeManualButtonPushed(app, event)
            app.loadCurrentImage;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Manually colocalizating objects...','Indeterminate', 'on');
            
            % Reloach objects image if different than the one stored in memory
            srcDots = loadObjects(app.lstColoObjects.Value);
            if ~strcmp(app.Settings.ImInfo.PostChName, srcDots.Settings.ImInfo.PostChName)
                dlg.Message = ['Loading image: ' srcDots.Settings.ImInfo.PostChName];
                [app.Post.I, app.Post.ImInfo, app.Post.MIP, app.Post.ImRes] = loadImage(srcDots.Settings.ImInfo.PostChName);
                app.Post.Name = srcDots.Settings.ImInfo.PostChName;
            end
            
            if exist('ColocManual.mat','file')
                savedColo = load('ColocManual.mat','ColocManual');
                if strcmp(savedColo.ColocManual(1).Source, srcDots.Name)
                    switch questdlg({'An unfinished colocalization analysis exists for this channel,', 'do you want to lose progress and start anew?'},'Unfinished colocalization detected', 'No', 'Yes', 'No')
                        case 'Yes'
                            delete('ColocManual.mat');
                    end
                else
                    switch questdlg({['An unfinished colocalization analysis exists for ' savedColo.ColocManual(1).Source ','], 'do you want to lose progress and start anew?'},'Unfinished colocalization detected', 'No', 'Yes', 'No')
                        case 'Yes', delete('ColocManual.mat');
                        case 'No', return;
                    end
                end
            end
            
            switch numel(app.lstColoImages.Value)
                case 1
                    PathName = [pwd filesep 'I' filesep];
                    FileName = app.lstColoImages.Value{1};
                    if ~isempty(FileName)
                        dlg.Message = ['Loading image: ' FileName];
                        Colo = loadImage(FileName);
                    end
                    
                    ColocManual = colocManual(srcDots, app.Post.I, Colo, [PathName FileName], [], '');
                    
                    if ~isempty(ColocManual)  && ~isempty(fieldnames(ColocManual))
                        % Update filter with invalid objects in case some manually invalidated by user during colocalization
                        srcDots.Filter.passF(ColocManual.ColocFlag == 3) = 0;
                        if strcmp(srcDots.UID, app.Dots.UID)
                            % Update validation status of current objects if they are the same as those manually colocalized
                            app.Dots.Filter.passF = srcDots.Filter.passF;
                            app.cmbObjsDetected.Tooltip = ['Current Objects (Valid: ' num2str(numel(find(app.Dots.Filter.passF))) ' / ' num2str(numel(app.Dots.Filter.passF)) ')'];
                        end
                        
                        % Add current colocalization analysis to srcDots
                        if isempty(fieldnames(srcDots.Coloc))
                            srcDots.Coloc = ColocManual;
                        else
                            % Check if we need to replace a previously done analysis with same mask
                            for j = 1:numel(ColocManual)
                                FoundColocManual  = false;
                                for i = 1:numel(srcDots.Coloc)
                                    if strcmp(srcDots.Coloc(i).Source, ColocManual(j).Source) && strcmp(srcDots.Coloc(i).Fish1, ColocManual(j).Fish1)
                                        srcDots.Coloc(i) = ColocManual(j);
                                        FoundColocManual = true;
                                        break
                                    end
                                end
                                
                                % If nothing to replace, just append to the list
                                if ~FoundColocManual
                                    srcDots.Coloc(end+1) = ColocManual(j); % Coloc is saved later
                                end
                            end
                        end
                        saveObjects(srcDots);
                        app.RefreshColoDone;
                    end
                    
                case 2
                    % Load colocalizing channels' images
                    PathName = [pwd filesep 'I' filesep];
                    FileName1 = app.lstColoImages.Value{1};
                    FileName2 = app.lstColoImages.Value{2};
                    if ~isempty(FileName1) && ~isempty(FileName2)
                        dlg.Message = ['Loading image: ' FileName1];
                        Colo = loadImage(FileName1);
                        dlg.Message = ['Loading image: ' FileName2];
                        Colo2 = loadImage(FileName2);
                    end
                    
                    ColocManual = colocManual(srcDots, app.Post.I, Colo, [PathName FileName1], Colo2, [PathName FileName2]);
                    
                    if ~isempty(ColocManual) && ~isempty(fieldnames(ColocManual))
                        % Update filter with invalid objects in case some manually invalidated by user during colocalization
                        srcDots.Filter.passF(ColocManual(1).ColocFlag == 3) = 0;
                        if strcmp(srcDots.UID, app.Dots.UID)
                            % Update validation status of current objects if they are the same as those manually colocalized
                            app.Dots.Filter.passF = srcDots.Filter.passF;
                            app.cmbObjsDetected.Tooltip = ['Current Objects (Valid: ' num2str(numel(find(app.Dots.Filter.passF))) ' / ' num2str(numel(app.Dots.Filter.passF)) ')'];
                        end
                        
                        if isempty(fieldnames(srcDots.Coloc))
                            srcDots.Coloc = ColocManual;
                        else
                            % Check if we need to replace a previously done analysis with same mask
                            for j = 1:numel(ColocManual)
                                FoundColocManual  = false;
                                for i = 1:numel(srcDots.Coloc)
                                    if strcmp(srcDots.Coloc(i).Source, ColocManual(j).Source) && strcmp(srcDots.Coloc(i).Fish1, ColocManual(j).Fish1)
                                        srcDots.Coloc(i) = ColocManual(j);
                                        FoundColocManual = true;
                                        break
                                    end
                                end
                                
                                % If nothing to replace, just append to the list
                                if ~FoundColocManual
                                    srcDots.Coloc(end+1) = ColocManual(j); % Coloc is saved later
                                end
                            end
                        end
                        saveObjects(srcDots);
                        app.RefreshColoDone;
                    end
            end
            close(dlg);
        end

        % Value changed function: lstBatch
        function lstBatchValueChanged(app, event)
            if isempty(app.lstBatch.Items)
                app.btnBatchRemove.Enable   = 'off';
                app.btnBatchClear.Enable    = 'off';
                app.btnBatchUp.Enable       = 'off';
                app.btnBatchDown.Enable     = 'off';
                app.btnBatchTop.Enable      = 'off';
                app.btnBatchBottom.Enable   = 'off';
            else
                app.btnBatchRemove.Enable   = 'on';
                app.btnBatchClear.Enable    = 'on';
                app.btnBatchUp.Enable       = 'on';
                app.btnBatchDown.Enable     = 'on';
                app.btnBatchTop.Enable      = 'on';
                app.btnBatchBottom.Enable   = 'on';
            end
        end

        % Value changed function: lstTraining
        function lstTrainingValueChanged(app, event)
            if isempty(app.lstTraining.Items)
                app.btnTrainingRemove.Enable             = 'off';
                app.btnTrainingClear.Enable              = 'off';
                app.btnTrainingUp.Enable                 = 'off';
                app.btnTrainingDown.Enable               = 'off';
                app.btnTrainingTop.Enable                = 'off';
                app.btnTrainingBottom.Enable             = 'off';
                app.GenerateTrainingSetButton.Enable = 'off';
                app.btnNetTrain.Enable      = 'off';
            else
                app.btnTrainingRemove.Enable             = 'on';
                app.btnTrainingClear.Enable              = 'on';
                app.btnTrainingUp.Enable                 = 'on';
                app.btnTrainingDown.Enable               = 'on';
                app.btnTrainingTop.Enable                = 'on';
                app.btnTrainingBottom.Enable             = 'on';
                app.GenerateTrainingSetButton.Enable = 'on';
            end
        end

        % Button pushed function: btnTrainingAdd
        function btnTrainingAddButtonPushed(app, event)
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                app.cmbTrainingObjects.Items = {}; % Clear list of available objects
                
                cd(tmpDir);
                % Make sure the experiment data format matches current ObjectFinder version, otherwise convert it automatically
                if exist('find','dir')
                    convertObjectFinderDataV3toV4;
                    convertObjectFinderDataV4toV5;
                    convertObjectFinderDataV5toV7;
                elseif exist('Density.mat','file')
                    convertObjectFinderDataV4toV5;
                    convertObjectFinderDataV5toV7;
                elseif ~exist('objects','dir') && exist('Dots.mat','file')
                    convertObjectFinderDataV5toV7;
                end
                
                if ~exist([tmpDir filesep 'objects'], 'dir')
                    disp(['Error: Working folder not valid for analysis ' tmpDir]);
                    return
                end
                
                % Add folder to processing list
                if isempty(app.lstTraining.Items)
                    app.lstTraining.Items = {tmpDir};
                else
                    app.lstTraining.Items{end+1} = tmpDir;
                end
                
                % Populate list of available objects
                app.cmbTrainingObjects.Items  = listObjects;
            end
            app.lstTrainingValueChanged;
        end

        % Button pushed function: btnTrainingRemove
        function btnTrainingRemoveButtonPushed(app, event)
            if isempty(app.lstTraining.Value)
                return
            end
            idx = strcmp(app.lstTraining.Items, app.lstTraining.Value);
            
            app.lstTraining.Items(idx) = [];
            if isempty(app.lstTraining.Items)
                app.cmbTrainingObjects.Items = {}; % Clear list of available objects
            end
            app.lstTrainingValueChanged;
        end

        % Button pushed function: btnTrainingClear
        function btnTrainingClearButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Remove all items from list?','Delete items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    app.lstTraining.Items = {};
                    app.cmbTrainingObjects.Items = {}; % Clear list of available objects
                    app.lstTrainingValueChanged;
            end
        end

        % Button pushed function: btnTrainingTop
        function btnTrainingTopButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx > 1
                app.lstTraining.Items(idx) = [];
                app.lstTraining.Items = [{val}, app.lstTraining.Items];
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: btnTrainingUp
        function btnTrainingUpButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx > 1
                app.lstTraining.Items(idx) = app.lstTraining.Items(idx-1);
                app.lstTraining.Items{idx-1} = val;
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: btnTrainingDown
        function btnTrainingDownButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx < numel(app.lstTraining.Items)
                app.lstTraining.Items(idx) = app.lstTraining.Items(idx+1);
                app.lstTraining.Items{idx+1} = val;
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: btnTrainingBottom
        function btnTrainingBottomButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx < numel(app.lstTraining.Items)
                app.lstTraining.Items(idx) = [];
                app.lstTraining.Items{end+1} = val;
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: GenerateTrainingSetButton
        function GenerateTrainingSetButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Generating training data...','Value',0, 'Cancelable', 'on');
            
            % Create individual images for each passing and nonpassing object
            if ~isfolder([app.TrainingFolder.Value filesep 'Images'])
                mkdir([app.TrainingFolder.Value filesep 'Images']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise']);
            else
                rmdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object'], 's');
                rmdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise'], 's');
                rmdir([app.TrainingFolder.Value filesep 'Images'], 's');
                mkdir([app.TrainingFolder.Value filesep 'Images']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise']);
            end
            
            try
                switch app.cmbTrainingModel.Value
                    case 'AlexNet',             net = eval('alexnet');
                    case 'VGG-16',              net = eval('vgg16');
                    case 'VGG-19',              net = eval('vgg19');
                    case 'SqueezeNet',          net = eval('squeezenet');
                    case 'GoogleNet',           net = eval('googlenet');
                    case 'Inception-v3',        net = eval('inceptionv3');
                    case 'ResNet-50',           net = eval('resnet50');
                    case 'ResNet-101',          net = eval('resnet101');
                    case 'Inception-ResNet-v2', net = eval('inceptionresnetv2');
                end
                sz = net.Layers(1).InputSize;
            catch
                msgbox([app.cmbTrainingModel.Value ' not available, please install it using the AddOn explorer'], 'NeuralNet Error', 'error');
                return
            end
            
            % Store each object as a separate image in the proper training subfolder
            for exp = 1:numel(app.lstTraining.Items)
                app.lstTraining.Value = app.lstTraining.Items{exp};
                dlg.Title = ['Processing folder' num2str(exp) '/' numel(app.lstTraining.Items) ' : ' app.lstTraining.Items{exp}];
                dlg.Value = exp/numel(app.lstTraining.Items);
                
                cd(app.lstTraining.Items{exp});
                
                % Identify which object set(s) to use
                if app.chkTrainingObjectsAll.Value
                    ObjList = listObjects;
                elseif ~isempty(app.cmbTrainingObjects.Value)
                    ObjList = intersect(listObjects, app.cmbTrainingObjects.Value);
                else
                    ObjList = {};
                end
                
                disp(['Processing ' ObjList{:} ' found in ' app.lstTraining.Items{exp}]);
                
                % Load Image Matrix of each object, save each axis MIP to Noise or Cell Folder
                for i = 1:numel(ObjList)
                    Objs = loadObjects(ObjList{i});
                    
                    for j = 1:numel(Objs.Vox)
                        % Retrieve the intensity values of current object
                        minPt = min(Objs.Vox(j).Pos(:,1:3));
                        maxPt = max(Objs.Vox(j).Pos(:,1:3));
                        imMat = zeros(maxPt-minPt+1, 'uint8');
                        for k = 1:size(Objs.Vox(j).Pos, 1)
                            pt = Objs.Vox(j).Pos(k,:) - minPt + 1;
                            imMat(pt(1),pt(2),pt(3)) = Objs.Vox(j).RawBright(k);
                        end
                        
                        % Treat differently 2D vs 3D images
                        if size(imMat, 3) == 1
                            % 2D image: Resize image and encode as RGB
                            x = imresize(imMat, [sz(1) sz(2)]);
                            I = cat(3,x,x,x);
                        else
                            % 3D image: Create MIPs along cardinal axes, resize and combine into RGB
                            z = imresize(squeeze(max(imMat,[],3)), [sz(1) sz(2)]);
                            y = imresize(squeeze(max(imMat,[],1)), [sz(1) sz(2)]);
                            x = imresize(squeeze(max(imMat,[],2)), [sz(1) sz(2)]);
                            I = cat(3,z,y,x);
                        end
                        [~,expName,~] = fileparts(app.lstTraining.Items{exp});
                        objsName = Objs.Name;
                        
                        % Store the object into 'Object' or 'Noise' according to its valudation status
                        if(Objs.Filter.passF(j))
                            imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Object' filesep expName '_' objsName '_' num2str(j) '.tif']);
                        else
                            imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Noise' filesep expName '_' objsName '_' num2str(j) '.tif']);
                        end
                    end
                end
            end
            
            app.btnNetTrain.Enable = 'on';
            close(dlg)
        end

        % Button pushed function: btnNetTrain
        function btnNetTrainPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            % Choose folder containing training images
            gtFolder = uigetdir([],'Select Training folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if gtFolder == 0
                return
            elseif ~isfolder(gtFolder)
                return
            end
            
            % Load network from disk and ensure layers are defined
            % TODO: check if this way to extract layers keeps also weights from prev trainings
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN  = loadNetwork(UID{:});
            if ~isfield(NN, 'Layers')
                if isa(NN.Net,'SeriesNetwork')
                    NN.Layers = layerGraph(NN.Net.Layers);
                else
                    NN.Layers = layerGraph(NN.Net);
                end
            end
            
            
            switch app.cmbNetType.Value
                case 'Object classifier'
                    % Load the training set and split images in a randomized way according to user defined split ratio
                    imds = imageDatastore(gtFolder,'IncludeSubfolders',true,'LabelSource','foldernames');
                    [trainingImages, validationImages] = splitEachLabel(imds, 0.9,'randomized');
                    
                    % Augment training dataset if chosen by user, otherwise just conform to size required by net
                    % trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages); % No image augmentation
                    inputSize = NN.Layers.Layers(1).InputSize;
                    switch app.TrainingAugment.Value
                        case 'On'
                            pixelRange       = [-30 30];
                            scaleRange       = [0.9 1.1];
                            imageAugmenter   = imageDataAugmenter('RandXReflection',true, 'RandXTranslation',pixelRange, 'RandYTranslation',pixelRange, 'RandXScale',scaleRange, 'RandYScale',scaleRange);
                            trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages, 'DataAugmentation',imageAugmenter);
                        case 'Off'
                            trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages);
                    end
                    
                    % Define training options
                    miniBatchSize           = app.TrainMiniBatchSize.Value;
                    numIterationsPerEpoch   = floor(trainingImages.NumObservations/miniBatchSize);
                    NN.trainOpts = trainingOptions(...
                        'sgdm',...
                        'Momentum',app.TrainMomentum.Value/100,...
                        'MiniBatchSize',miniBatchSize,...
                        'MaxEpochs',app.TrainMaxEpochs.Value,...
                        'Shuffle', app.cmbNetShuffle.Value,...
                        'InitialLearnRate',app.TrainInitialLearningRate.Value,...
                        'Verbose',false,...
                        'Plots','training-progress',...
                        'ExecutionEnvironment', app.cmbExecEnvironment.Value,...
                        'ValidationData',validationImages,...
                        'ValidationFrequency',numIterationsPerEpoch);
                    
                    % Train the network using training dataet
                    NN.Net      = trainNetwork(trainingImages, NN.Layers, NN.trainOpts);
                    predicted   = classify(NN.Net, augmentedImageDatastore(inputSize(1:2), validationImages));
                    expected    = validationImages.Labels;
                    accuracy    = sum(predicted == expected) / numel(expected);
                    disp(['NeuralNet accuracy = ' num2str(accuracy)]);
            end
            
            NN.Trained      = true;
            NN.Date         = datetime;
            saveNetwork(NN);
            
            dlg.Title = 'Cleaning Video Card(s) memory...';
            % clear GPU memory
            for i = 1:gpuDeviceCount
                gpuDevice(i);
                reset(gpuDevice(i));
                wait(gpuDevice(i));
            end
            
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNetTable;
            close(dlg);
        end

        % Button pushed function: btnColocalizeMask
        function btnColocalizeMaskButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Automatically colocalizing...','Indeterminate', 'on');
            
            srcObjs = loadObjects(app.lstColoObjects.Value);
            
            PathName = [pwd filesep 'I' filesep];
            MaskName = app.lstColoImages.Value{1};
            if isempty(MaskName)
                return
            end
            
            Colo = loadImage(MaskName);
            if numel(unique(Colo)) ~= 2
                errordlg('Selected image is not a binary mask','File Error');
                return
            end
            Colo = Colo./max(Colo(:)); % Normalize mask values to 0-1 (ImageJ uses 0-255)
            
            switch app.ColoSwitchVoxPerc.Value
                case 'Vox'
                    NumVoxOverlap  = app.ColoSharedVoxNum.Value;
                    NumPercOverlap = 0;
                case '%'
                    NumVoxOverlap  = 0;
                    NumPercOverlap = app.ColoSharedVoxNum.Value;
            end
            
            ColocAuto = colocAutoMask(srcObjs, Colo, [PathName MaskName], NumVoxOverlap, NumPercOverlap, app.chkColoCenterOverlap.Value);
            %             assignin('base', 'ColocAuto', ColocAuto);
            
            if isempty(fieldnames(srcObjs.Coloc))
                srcObjs.Coloc = ColocAuto;
            else
                if isfield(srcObjs.Coloc,'Fish2')
                    srcObjs.Coloc = rmfield(srcObjs.Coloc, 'Fish2');
                end
                
                % Check if we need to replace a previously done analysis with same mask
                FoundColocManual  = false;
                for i= 1:numel(srcObjs.Coloc)
                    if strcmp(srcObjs.Coloc(i).Source, ColocAuto.Source) && strcmp(srcObjs.Coloc(i).Fish1, ColocAuto.Fish1)
                        srcObjs.Coloc(i) = ColocAuto;
                        FoundColocManual = true;
                        break
                    end
                end
                
                % If nothing to replace, just append to the list
                if ~FoundColocManual
                    srcObjs.Coloc(end+1) = ColocAuto; % Coloc is saved later
                end
            end
            
            saveObjects(srcObjs);
            
            % If selected by user, also save colocalized objects as a new set
            if app.chkColoSaveAsNewObjects.Value
                srcColoObjs = srcObjs;
                srcColoObjs.UID = generateUID;
                srcColoObjs.Name = [srcObjs.Name ' colocalized with ' ColocAuto.Fish1];
                srcColoObjs.Filter.passF = logical(ColocAuto.ColocFlag == 1);
                srcColoObjs.Coloc = struct;
                srcColoObjs = app.calcObjDensity(srcColoObjs);
                srcColoObjs.Settings.ImInfo.MaskChName = MaskName; 
                saveObjects(srcColoObjs);
                app.RefreshValidObjects;
            end
            
            % If selected by user, also save NOT colocalized objects as a new set
            if app.chkNotColoSaveAsNewObjects.Value
                srcNonColoObjs = srcObjs;
                srcNonColoObjs.UID = generateUID;
                srcNonColoObjs.Name = [srcObjs.Name ' not colocalized with ' ColocAuto.Fish1];
                srcNonColoObjs.Filter.passF = logical(ColocAuto.ColocFlag == 2);
                srcNonColoObjs.Coloc = struct;
                srcNonColoObjs = app.calcObjDensity(srcNonColoObjs);
                saveObjects(srcNonColoObjs);
                app.RefreshValidObjects;
            end
            
            app.RefreshColoDone;
            close(dlg);
        end

        % Button pushed function: btnRenameObjects
        function btnRenameObjectsButtonPushed(app, event)
            opts.Resize = 'off';
            opts.WindowStyle = 'modal';
            opts.Interpreter = 'none';
            answer = inputdlg('New name for selected objects','Rename objects', [1 40],{app.Dots.Name},opts);
            if ~isempty(answer)
                dlg = uiprogressdlg(app.UIFigure,'Title','Renaming objects...','Indeterminate', 'on');
                
                app.Dots.Name = answer{:};
                saveObjects(app.Dots, 'Name');
                
                app.RefreshValidObjects;
                app.cmbObjsDetected.Value = app.Dots.UID;
                app.cmbObjsDetectedValueChanged;
                close(dlg);
            end
        end

        % Value changed function: cmbObjsDetected
        function cmbObjsDetectedValueChanged(app, event)
            if isempty(app.cmbObjsDetected.Value)
                % Load default values for the GUI
                app.cmbObjsDetected.Tooltip         = 'Current Objects';
                app.btnFilterObjs.Enable            = 'off';
                app.btnRecalculateSphericity.Enable = 'off';
                app.btnValidateObjects.Enable       = 'off';
                app.btnRenameObjects.Enable         = 'off';
                app.btnDeleteObjects.Enable         = 'off';
                app.btnCopyObjects.Enable           = 'off';
                app.btnPurgeInvalid.Enable          = 'off';
                app.btnPlotLocations.Enable         = 'off';
                app.btnCreateObjMask.Enable         = 'off';
                app.btnCreateObjMaskColored.Enable  = 'off';
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading objects...','Indeterminate', 'on');
            app.Dots = loadObjects(app.cmbObjsDetected.Value);
            
            % Load detection settings from current objects in the GUI
            app.Settings.ImInfo                 = app.Dots.Settings.ImInfo;
            app.Watershed.Value                 = app.Dots.Settings.objfinder.watershed;
            app.BlockSearch.Value               = app.Dots.Settings.objfinder.blockSearch;
            app.Sphericity.Value                = app.Dots.Settings.objfinder.sphericity;
            if isfield(app.Dots.Settings.objfinder, 'localNoise')
                app.LocalNoise.Value            = app.Dots.Settings.objfinder.localNoise;
            else
                app.LocalNoise.Value            = app.BlockSearch.Value;
            end
            app.MinIntensity.Value              = app.Dots.Settings.objfinder.minIntensity;
            app.SingleZDotCut.Value             = app.Dots.Settings.Filter.SingleZDotCut;
            app.EdgeDotCut.Value                = app.Dots.Settings.Filter.EdgeDotCut;
            app.xyStableDots.Value              = app.Dots.Settings.Filter.xyStableDots;
            app.cmbObjsDetected.Tooltip         = ['Current Objects (Valid: ' num2str(numel(find(app.Dots.Filter.passF))) ' / ' num2str(numel(app.Dots.Filter.passF)) ')'];
            app.btnFilterObjs.Enable            = 'on';
            app.btnRecalculateSphericity.Enable = 'on';
            app.btnFindObjects.Enable           = 'on';
            app.btnSimpleSettings.Enable        = 'on';
            app.btnValidateObjects.Enable       = 'on';
            app.btnRenameObjects.Enable         = 'on';
            app.btnDeleteObjects.Enable         = 'on';
            app.btnCopyObjects.Enable           = 'on';
            app.btnPlotLocations.Enable         = 'on';
            app.btnCalcDiameter.Enable          = 'on';
            app.btnPurgeInvalid.Enable          = 'on';
            app.btnCreateObjMask.Enable         = 'on';
            app.btnCreateObjMaskColored.Enable  = 'on';
            if ~isempty(app.Dots)
                app.Settings.ImInfo = app.Dots.Settings.ImInfo;
                app.cmbPost.Value                   = app.Dots.Settings.ImInfo.PostChName;
                app.cmbPostValueChanged;
                if isfield(app.Dots.Settings.ImInfo, 'MaskChName') && ismember(app.Dots.Settings.ImInfo.MaskChName,app.cmbMask.Items)
                    app.cmbMask.Value = app.Dots.Settings.ImInfo.MaskChName;
                else
                    app.cmbMask.Value = '';
                end
                app.cmbMaskValueChanged;
            end
            close(dlg);
        end

        % Button pushed function: btnDeleteObjects
        function btnDeleteObjectsButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Permanently delete selected objects?','Delete objects','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting objects...','Indeterminate', 'on');
                    delete([pwd filesep 'objects' filesep app.Dots.UID '.mat']);
                    app.Dots = [];
                    app.RefreshValidObjects;
                    app.cmbObjsDetectedValueChanged;
                    close(dlg);
            end
        end

        % Button pushed function: btnCreateObjMask
        function btnCreateObjMaskButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Creating a mask of objects','Indeterminate', 'on');
            dlg.Message = 'Generating mask...';
            Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
            ImInfo = Objs.Settings.ImInfo;
            I = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
            for i = 1:numel(Objs.Vox)
                I(Objs.Vox(i).Ind) = 1;
            end
            dlg.Message = 'Saving mask...';
            saveastiff(I, [pwd filesep 'I' filesep app.Dots.Name '_objects_mask.tif']);
            close(dlg);
        end

        % Button pushed function: btnPlotObjSizeDist
        function btnPlotObjSizeDistButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects distribution...','Indeterminate', 'on');
            
            Objs        = getFilteredObjects(app.Dots, app.Dots.Filter);
            VoxelVolume = app.Dots.Settings.ImInfo.xyum * app.Dots.Settings.ImInfo.xyum * app.Dots.Settings.ImInfo.zum;
            Data        = Objs.Vol.*VoxelVolume; % Convert volumes from voxels to micron^3
            
            % Fit data with selected distribution type and plot
            figure('Name', 'Objects size distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
            subplot(1,2,1);
            h               = histfit(Data, [], app.ReportDistFitType.Value);
            h(1).FaceColor  = [.8 .8 1]; % violet histogram
            xlabel('Volume (µm^3)');
            ylabel('Number of objects');
            legend({app.Dots.Name, [app.ReportDistFitType.Value ' distribution']});
            title([app.Dots.Name ' size distribution']);
            
            % Retrieve data from plot
            VolumeBin   = get(h(1),'xdata')';   % Number of object on first column
            Count       = get(h(1),'ydata')';   % Volume of each object on second column
            FitVolumeBin= get(h(2),'xdata')';   % Bin number for the fitted distribution
            FitCount    = get(h(2),'ydata')';   % Fitted values
            
            % Calculate and plot Cumulative distribution data
            CumCount        = Count;
            for i = 1:numel(Count)
                if i==1
                    CumCount(i) = 100* Count(i)/sum(Count);
                else
                    CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                end
            end
            subplot(1,2,2);
            plot(VolumeBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
            ylim([0 100]);
            box off;
            xlabel('Volume (µm^3)');
            ylabel('Percentage of objects');
            title([app.Dots.Name ' size cumulative distribution']);
            
            % Export individual data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Volume_rawdata.csv'];
            Object      = (1:numel(Objs.Vol))'; % Number of object on first column
            Volume      = Data';                % Volume of each object on second column
            TableData   = table(Object,Volume);
            writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
            
            % Export distribution data as Excel table inside the results folder
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Volume_distribution.xls'];
            TableData   = table(VolumeBin, Count, CumCount);
            writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
            
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Volume_distribution_fit_' app.ReportDistFitType.Value '.xls'];
            TableData   = table(FitVolumeBin, FitCount);
            writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
            close(dlg);
        end

        % Button pushed function: btnPlotObjBrightDist
        function btnPlotObjBrightDistButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects distribution...','Indeterminate', 'on');
            
            Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
            figure('Name', 'Objects average brightness distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
            subplot(1,2,1);
            
            % Fit data with selected distribution type and plot
            h = histfit(Objs.MeanBright, [], app.ReportDistFitType.Value);
            h(1).FaceColor = [.8 .8 1]; % violet histogram
            xlim([0 256]);
            xlabel('Brightness level');
            ylabel('Number of objects');
            legend({app.Dots.Name, [app.ReportDistFitType.Value ' distribution']});
            title([app.Dots.Name ' average brighness distribution']);
            
            % Collect plotted data
            BrightnessBin   = get(h(1),'xdata')';   % Number of object on first column
            Count           = get(h(1),'ydata')';   % Volume of each object on second column
            FitBrightnessBin= get(h(2),'xdata')';   % Bin number for the fitted distribution
            FitCount        = get(h(2),'ydata')';   % Fitted values
            
            % Calculate and plot Cumulative distribution data
            CumCount        = Count;
            for i = 1:numel(Count)
                if i==1
                    CumCount(i) = 100* Count(i)/sum(Count);
                else
                    CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                end
            end
            subplot(1,2,2);
            plot(BrightnessBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
            ylim([0 100]);
            xlim([0 255]);
            box off;
            xlabel('Brighness level');
            ylabel('Percentage of objects');
            title([app.Dots.Name ' average brightness cumulative distribution']);
            
            % Export data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Brighness_rawdata.csv'];
            Object      = (1:numel(Objs.MeanBright))'; % Number of object on first column
            Brightness  = Objs.MeanBright';            % Volume of each object on second column
            TableData   = table(Object, Brightness);
            writetable(TableData, FileName);           % Write an Excel table with Object-Brightness pairs
            
            % Export distribution data as Excel table inside the results folder
            FileName        = [pwd filesep 'results' filesep app.Dots.Name '_Brightness_distribution.xls'];
            TableData       = table(BrightnessBin, Count, CumCount);
            writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
            
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Brightness_distribution_fit_' app.ReportDistFitType.Value '.xls'];
            TableData   = table(FitBrightnessBin, FitCount);
            writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
            close(dlg);
        end

        % Value changed function: lstColoObjects
        function lstColoObjectsValueChanged(app, event)
            switch numel(app.lstColoObjects.Value)
                case 1
                    app.btnColocalizeManual.Enable  = 'off';
                    app.btnColocalizeMask.Enable    = 'off';
                    app.btnColocalizeNN.Enable      = 'off';
                    app.btnColocalizeEngulf.Enable  = 'off';
                    app.ColoSharedVoxNum.Enable     = 'off';
                    app.ColoSwitchVoxPerc.Enable    = 'off';
                    app.ColoThresholdDist.Enable    = 'off';
                    app.lstColoImages.Enable        = 'on';
                    app.btnColoRefreshObjs.Enable   = 'on';
                case 2
                    app.btnColocalizeManual.Enable  = 'off';
                    app.btnColocalizeMask.Enable    = 'off';
                    app.btnColocalizeNN.Enable      = 'on';
                    app.btnColocalizeEngulf.Enable  = 'on';
                    app.ColoSharedVoxNum.Enable     = 'on';
                    app.ColoSwitchVoxPerc.Enable    = 'on';
                    app.ColoThresholdDist.Enable    = 'on';
                    app.lstColoImages.Enable        = 'off';
                    app.lstColoImages.Value         = {};
                    app.btnColoRefreshObjs.Enable   = 'on';
                    app.btnColoRefreshImgs.Enable   = 'off';
                    app.chkColoCenterOverlap.Enable = 'on';
                otherwise
                    app.btnColocalizeManual.Enable  = 'off';
                    app.btnColocalizeMask.Enable    = 'off';
                    app.btnColocalizeNN.Enable      = 'off';
                    app.btnColocalizeEngulf.Enable  = 'off';
                    app.ColoSharedVoxNum.Enable     = 'off';
                    app.ColoSwitchVoxPerc.Enable    = 'off';
                    app.ColoThresholdDist.Enable    = 'off';
                    app.lstColoImages.Enable        = 'on';
                    app.btnColoRefreshObjs.Enable   = 'on';
            end
            
            app.lstColoImagesValueChanged;
            app.RefreshColoDone;
        end

        % Button pushed function: btnCalculateNN
        function btnCalculateNNButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Measuring Nearest Neighbor Distance...','Indeterminate', 'on');
            
            srcObjs = app.Dots;
            dstObjs = loadObjects(app.ReportSelObjectsNN.Value);
            
            if ~isempty(srcObjs) && ~isempty(dstObjs)
                NN = app.calcNNDistance(srcObjs, dstObjs, true);
                
                if isempty(fieldnames(srcObjs.NN))
                    srcObjs.NN = NN;
                else
                    % Check if we need to replace a previously done analysis with same mask
                    FoundColocManual  = false;
                    for i = 1:numel(srcObjs.NN)
                        if strcmp(srcObjs.NN(i).Name, NN.Name)
                            srcObjs.NN(i) = NN;
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        srcObjs.NN(end+1) = NN;
                    end
                end
                app.Dots = srcObjs;
                saveObjects(app.Dots);
                app.ReportSelObjectsNNValueChanged;
            end
            close(dlg);
        end

        % Value changed function: ReportSelObjectsNN
        function ReportSelObjectsNNValueChanged(app, event)
            if isempty(app.Dots)
                return
            end
            name_dst = app.ReportSelObjectsNN.Items{strcmp(app.ReportSelObjectsNN.ItemsData,app.ReportSelObjectsNN.Value)};
            idx_dst = 0;
            for i= 1:numel(app.Dots.NN)
                if numel(fieldnames(app.Dots.NN(i))) > 0
                    if strcmp(app.Dots.NN(i).Name,  name_dst)
                        idx_dst = i; % Found the matching item
                        break
                    end
                end
            end
            
            if idx_dst > 0
                idx_valid = find(app.Dots.Filter.passF);
                app.lblNNdistance.Text      = ['Nearest neighbor distance: '  sprintf('%0.3f', mean(app.Dots.NN(idx_dst).Dist(idx_valid))) ' ' char(177) ' ' sprintf('%0.3f', std(app.Dots.NN(idx_dst).Dist(idx_valid))) ' µm'];
                app.lblNNvoxelOverlap.Text  = ['Percent voxels overlapping: ' sprintf('%0.2f', mean(app.Dots.NN(idx_dst).VoxOverlapPerc(idx_valid))) ' ' char(177) ' ' sprintf('%0.2f', std(app.Dots.NN(idx_dst).VoxOverlapPerc(idx_valid))) ' %'];
            else
                app.lblNNdistance.Text      = 'Nearest neighbor distance: N/A';
                app.lblNNvoxelOverlap.Text  = 'Percent voxels overlapping: N/A';
            end
            
        end

        % Button pushed function: btnDeleteNN
        function btnDeleteNNPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Permanently delete selected analysis?','Delete objects','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting Nearest Neighbor Analysis...','Indeterminate', 'on');
                    
                    name_dst = app.ReportSelObjectsNN.Items{strcmp(app.ReportSelObjectsNN.ItemsData,app.ReportSelObjectsNN.Value)};
                    idx_dst = 0;
                    % Find index of the matching NN analysis against requested name from the listbox
                    if ~isempty(app.Dots)
                        for i=1: numel(app.Dots.NN)
                            if numel(fieldnames(app.Dots.NN(i))) > 0
                                if strcmp(app.Dots.NN(i).Name,  name_dst)
                                    idx_dst = i; % Found the matching item
                                    break
                                end
                            end
                        end
                        
                        if idx_dst > 0
                            app.Dots.NN(idx_dst) = [];
                            saveObjects(app.Dots, 'NN');
                            app.ReportSelObjectsNNValueChanged;
                        end
                    end
                    close(dlg);
            end
        end

        % Button pushed function: btnPlotNNdistDist
        function btnPlotNNdistDistButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects distribution...','Indeterminate', 'on');
            
            % Find index of the matching NN analysis against requested name from the listbox
            name_dst = app.ReportSelObjectsNN.Items{strcmp(app.ReportSelObjectsNN.ItemsData,app.ReportSelObjectsNN.Value)};
            idx_dst = 0;
            for i=1: numel(app.Dots.NN)
                if numel(fieldnames(app.Dots.NN(i))) > 0
                    if strcmp(app.Dots.NN(i).Name,  name_dst)
                        idx_dst = i; % Found the matching item
                        break
                    end
                end
            end
            
            if idx_dst > 0
                idx_valid = find(app.Dots.Filter.passF);
                data = app.Dots.NN(idx_dst).Dist(idx_valid);
                figure('Name', 'Nearest Neighbor distance distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                
                % Fit data with selected distribution type and plot
                h = histfit(data, [], app.ReportDistFitType.Value);
                h(1).FaceColor = [.8 .8 1]; % violet histogram
                xlabel('Distance (µm)');
                ylabel('Number of objects');
                xlim([0 max(data)]);
                legend({[app.Dots.NN(idx_dst).Name ' -> ' app.Dots.Name],[app.ReportDistFitType.Value ' distribution']});
                title(['Nearest ' app.Dots.NN(idx_dst).Name ' to ' app.Dots.Name]);
                
                % Collect plotted data
                NNdistBin       = get(h(1),'xdata')';   % Bin data
                Count           = get(h(1),'ydata')';   % Count data
                FitNNdistBin    = get(h(2),'xdata')';   % Fitted Bin data
                FitCount        = get(h(2),'ydata')';   % Fitted Count data
                
                % Calculate and plot Cumulative distribution data
                CumCount        = Count;
                for i = 1:numel(Count)
                    if i==1
                        CumCount(i) = 100* Count(i)/sum(Count);
                    else
                        CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                    end
                end
                subplot(1,2,2);
                plot(NNdistBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                ylim([0 100]);
                box off;
                xlabel('Distance (µm)');
                ylabel('Percentage of objects');
                title(['Nearest ' app.Dots.NN(idx_dst).Name ' to ' app.Dots.Name ' cumulative distribution']);
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots.Name  '-' app.Dots.NN(idx_dst).Name '_NNdistance_rawdata.xls'];
                Object      = (1:numel(idx_valid))'; % Number of object on first column
                NNdist      = data';                 % Volume of each object on second column
                TableData   = table(Object, NNdist);
                try
                    writetable(TableData, FileName);     % Write an Excel table with Object-NNdistance pairs
                catch
                    disp('Cannot write the spreadsheet file');
                end
                
                % Export distribution data as Excel table inside the results folder
                FileName        = [pwd filesep 'results' filesep app.Dots.Name '-' app.Dots.NN(idx_dst).Name '_NNdistance_distribution.xls'];
                TableData       = table(NNdistBin, Count, CumCount);
                try
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                catch
                    disp('Cannot write the spreadsheet file');
                end
                
                FileName        = [pwd filesep 'results' filesep app.Dots.Name '-' app.Dots.NN(idx_dst).Name '_NNdistance_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                TableData       = table(FitNNdistBin, FitCount);
                try
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                catch
                    disp('Cannot write the spreadsheet file');
                end
            end
            close(dlg);
        end

        % Button pushed function: btnPlotNNoverlapDist
        function btnPlotNNoverlapDistButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects distribution...','Indeterminate', 'on');
            
            % Find index of the matching NN analysis against requested name from the listbox
            name_dst = app.ReportSelObjectsNN.Items{strcmp(app.ReportSelObjectsNN.ItemsData,app.ReportSelObjectsNN.Value)};
            idx_dst = 0;
            for i = 1:numel(app.Dots.NN)
                if numel(fieldnames(app.Dots.NN(i))) > 0
                    if strcmp(app.Dots.NN(i).Name,  name_dst)
                        idx_dst = i; % Found the matching item
                        break
                    end
                end
            end
            
            if idx_dst > 0
                idx_valid = find(app.Dots.Filter.passF);
                data = app.Dots.NN(idx_dst).VoxOverlapPerc(idx_valid);
                figure('Name', 'Nearest Neighbor overlap distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                
                % Fit data with selected distribution type and plot
                h = histfit(data, [], app.ReportDistFitType.Value);
                h(1).FaceColor = [.8 .8 1]; % violet histogram
                xlim([-10 110]);
                xlabel('Volume overlap (%)');
                ylabel('Number of objects');
                legend({[app.Dots.NN(idx_dst).Name ' -> ' app.Dots.Name],[app.ReportDistFitType.Value ' distribution']});
                title(['Nearest ' app.Dots.NN(idx_dst).Name ' to ' app.Dots.Name]);
                
                % Collect data from plot
                NNoverlapBin    = get(h(1),'xdata')';   % Bin data
                Count           = get(h(1),'ydata')';   % Count data
                FitNNoverlapBin = get(h(2),'xdata')';   % Fitted Bin data
                FitCount        = get(h(2),'ydata')';   % Fitted Count data
                
                % Calculate and plot Cumulative distribution data
                CumCount        = Count;
                for i = 1:numel(Count)
                    if i==1
                        CumCount(i) = 100* Count(i)/sum(Count);
                    else
                        CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                    end
                end
                subplot(1,2,2);
                plot(NNoverlapBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                ylim([0 100]);
                box off;
                xlabel('Volume overlap (%)');
                ylabel('Percentage of objects');
                title(['Nearest ' app.Dots.NN(idx_dst).Name ' to ' app.Dots.Name ' cumulative distribution']);
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots.Name '-' app.Dots.NN(idx_dst).Name '_NNoverlap_rawdata.xls'];
                Object      = (1:numel(idx_valid))'; % Number of object on first column
                NNoverlap   = data';                 % Volume of each object on second column
                TableData   = table(Object, NNoverlap);
                try
                    writetable(TableData, FileName);     % Write an Excel table with Object-NNdistance pairs
                catch
                    disp('Cannot write the spreadsheet file');
                end
                
                % Export distribution data as Excel table inside the results folder
                FileName        = [pwd filesep 'results' filesep app.Dots.Name '-' app.Dots.NN(idx_dst).Name '_NNoverlap_distribution.xls'];
                TableData       = table(NNoverlapBin, Count, CumCount);
                try
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                catch
                    disp('Cannot write the spreadsheet file');
                end
                
                FileName        = [pwd filesep 'results' filesep app.Dots.Name '-' app.Dots.NN(idx_dst).Name '_NNoverlap_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                TableData       = table(FitNNoverlapBin, FitCount);
                try
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                catch
                    disp('Cannot write the spreadsheet file');
                end
            end
            close(dlg);
        end

        % Button pushed function: btnColocalizeNN
        function btnColocalizeNNButtonPushed(app, event)
            RepeatNN = false;
            switch uiconfirm(app.UIFigure, 'Recalculate Nearest Neighbor analysis if present?','Nearest Neighbor Analysis','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes', RepeatNN = true;
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Colocalization analysis','Indeterminate', 'on');
            dlg.Message = 'Loading reference objects...';
            srcDots   = loadObjects(app.lstColoObjects.Value{1});
            dlg.Message = 'Loading test objects...';
            dstDots   = loadObjects(app.lstColoObjects.Value{2});
            
            % Find nearest dstDots to scrDots
            idx_NN  = 0;
            for i= 1: numel(srcDots.NN)
                
                if numel(fieldnames(srcDots.NN(i))) > 0
                    if strcmp(srcDots.NN(i).Name,  dstDots.Name)
                        idx_NN = i; % Found the matching item
                        break
                    end
                end
            end
            
            % Calculate nearest neighbor distances if not already done
            if idx_NN == 0 || RepeatNN
                dlg.Message = 'Measuring Nearest Neighbor Distance...';
                NN = app.calcNNDistance(srcDots, dstDots, true);
                if isempty(fieldnames(srcDots.NN))
                    srcDots.NN = NN;
                else
                    % Check if we need to replace a previously done analysis with same mask
                    FoundColocManual  = false;
                    for i = 1:numel(srcDots.NN)
                        if strcmp(srcDots.NN(i).Name, NN.Name)
                            srcDots.NN(i) = NN;
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        srcDots.NN(end+1) = NN;
                    end
                end
                
                saveObjects(srcDots);
                if strcmp(srcDots.UID, app.Dots.UID)
                    app.Dots = srcDots;
                end
                
                for i= 1: numel(srcDots.NN)
                    if numel(fieldnames(srcDots.NN(i))) > 0
                        if strcmp(srcDots.NN(i).Name,  dstDots.Name)
                            idx_NN = i; % Found the matching item
                            break
                        end
                    end
                end
                
            end
            
            % Perform colocalization analysis now that NN distances are ensured present
            dlg.Message = 'Measuring colocalization...';
            if idx_NN > 0
                switch app.ColoSwitchVoxPerc.Value
                    case 'Vox'
                        NumVoxOverlap  = app.ColoSharedVoxNum.Value;
                        NumPercOverlap = 0;
                    case '%'
                        NumVoxOverlap  = 0;
                        NumPercOverlap = app.ColoSharedVoxNum.Value;
                end
                WithinDist = app.ColoThresholdDist.Value;
                
                % Assign colocalized status to nearest neighbor objects sharing more than NumVoxOverlap AND NumPercOverlap, AND WithinDist
                ColocAuto = colocAutoNN(srcDots, dstDots, srcDots.NN(idx_NN), NumVoxOverlap, NumPercOverlap, WithinDist, app.chkColoCenterOverlap.Value);
                
                if isempty(fieldnames(srcDots.Coloc))
                    srcDots.Coloc = ColocAuto;
                else
                    % Check if we need to replace a previously done analysis with same mask
                    FoundColocManual  = false;
                    for i=1:numel(srcDots.Coloc)
                        if strcmp(srcDots.Coloc(i).Source, ColocAuto.Source) && strcmp(srcDots.Coloc(i).Fish1, ColocAuto.Fish1)
                            srcDots.Coloc(i) = ColocAuto;
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        srcDots.Coloc(end+1) = ColocAuto; % Coloc is saved later
                    end
                end
                saveObjects(srcDots, 'Coloc');
                
                % If selected by user, also save colocalized objects as a new set
                if app.chkColoSaveAsNewObjects.Value
                    srcColoDots = srcDots;
                    srcColoDots.UID = generateUID;
                    srcColoDots.Name = [srcDots.Name ' colocalized with ' ColocAuto.Fish1];
                    srcColoDots.Filter.passF = logical(ColocAuto.ColocFlag == 1);
                    srcColoDots.Coloc = struct;
                    srcColoDots = app.calcObjDensity(srcColoDots);
                    saveObjects(srcColoDots);
                    app.RefreshValidObjects;
                end
                
                % If selected by user, also save Not colocalized objects as a new set
                if app.chkNotColoSaveAsNewObjects.Value
                    srcNonColoDots = srcDots;
                    srcNonColoDots.UID = generateUID;
                    srcNonColoDots.Name = [srcDots.Name ' not colocalized with ' ColocAuto.Fish1];
                    srcNonColoDots.Filter.passF = logical(ColocAuto.ColocFlag == 2);
                    srcNonColoDots.Coloc = struct;
                    srcNonColoDots = app.calcObjDensity(srcNonColoDots);
                    saveObjects(srcNonColoDots);
                    app.RefreshValidObjects;
                end
                
                app.RefreshColoDone;
            end
            close(dlg);
        end

        % Button pushed function: btnPlotObjRoundDist
        function btnPlotObjRoundDistButtonPushed(app, event)
            if isempty(app.Dots) > 0 || ~isfield(app.Dots.Shape, 'Oblong')
                msgbox('No shape information available.', 'Plot Error', 'error');
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects distribution...','Indeterminate', 'on');
            
            Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
            figure('Name', 'Objects space distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
            subplot(1,2,1);
            
            % Fit data with selected distribution type and plot
            h = histfit(Objs.Shape.Oblong, [], app.ReportDistFitType.Value);
            h(1).FaceColor = [.8 .8 1]; % violet histogram
            xlim([0 1]);
            xlabel('Oblongness (0: line, 1: sphere)');
            ylabel('Number of objects');
            legend({app.Dots.Name, [app.ReportDistFitType.Value ' distribution']});
            title([app.Dots.Name ' shape distribution']);
            
            % Collect plotted data
            SphericityBin   = get(h(1),'xdata')';   % Bin data
            Count           = get(h(1),'ydata')';   % Count data
            FitSphericityBin= get(h(2),'xdata')';   % Fitted Bin data
            FitCount        = get(h(2),'ydata')';   % Fitted Count data
            
            % Calculate and plot Cumulative distribution data
            CumCount        = Count;
            for i = 1:numel(Count)
                if i==1
                    CumCount(i) = 100* Count(i)/sum(Count);
                else
                    CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                end
            end
            subplot(1,2,2);
            plot(SphericityBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
            ylim([0 100]);
            xlim([0 1]);
            box off;
            xlabel('Oblongness (0: line, 1: sphere)');
            ylabel('Percentage of objects');
            title([app.Dots.Name ' shape cumulative distribution']);
            
            % Export data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Oblongness_rawdata.csv'];
            Object      = (1:numel(Objs.Shape.Oblong))'; % Number of object on first column
            Oblongness  = Objs.Shape.Oblong';            % Volume of each object on second column
            TableData   = table(Object, Oblongness);
            writetable(TableData, FileName);             % Write an Excel table with Object-Oblongness pairs
            
            % Export distribution data as Excel table inside the results folder
            FileName        = [pwd filesep 'results' filesep app.Dots.Name '_Sphericity_distribution.xls'];
            TableData       = table(SphericityBin, Count, CumCount);
            writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
            
            FileName        = [pwd filesep 'results' filesep app.Dots.Name '_Sphericity_distribution_fit_' app.ReportDistFitType.Value '.xls'];
            TableData       = table(FitSphericityBin, FitCount);
            writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
            close(dlg);
        end

        % Button pushed function: btnPlotObjMajorAxisDist
        function btnPlotObjMajorAxisDistButtonPushed(app, event)
            if isempty(app.Dots) || ~isfield(app.Dots.Shape, 'PrincipalAxisLen')
                msgbox('No shape information available.', 'Plot Error', 'error');
                return
            end
            dlg = uiprogressdlg(app.UIFigure,'Title','Plotting objects distribution...','Indeterminate', 'on');
            
            Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
            figure('Name', 'Objects length distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
            subplot(1,2,1);
            
            % Fit data with selected distribution type and plot
            h = histfit(Objs.Shape.PrincipalAxisLen(:,1)*app.Dots.Settings.ImInfo.xyum, [], app.ReportDistFitType.Value);
            h(1).FaceColor = [.8 .8 1]; % violet histogram
            xlabel('Major axis length (µm)');
            ylabel('Number of objects');
            legend({app.Dots.Name, [app.ReportDistFitType.Value ' distribution']});
            title([app.Dots.Name ' length distribution']);
            
            % Collect plotted data
            MajorAxisBin    = get(h(1),'xdata')';   % Bin data
            Count           = get(h(1),'ydata')';   % Count data
            FitMajorAxisBin = get(h(2),'xdata')';   % Fitted Bin data
            FitCount        = get(h(2),'ydata')';   % Fitted Count data
            
            % Calculate and plot Cumulative distribution data
            CumCount        = Count;
            for i = 1:numel(Count)
                if i==1
                    CumCount(i) = 100* Count(i)/sum(Count);
                else
                    CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                end
            end
            subplot(1,2,2);
            plot(MajorAxisBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
            ylim([0 100]);
            box off;
            xlabel('Major axis length (µm)');
            ylabel('Percentage of objects');
            title([app.Dots.Name ' length cumulative distribution']);
            
            % Export data as Excel table inside the results folder
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_MajorAxisLength_rawdata.csv'];
            Object      = (1:size(Objs.Shape.PrincipalAxisLen,1))'; % Number of object on first column
            MajorAxis   = Objs.Shape.PrincipalAxisLen(:,1)*app.Dots.Settings.ImInfo.xyum; % Length of the major principal axis
            TableData   = table(Object, MajorAxis);
            writetable(TableData, FileName);
            
            % Export distribution data as Excel table inside the results folder
            FileName        = [pwd filesep 'results' filesep app.Dots.Name '_MajorAxisLength_distribution.xls'];
            TableData       = table(MajorAxisBin, Count, CumCount);
            writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
            
            FileName        = [pwd filesep 'results' filesep app.Dots.Name '_MajorAxisLength_distribution_fit_' app.ReportDistFitType.Value '.xls'];
            TableData       = table(FitMajorAxisBin, FitCount);
            writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
            close(dlg);
        end

        % Button pushed function: btnRecalculateSphericity
        function btnRecalculateSphericityButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            
            % Recalculate sphericity of objects
            dlg = uiprogressdlg(app.UIFigure,'Title','Measuring objects shape...','Indeterminate', 'on');
            app.Dots.Settings.objfinder.sphericity = true;
            app.Dots = fitSphere(app.Dots, app.Dots.Settings);
            saveObjects(app.Dots, 'Shape');
            app.cmbObjsDetectedValueChanged;
            close(dlg);
        end

        % Value changed function: cmbInspectorType
        function cmbInspectorTypeValueChanged(app, event)
            value = app.cmbInspectorType.Value;
            switch value
                case 'Neural Net'
                    app.cmbNeuralNet.Enable = 'on';
                    app.RefreshNeuralNets;
                otherwise
                    app.cmbNeuralNet.Enable = 'off';
            end
        end

        % Button pushed function: btnCopyObjects
        function btnCopyObjectsButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Duplicating objects...','Indeterminate', 'on');
            
            newName = [app.Dots.Name ' (copy)'];
            app.Dots.Name = newName;
            app.Dots.UID  = generateUID;
            saveObjects(app.Dots);
            app.RefreshValidObjects;
            app.cmbObjsDetected.Value = app.Dots.UID;
            app.cmbObjsDetectedValueChanged;
            
            close(dlg);
        end

        % Button pushed function: btnReportBug
        function btnReportBugButtonPushed(app, event)
            web('https://github.com/lucadellasantina/ObjectFinder/issues', '-browser');
        end

        % Button pushed function: btnHomePage
        function btnHomePageButtonPushed(app, event)
            web('https://lucadellasantina.github.io/ObjectFinder', '-browser');
        end

        % Button pushed function: btnUserManual
        function btnUserManualButtonPushed(app, event)
            web('https://github.com/lucadellasantina/ObjectFinder/wiki', '-browser');
        end

        % Button pushed function: btnCalcDiameter
        function btnCalcDiameterButtonPushed(app, event)
            if isempty(app.dlgCalc) || ~isprop(app.dlgCalc,'UIFigure')
                app.dlgCalc = DiameterCalculator(app);
            end
            
            % Populate calculator with current size range in voxels
            app.dlgCalc.MinSizeVox.Value = app.convertDiameterToVoxels(app.MinSizeXY.Value);
            app.dlgCalc.MaxSizeVox.Value = app.convertDiameterToVoxels(app.MaxSizeXY.Value);
            
            app.dlgCalc.UIFigure.Visible = 'on';
        end

        % Value changed function: BlockSearch
        function BlockSearchValueChanged(app, event)
            if app.BlockSearch.Value
                app.LocalNoise.Enable = 'on';
                app.LocalNoise.Value  = true;
            else
                app.LocalNoise.Value = false;
                app.LocalNoise.Enable = 'off';
            end
        end

        % Value changed function: BatchBlockSearch
        function BatchBlockSearchValueChanged(app, event)
            if app.BatchBlockSearch.Value
                app.BatchLocalNoise.Enable = 'on';
                app.BatchLocalNoise.Value  = true;
            else
                app.BatchLocalNoise.Enable = 'off';
                app.BatchLocalNoise.Value  = false;
            end
        end

        % Button pushed function: btnPurgeInvalid
        function btnPurgeInvalidButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            
            switch uiconfirm(app.UIFigure, 'Permanently delete invalid objects?','Delete objects','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    % Discard all invalid objects and remove them from disk
                    dlg = uiprogressdlg(app.UIFigure,'Title','Pruning invalid objects...','Indeterminate', 'on');
                    
                    app.Dots = getFilteredObjects(app.Dots, app.Dots.Filter);
                    saveObjects(app.Dots);
                    
                    app.RefreshValidObjects;
                    app.cmbObjsDetected.Value = app.Dots.UID;
                    app.cmbObjsDetectedValueChanged;
                    close(dlg);
            end
        end

        % Button pushed function: btnBatchAddMultiple
        function btnBatchAddMultipleButtonPushed(app, event)
            rootDir = uigetdir(pwd , 'Select root folder');
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            dlg = uiprogressdlg(app.UIFigure,'Title','Recursively adding folders...','Value',0, 'Cancelable', 'on');
            
            if ~rootDir
                close(dlg);
                return
            end
            
            % List recursively all subfolders of root folder
            cd(rootDir);
            dirs = dir('**/*.*');
            dirs = dirs([dirs.isdir] & strcmp({dirs.name},'.'));
            
            % Keep only valid objectfinder project folders
            for d = numel(dirs):-1:1
                if ~isfolder([dirs(d).folder filesep dirs(d).name filesep 'I'])
                    dirs(d) = [];
                end
            end
            
            % Add folders to batch, converting data if from previous version
            for d = 1:numel(dirs)
                dlg.Message = ['Adding folder ' num2str(d) '/' num2str(numel(dirs)) ' : ' dirs(d).name];
                dlg.Value = d/numel(dirs);
                dlg.Cancelable = 'on';
                
                cd([dirs(d).folder filesep dirs(d).name]);
                
                % Force folder conversion if in old format
                if exist('find','dir')
                    convertObjectFinderDataV3toV4;
                    convertObjectFinderDataV4toV5;
                    convertObjectFinderDataV5toV7;
                    convertObjectFinderDataV7toV8;
                elseif exist('Density.mat','file')
                    convertObjectFinderDataV4toV5;
                    convertObjectFinderDataV5toV7;
                    convertObjectFinderDataV7toV8;
                elseif ~exist('objects','dir') && exist('Dots.mat','file')
                    convertObjectFinderDataV5toV7;
                    convertObjectFinderDataV7toV8;
                elseif exist('Skel.mat','file') || isfolder([pwd filesep 'data']) || isfolder([pwd filesep 'images'])
                    convertObjectFinderDataV7toV8;
                end
                
                % Add current subfolder to the processing list
                if isempty(app.lstBatch.Items)
                    app.lstBatch.Items = {dirs(d).folder};
                else
                    app.lstBatch.Items{end+1} = dirs(d).folder;
                end
                
                % Stop adding folders if user pressed the stop button
                if dlg.CancelRequested
                    close(dlg);
                    return
                end
            end
            
            app.RefreshAutomateTab;
            cd(rootDir);
            app.lstBatchValueChanged;
            close(dlg);
        end

        % Value changed function: AutoColoMaskOrObjs
        function AutoColoMaskOrObjsValueChanged(app, event)
            app.RefreshAutomateTab;
        end

        % Value changed function: chkAutoColoAll
        function chkAutoColoAllValueChanged(app, event)
            if app.chkAutoColoAll.Value
                app.AutoColoRefObjs.Enable = 'off';
            else
                app.AutoColoRefObjs.Enable = 'on';
            end
        end

        % Value changed function: cmbSimTestObjects
        function cmbSimTestObjectsValueChanged(app, event)
            if ~isempty(app.cmbSimTestObjects.Value)
                Objs = loadObjects(app.cmbSimTestObjects.Value, {'Filter'});
                app.SimTestHowManyObjs.Value = numel(find(Objs.Filter.passF));
            end
        end

        % Button pushed function: btnSimScramble
        function btnSimScrambleButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Simulating rearrangement...','Indeterminate', 'on');
            
            srcDots = loadObjects(app.cmbSimTestObjects.Value);
            
            % Initialize a structure containing data of the simulation
            Simu             = struct;
            Simu.Name        = [srcDots.Name ' ' num2str(app.cmbSimParameter.Value) ' after randomizing ' num2str(app.SimTestHowManyObjs.Value) ' items ' num2str(app.SimIterations.Value) ' times'];
            Simu.Type        = 'Scramble';
            Simu.ObjUID      = srcDots.UID;
            Simu.ObjName     = srcDots.Name;
            Simu.ObjNum      = app.SimTestHowManyObjs.Value;
            Simu.Iterations  = app.SimIterations.Value;
            Simu.Parameter   = app.cmbSimParameter.Value;
            Simu.Statistic   = app.cmbSimStatistic.Value;
            Simu.ObjValue    = 0;
            Simu.SimValues   = zeros(app.SimIterations.Value,1);
            
            switch app.cmbSimParameter.Value
                case 'Position'
                    ObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                    NN           = app.calcNNDistance(ObjTest, ObjTest, false);
                    Simu.ObjValue = mean(NN.Dist);
                    MaxInd  = ObjTest.ImSize(1)*ObjTest.ImSize(2)*ObjTest.ImSize(3);
                    
                    % Randomly sample user-selected number of objects (RndObjs) and calculate NN distance for # iterations
                    % Generate randomized positions (as linear index) within image stack and assign to randomized objects
                    for i = 1:app.SimIterations.Value
                        ObjRnd  = ObjTest;
                        RndObjs = datasample(1:ObjTest.Num, Simu.ObjNum, 'Replace', false); % Indexes of randomly choosen objects
                        RndInds = datasample(1:MaxInd, Simu.ObjNum, 'Replace', false);      % Sample without replacement because same position in the image cannot be shared by multiple objects
                        for j = 1:numel(RndObjs)
                            ObjRnd.Vox(RndObjs(j)).ind = RndInds(j);
                            [ObjRnd.Pos(RndObjs(j),1), ObjRnd.Pos(RndObjs(j),2), ObjRnd.Pos(RndObjs(j),3)] = ind2sub(ObjRnd.ImSize, ObjRnd.Vox(RndObjs(j)).ind);
                        end
                        NN = app.calcNNDistance(ObjRnd, ObjRnd, false);
                        mean(NN.Dist)
                        Simu.SimValues(i) = mean(NN.Dist);
                    end
                    
                case 'Size'
                    ObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                    Simu.ObjValue = mean(ObjTest.Vol);
                    MinVal       = min(ObjTest.Vol);
                    MaxVal       = max(ObjTest.Vol);
                    
                    % Randomize objects size and calculate their average size for # iterations
                    for i = 1:app.SimIterations.Value
                        ObjRnd = ObjTest;
                        RndObjs = datasample(1:ObjTest.Num, Simu.ObjNum, 'Replace', false); % Indexes of randomly choosen objects
                        RndVols = datasample(MinVal:MaxVal, Simu.ObjNum, 'Replace', true);  % Sample with replacement because same volume's value can occurr in multiple objects
                        for j = 1:numel(RndObjs)
                            ObjRnd.Vol(RndObjs(j)) = RndVols(j);
                        end
                        Simu.SimValues(i) = mean(ObjRnd.Vol);
                    end
            end
            
            % Store the new simulation
            Simu.UID  = generateUID;
            saveSimulation(Simu);
            app.RefreshSimulations;
            close(dlg);
        end

        % Value changed function: cmbSimParameter
        function cmbSimParameterValueChanged(app, event)
            switch app.cmbSimParameter.Value
                case 'Position'
                    app.cmbSimStatistic.Items = {'Nearest Neighbor Distance'};
                    app.cmbSimTestObjects2.Enable   = 'off';
                    app.cmbSimTest2type.Enable      = 'off';
                    app.SimTestHowManyObjs.Enable   = 'on';
                    app.SimIterations.Enable        = 'on';
                    app.btnSimScramble.Enable       = 'on';
                    app.btnSimAdd.Enable            = 'on';
                    app.btnSimRemove.Enable         = 'on';
                    app.btnSimSimulate.Enable       = 'off';
                case 'Size'
                    app.cmbSimStatistic.Items = {'Average size'};
                    app.cmbSimTestObjects2.Enable   = 'off';
                    app.cmbSimTest2type.Enable      = 'off';
                    app.SimTestHowManyObjs.Enable   = 'on';
                    app.SimIterations.Enable        = 'on';
                    app.btnSimScramble.Enable       = 'on';
                    app.btnSimAdd.Enable            = 'on';
                    app.btnSimRemove.Enable         = 'on';
                    app.btnSimSimulate.Enable       = 'off';
                case 'Overlap'
                    app.cmbSimStatistic.Items = {'Colocalization Rate'};
                    app.cmbSimTestObjects2.Enable   = 'on';
                    app.cmbSimTest2type.Enable      = 'on';
                    app.SimTestHowManyObjs.Enable   = 'off';
                    app.SimIterations.Enable        = 'off';
                    app.btnSimScramble.Enable       = 'off';
                    app.btnSimAdd.Enable            = 'off';
                    app.btnSimRemove.Enable         = 'off';
                    app.btnSimSimulate.Enable       = 'on';
            end
        end

        % Button pushed function: btnSimPlotDist
        function btnSimPlotDistButtonPushed(app, event)
            switch app.Sim.Type
                case 'Percentage'
                    % Plot colocalization rate as a function of overlap threshold
                    figure('Name', 'Simulation', 'units', 'normalized');
                    scatter(1:100, app.Sim.SimValues*100);
                    xlim([0 100]);
                    ylim([0 100]);
                    box off;
                    xlabel([app.Sim.Parameter ' (%)']);
                    ylabel([app.Sim.Statistic ' (%)']);
                    title([app.Sim.ObjName ' -> ' app.Sim.Obj2Name ' distribution'], 'Interpreter', 'none');
                    
                    % Export individual data as Excel table inside the results folder
                    if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                    FileName    = [pwd filesep 'results' filesep app.Sim.Name '_simulated_rawdata.xls'];
                    Threshold   = (0:numel(app.Sim.SimValues)-1)';  % Number of object on first column
                    ColocRate    = app.Sim.SimValues; % Volume of each object on second column
                    TableData   = table(Threshold, ColocRate);
                    writetable(TableData, FileName);
                    
                otherwise
                    % Fit data with selected distribution type and plot the frequency distribution
                    figure('Name', 'Smulation', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                    subplot(1,2,1);
                    h = histfit(app.Sim.SimValues, [], app.ReportDistFitType.Value);
                    h(1).FaceColor  = [.8 .8 1]; % violet histogram
                    hold on;
                    line([app.Sim.ObjValue app.Sim.ObjValue], [0 max(get(h(1),'ydata'))]);
                    xlabel(app.Sim.Statistic);
                    ylabel('Frequency');
                    legend({['Simulation (' num2str(app.Sim.Iterations) ' iter)'], [app.cmbSimFittingDist.Value ' distribution'], 'Empiric data'});
                    title([app.Sim.Statistic ' distribution']);
                    
                    % Retrieve frequency data from plot
                    Bins    = get(h(1),'xdata')'; % Bins on the frequency distribution histograms
                    Freq    = get(h(1),'ydata')'; % Frequency of data in each bin
                    FitBins = get(h(2),'xdata')'; % Bins for the fitted distribution
                    FitFreq = get(h(2),'ydata')'; % Fitted values
                    
                    % Plot the probability distribution
                    subplot(1,2,2);
                    histogram(app.Sim.SimValues,numel(Bins),'Normalization','probability');
                    box off;
                    xlabel(app.Sim.Statistic);
                    ylabel('Probability');
                    title([app.Sim.Statistic ' distribution']);
                    
                    % Export individual data as Excel table inside the results folder
                    if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                    FileName    = [pwd filesep 'results' filesep app.Sim.Name '_simulated_rawdata.xls'];
                    Object      = (1:numel(app.Sim.SimValues))';     % Number of object on first column
                    Value       = app.Sim.SimValues; % Volume of each object on second column
                    TableData   = table(Object,Value);
                    writetable(TableData, FileName);
                    
                    % Export distribution data as Excel table inside the results folder
                    FileName    = [pwd filesep 'results' filesep app.Sim.ObjName '_simulated_distribution.xls'];
                    TableData   = table(Bins, Freq);
                    writetable(TableData, FileName);
                    
                    FileName    = [pwd filesep 'results' filesep app.Sim.ObjName '_simulated_distribution_fit_' app.cmbSimFittingDist.Value '.xls'];
                    TableData   = table(FitBins, FitFreq);
                    writetable(TableData, FileName);
            end
        end

        % Button pushed function: btnSimListClear
        function btnSimListClearButtonPushed(app, event)
            switch questdlg('Do you really want to permanently remove all simulations?','Delete items', 'No', 'Yes', 'No')
                case 'Yes'
                    app.Sim = [];
                    delete([pwd filesep 'simulations' filesep '*.mat']);
                    app.RefreshSimulations;
            end
        end

        % Button pushed function: btnSimListRemove
        function btnSimListRemoveButtonPushed(app, event)
            idx = find(strcmp(app.lstSimObjs.Items, app.lstSimObjs.Value),1);
            if idx == 0, return; end
            
            switch questdlg('Do you really want to permanently remove selected simulation?','Delete items', 'No', 'Yes', 'No')
                case 'Yes'
                    delete([pwd filesep 'simulations' filesep app.Sim.UID '.mat']);
                    app.Sim = [];
                    app.RefreshSimulations;
            end
        end

        % Button pushed function: btnSimTest
        function btnSimTestButtonPushed(app, event)
            switch app.cmbSimNull.Value
                case 'Empirical value is drawn from sampled random distribution'
                    % Place zero as the distribution mean value
                    RndVal = app.Sim.SimValues - mean(app.Sim.SimValues);
                    EmpVal = app.Sim.ObjValue  - mean(app.Sim.SimValues);
                    
                    % p = fraction of random values are bigger than empirical value
                    if app.chkSimTestTwoTailed.Value
                        p = numel(find(abs(RndVal) > abs(EmpVal))) / app.Sim.Iterations;
                    else
                        p = numel(find(RndVal > EmpVal)) / app.Sim.Iterations;
                    end
                    
                    % Report the result and p-value in a message box
                    Precision = 1/app.Sim.Iterations;
                    Decimals = floor(log10(app.Sim.Iterations));
                    
                    if p < app.chkSimTestTwoTailed.Value
                        TestResult = 'Null hypothesis is rejected.';
                    else
                        TestResult = 'Null hypothesis cannot be rejected.';
                    end
                    msgbox({TestResult; ['p = ' sprintf(['%0.' num2str(Decimals) 'f'],p) ' (precision = ' num2str(Precision) ')']}, 'Test result');
                    
                case 'Empirical value is drawn from normal distribution fitting data'
                    % Place zero as the distribution mean value
                    RndVal = app.Sim.SimValues - mean(app.Sim.SimValues);
                    EmpVal = app.Sim.ObjValue  - mean(app.Sim.SimValues);
                    PD      = fitdist(RndVal,'normal');
                    
                    % p = fraction of fitted distribution (from cumulative density function, cdf) with values bigger than the empirical value
                    if app.chkSimTestTwoTailed.Value
                        EmpCDF    = cdf(PD, abs(EmpVal)); % Where empirical value sits on the cdf
                        OppEmpCDF = cdf(PD, -abs(EmpVal)); % Where the opposite value sits on the cdf
                        p = OppEmpCDF + (1 - EmpCDF);
                    else
                        EmpCDF = cdf(PD, EmpVal); % Calculate where the empirical value sits on the cumulative distribution function
                        if EmpVal < 0
                            p = EmpCDF;
                        else
                            p = 1 - EmpCDF;
                        end
                    end
                    
                    % Report the result and p-value in a message box
                    Precision = 1/numel(cdf(PD, RndVal));
                    Decimals = floor(log10(numel(cdf(PD, RndVal))));
                    if p < app.chkSimTestTwoTailed.Value
                        TestResult = 'Null hypothesis is rejected.';
                    else
                        TestResult = 'Null hypothesis cannot be rejected.';
                    end
                    msgbox({TestResult; ['p = ' sprintf(['%0.' num2str(Decimals) 'f'],p) ' (precision = ' num2str(Precision) ')']}, 'Test result');
            end
            
        end

        % Button pushed function: btnSimRemove
        function btnSimRemoveButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Simulating random removal...','Indeterminate', 'on');
            
            srcDots = loadObjects(app.cmbSimTestObjects.Value);
            
            % Initialize a structure containing data of the simulation
            Simu             = struct;
            Simu.Name        = [srcDots.Name  ' ' num2str(app.cmbSimParameter.Value) ' after removing ' num2str(app.SimTestHowManyObjs.Value) ' items ' num2str(app.SimIterations.Value) ' times'];
            Simu.Type        = 'Scramble';
            Simu.ObjIdx      = idx;
            Simu.ObjName     = srcDots.Name;
            Simu.ObjNum      = app.SimTestHowManyObjs.Value;
            Simu.Iterations  = app.SimIterations.Value;
            Simu.Parameter   = app.cmbSimParameter.Value;
            Simu.Statistic   = app.cmbSimStatistic.Value;
            Simu.ObjValue    = 0;
            Simu.SimValues   = zeros(app.SimIterations.Value,1);
            
            switch app.cmbSimParameter.Value
                case 'Position'
                    ObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                    NN           = app.calcNNDistance(ObjTest, ObjTest, false);
                    Simu.ObjValue = mean(NN.Dist);
                    
                    % Sample and remove selected number of objects without replacement (a given object can only be sampled once)
                    for i = 1:app.SimIterations.Value
                        RndFilter = ObjTest.Filter;
                        RndFilter.passF(datasample(1:ObjTest.Num, Simu.ObjNum, 'Replace', false)) = 0;
                        ObjRnd = getFilteredObjects(ObjTest, RndFilter);
                        NN = app.calcNNDistance(ObjRnd, ObjRnd, false);
                        Simu.SimValues(i) = mean(NN.Dist);
                    end
                    
                case 'Size'
                    ObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                    Simu.ObjValue = mean(ObjTest.Vol);
                    
                    % Sample and Remove selected number of objects without replacement (a given object can only be sampled once)
                    for i = 1:app.SimIterations.Value
                        RndFilter = ObjTest.Filter;
                        RndFilter.passF(datasample(1:ObjTest.Num, Simu.ObjNum, 'Replace', false)) = 0;
                        ObjRnd = getFilteredObjects(ObjTest, RndFilter);
                        Simu.SimValues(i) = mean(ObjRnd.Vol);
                    end
            end
            
            % Store the new simulation
            Simu.UID  = generateUID;
            saveSimulation(Simu);
            app.RefreshSimulations;
            close(dlg);
        end

        % Button pushed function: btnColocalizeEngulf
        function btnColocalizeEngulfButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading objects...','Indeterminate', 'on');
            
            srcObjs   = loadObjects(app.lstColoObjects.Value{1});
            dstObjs   = loadObjects(app.lstColoObjects.Value{2});
            dlg.Title = ['Detecting ' dstObjs.Name ' engufed by ' srcObjs.Name ' ...'];
            
            switch app.ColoSwitchVoxPerc.Value
                case 'Vox'
                    NumVoxOverlap  = app.ColoSharedVoxNum.Value;
                    NumPercOverlap = 0;
                case '%'
                    NumVoxOverlap  = 0;
                    NumPercOverlap = app.ColoSharedVoxNum.Value;
            end
            WithinDist = app.ColoThresholdDist.Value;
            
            % Count how many Dots(idx_dst) are engulfed by each Dots(idx_src)
            ColocAuto = colocAutoEngulf(srcObjs, dstObjs, NumVoxOverlap, NumPercOverlap, WithinDist, app.chkColoCenterOverlap.Value);
            
            if isempty(fieldnames(srcObjs.Coloc))
                srcObjs.Coloc = ColocAuto;
            else
                % Check if we need to replace a previously done analysis with same objects
                FoundColoc  = false;
                for i=1:numel(srcObjs.Coloc)
                    if strcmp(srcObjs.Coloc(i).Source, ColocAuto.Source) && strcmp(srcObjs.Coloc(i).Fish1, ColocAuto.Fish1)
                        srcObjs.Coloc(i) = ColocAuto;
                        FoundColoc = true;
                        break
                    end
                end
                
                % If nothing to replace, just append to the list
                if ~FoundColoc
                    srcObjs.Coloc(end+1) = ColocAuto; % Coloc is saved later
                end
            end
            saveObjects(srcObjs, 'Coloc');
            
            % If selected by user, also save engulfing objects as a new set
            if app.chkColoSaveAsNewObjects.Value
                srcColoObjs = srcObjs;
                srcColoObjs.UID = generateUID;
                srcColoObjs.Name = [srcObjs.Name ' engulfing ' ColocAuto.Fish1];
                srcColoObjs.Filter.passF = logical(ColocAuto.ColocFlag == 1);
                srcColoObjs.Coloc = struct;
                srcColoObjs = app.calcObjDensity(srcColoObjs);
                
                saveObjects(srcColoObjs);
                app.RefreshValidObjects;
            end
            
            % If selected by user, also save NOT engulfing objects as a new set
            if app.chkNotColoSaveAsNewObjects.Value
                srcNonColoObjs = srcObjs;
                srcNonColoObjs.UID = generateUID;
                srcNonColoObjs.Name = [srcObjs.Name ' not engulfing ' ColocAuto.Fish1];
                srcNonColoObjs.Filter.passF = logical(ColocAuto.ColocFlag == 0);
                srcNonColoObjs.Coloc = struct;
                srcNonColoObjs = app.calcObjDensity(srcNonColoObjs);
                
                saveObjects(srcNonColoObjs);
                app.RefreshValidObjects;
            end
            
            app.RefreshColoDone;
            close(dlg);
        end

        % Value changed function: lstSimObjs
        function lstSimObjsValueChanged(app, event)
            if ~isempty(app.lstSimObjs.Value)
                app.btnSimTest.Enable       = 'on';
                app.btnSimPlotDist.Enable   = 'on';
                app.Sim = loadSimulation(app.lstSimObjs.Value);
            else
                app.btnSimTest.Enable       = 'off';
                app.btnSimPlotDist.Enable   = 'off';
                app.Sim = [];
            end
            
        end

        % Button pushed function: btnSimAdd
        function btnSimAddButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Simulating random addition...','Indeterminate', 'on');
            
            srcDots = loadObjects(app.cmbSimTestObjects.Value);
            
            % Initialize a structure containing data of the simulation
            Simu             = struct;
            Simu.Name        = [srcDots.Name  ' ' num2str(app.cmbSimParameter.Value) ' after removing ' num2str(app.SimTestHowManyObjs.Value) ' items ' num2str(app.SimIterations.Value) ' times'];
            Simu.Type        = 'Scramble';
            Simu.ObjIdx      = idx;
            Simu.ObjName     = srcDots.Name;
            Simu.ObjNum      = app.SimTestHowManyObjs.Value;
            Simu.Iterations  = app.SimIterations.Value;
            Simu.Parameter   = app.cmbSimParameter.Value;
            Simu.Statistic   = app.cmbSimStatistic.Value;
            Simu.ObjValue    = 0;
            Simu.SimValues   = zeros(app.SimIterations.Value,1);
            
            switch app.cmbSimParameter.Value
                case 'Position'
                    ObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                    NN           = app.calcNNDistance(ObjTest, ObjTest, false);
                    Simu.ObjValue = mean(NN.Dist);
                    
                    % Add user-selected number of objects (RndObjs) and calculate NN distance for # iterations
                    % Generate randomized positions (as linear index) within image stack and assign to randomized objects
                    MaxInd  = ObjTest.ImSize(1)*ObjTest.ImSize(2)*ObjTest.ImSize(3);
                    for i = 1:app.SimIterations.Value
                        ObjRnd  = ObjTest;
                        RndInds = datasample(1:MaxInd, Simu.ObjNum, 'Replace', false);      % Sample without replacement because same position in the image cannot be shared by multiple objects
                        for j = 1:Simu.ObjNum
                            ObjRnd.Vox(end+1).ind = RndInds(j);
                            [RndPos(1,1), RndPos(1,2), RndPos(1,3)] = ind2sub(ObjRnd.ImSize, ObjRnd.Vox(RndObjs(j)).ind);
                            ObjRnd.Pos(end+1,:) = RndPos;
                            ObjRnd.Filter.passF(end+1) = true;
                        end
                        NN = app.calcNNDistance(ObjRnd, ObjRnd, false);
                        mean(NN.Dist)
                        Simu.SimValues(i) = mean(NN.Dist);
                    end
                    
                case 'Size'
                    ObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                    Simu.ObjValue = mean(ObjTest.Vol);
                    MinVal       = min(ObjTest.Vol);
                    MaxVal       = max(ObjTest.Vol);
                    
                    % Randomize objects size and calculate their average size for # iterations
                    for i = 1:app.SimIterations.Value
                        ObjRnd = ObjTest;
                        RndVols = datasample(MinVal:MaxVal, Simu.ObjNum, 'Replace', true);  % Sample with replacement because same volume's value can occurr in multiple objects
                        for j = 1:Simu.ObjNum
                            ObjRnd.Vol(end+1) = RndVols(j);
                        end
                        Simu.SimValues(i) = mean(ObjRnd.Vol);
                    end
            end
            
            % Store the new simulation
            Simu.UID = generateUID;
            saveSimulation(Simu);
            app.RefreshSimulations;
            close(dlg);
        end

        % Button pushed function: btnTrainingAddMulti
        function btnTrainingAddMultiButtonPushed(app, event)
            rootDir = uigetdir(pwd , 'Select root folder');
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if ~rootDir
                return;
            else
                cd(rootDir);
            end
            
            app.cmbTrainingObjects.Items = {}; % Clear list of available objects
            
            dirs = dir;                 % List the content of rootDir
            dirs = dirs([dirs.isdir]);  % Keep only subfolders
            dirs(1:2) = [];             % remove first items from list ("." and "..")
            
            for d = 1:numel(dirs)
                % Enter the subfolder
                currDir = [dirs(d).folder filesep dirs(d).name];
                cd(currDir);
                
                % Make sure the experiment data format matches current ObjectFinder version, otherwise convert it automatically
                if exist('find','dir')
                    convertObjectFinderDataV3toV4;
                    convertObjectFinderDataV4toV5;
                    convertObjectFinderDataV5toV7;
                elseif exist('Density.mat','file')
                    convertObjectFinderDataV4toV5;
                    convertObjectFinderDataV5toV7;
                elseif ~exist('objects','dir') && exist('Dots.mat','file')
                    convertObjectFinderDataV5toV7;
                end
                
                if ~exist([currDir filesep 'objects'], 'dir')
                    disp(['Error: Working folder not valid for analysis ' currDir]);
                    continue
                end
                
                % Add current subfolder to the processing list
                if isempty(app.lstTraining.Items)
                    app.lstTraining.Items = {currDir};
                else
                    app.lstTraining.Items{end+1} = currDir;
                end
                
                % Populate list of available objects
                if isempty(app.cmbTrainingObjects.Items)
                    app.cmbTrainingObjects.Items  = listObjects;
                else
                    app.cmbTrainingObjects.Items = union(listObjects, app.cmbTrainingObjects.Items);
                end
            end
            
            app.lstTrainingValueChanged;
        end

        % Button pushed function: btnSimpleSettings
        function btnSimpleSettingsButtonPushed(app, event)
            if isempty(app.dlgSimpleSettings) || ~isprop(app.dlgSimpleSettings,'UIFigure')
                app.dlgSimpleSettings = SimpleSettings(app);
            end
            
            % Populate calculator with current size range in voxels
            app.dlgSimpleSettings.MinSizeVox.Value = app.convertDiameterToVoxels(app.MinSizeXY.Value);
            app.dlgSimpleSettings.MaxSizeVox.Value = app.convertDiameterToVoxels(app.MaxSizeXY.Value);
            
            app.dlgSimpleSettings.UIFigure.Visible  = 'on';
        end

        % Value changed function: chkTrainingObjectsAll
        function chkTrainingObjectsAllValueChanged(app, event)
            if app.chkTrainingObjectsAll.Value
                app.cmbTrainingObjects.Enable = 'off';
            else
                app.cmbTrainingObjects.Enable = 'on';
            end
        end

        % Value changed function: chkAutoColoExportAll
        function chkAutoColoExportAllValueChanged(app, event)
            if app.chkAutoColoExportAll.Value
                app.AutoColoExportRefObjs.Enable = 'off';
            else
                app.AutoColoExportRefObjs.Enable = 'on';
            end
        end

        % Button pushed function: btnColoDonePlotDist
        function btnColoDonePlotDistButtonPushed(app, event)
            [~, idx] = ismember(app.lstColoDone.Value, app.lstColoDone.Items);
            if idx <= 0
                return
            end
            
            srcDots = loadObjects(app.lstColoObjects.Value);
            
            if numel(app.lstColoDone.Value) == 1 && contains(srcDots.Coloc(idx).Method, 'AutoEngulf')
                Engulfed = double(srcDots.Coloc(idx).ColocFlag(srcDots.Filter.passF))';
                
                % Fit data with selected distribution type and plot the frequency distribution
                figure('Name', 'Smulation', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                try
                    h = histfit(Engulfed, max(Engulfed), 'poisson');
                catch
                    return
                end
                h(1).FaceColor  = [.8 .8 1]; % violet histogram
                xlabel('Engulfed objects');
                ylabel('Frequency');
                legend({[srcDots.Coloc(idx).Fish1 ' within ' srcDots.Coloc(idx).Source], 'Poisson distribution'}, 'interpreter', 'none');
                title('Engulfed objects distribution');
                
                % Retrieve frequency data from plot
                Bins    = get(h(1),'xdata')'; % Bins on the frequency distribution histograms
                Freq    = get(h(1),'ydata')'; % Frequency of data in each bin
                FitBins = get(h(2),'xdata')'; % Bins for the fitted distribution
                FitFreq = get(h(2),'ydata')'; % Fitted values
                
                % Plot the probability distribution
                subplot(1,2,2);
                h2 = histogram(Engulfed,numel(Bins),'Normalization','probability');
                box off;
                xlabel('Number of engulfed objects');
                ylabel('Probability');
                title('Engulfed objects distribution');
                
                % Retrieve probability data from plot
                Prob    = h2(1).Values'; % Frequency of data in each bin
                
                % Export individual data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep srcDots.Coloc(idx).Fish1 '_within_' srcDots.Coloc(idx).Source '_rawdata.xls'];
                Object      = (1:numel(Engulfed))';     % Number of object on first column
                Value       = Engulfed; % Volume of each object on second column
                TableData   = table(Object,Value);
                try
                    writetable(TableData, FileName);
                catch
                    writetable(TableData, [pwd filesep 'results' filesep 'Engulfed_distribution_rawdata.xls']);
                end
                
                % Export distribution data as Excel table inside the results folder
                FileName    = [pwd filesep 'results' filesep srcDots.Coloc(idx).Fish1 '_within_' srcDots.Coloc(idx).Source '_distribution.xls'];
                TableData   = table(Bins, Freq, Prob);
                try
                    writetable(TableData, FileName);
                catch
                    writetable(TableData, [pwd filesep 'results' filesep 'Engulfed_distribution.xls']);
                end
                
                FileName    = [pwd filesep 'results' filesep srcDots.Coloc(idx).Fish1 '_within_' srcDots.Coloc(idx).Source '_distribution_fit_poisson.xls'];
                TableData   = table(FitBins, FitFreq);
                try
                    writetable(TableData, FileName);
                catch
                    writetable(TableData, [pwd filesep 'results' filesep 'Engulfed_distribution_fit_Poisson.xls']);
                end
                
            elseif numel(app.lstColoDone.Value) == 1 && ~contains(srcDots.Coloc(idx).Method, 'AutoEngulf')
                
                % Retrieve position of colocalized and non-colocalized objects
                Name1 = srcDots.Coloc(idx).Fish1;
                Colo1idx    = intersect(find(srcDots.Coloc(idx).ColocFlag == 1), find(srcDots.Filter.passF));
                Colo1PosXY  = zeros(numel(Colo1idx), 2);
                Colo1PosYZ  = zeros(numel(Colo1idx), 2);
                for i = 1:numel(Colo1idx)
                    Colo1PosXY(i,:) = srcDots.Pos(Colo1idx(i), 1:2);
                    Colo1PosYZ(i,:) = srcDots.Pos(Colo1idx(i), 2:3);
                end
                
                NonColo1idx = intersect(find(srcDots.Coloc(idx).ColocFlag == 2), find(srcDots.Filter.passF));
                NonColo1PosXY = zeros(numel(NonColo1idx), 2);
                NonColo1PosYZ = zeros(numel(NonColo1idx), 2);
                for i = 1:numel(NonColo1idx)
                    NonColo1PosXY(i,:) = srcDots.Pos(NonColo1idx(i), 1:2);
                    NonColo1PosYZ(i,:) = srcDots.Pos(NonColo1idx(i), 2:3);
                end
                
                % Plot position of colocalized objects in red, not-colocalized in blue
                figure('units', 'pixels', 'position', [0 0 srcDots.Settings.ImInfo.xNumVox srcDots.Settings.ImInfo.yNumVox]);
                t = tiledlayout(1,2,'TileSpacing','none', 'Padding','normal');
                title(t,[srcDots.Name ' colocalization with ' Name1]);
                nexttile;
                hold on;
                scatter(NonColo1PosXY(:,1), NonColo1PosXY(:,2), 50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0.5 0.5 0.5]);
                scatter(Colo1PosXY(:,1),  Colo1PosXY(:,2),  50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0 1 0]);
                xlim([0 srcDots.Settings.ImInfo.xNumVox]);
                ylim([0 srcDots.Settings.ImInfo.yNumVox]);
                pbaspect([srcDots.Settings.ImInfo.yNumVox srcDots.Settings.ImInfo.yNumVox 1]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                title('En-face view');
                
                nexttile;
                hold on;
                scatter(NonColo1PosYZ(:,1), NonColo1PosYZ(:,2), 50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0.5 0.5 0.5]);
                scatter(Colo1PosYZ(:,1),  Colo1PosYZ(:,2),  50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0 1 0]);
                xlim([0 srcDots.Settings.ImInfo.yNumVox]);
                ylim([0 srcDots.Settings.ImInfo.zNumVox]);
                pbaspect([srcDots.Settings.ImInfo.yNumVox srcDots.Settings.ImInfo.yNumVox 1]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                title('Side view');
                legend({'Non colocalized ', ['Colocalized with ' Name1]});
                
            elseif numel(app.lstColoDone.Value) == 2
                
                % Retrieve position of colocalized and non-colocalized validated objects
                Name1 = srcDots.Coloc(idx(1)).Fish1;
                Name2 = srcDots.Coloc(idx(2)).Fish1;
                Colo1idx    = intersect(find(srcDots.Coloc(idx(1)).ColocFlag == 1), find(srcDots.Filter.passF));
                NonColo1idx = intersect(find(srcDots.Coloc(idx(1)).ColocFlag == 2), find(srcDots.Filter.passF));
                Colo2idx    = intersect(find(srcDots.Coloc(idx(2)).ColocFlag == 1), find(srcDots.Filter.passF));
                NonColo2idx = intersect(find(srcDots.Coloc(idx(2)).ColocFlag == 2), find(srcDots.Filter.passF));
                
                % Colocalized with both set#1 and #2
                Colo12idx = intersect(Colo1idx, Colo2idx);
                Colo12PosXY = zeros(numel(Colo12idx), 2);
                Colo12PosYZ = zeros(numel(Colo12idx), 2);
                for i = 1:numel(Colo12idx)
                    Colo12PosXY(i,:) = srcDots.Pos(Colo12idx(i), 1:2);
                    Colo12PosYZ(i,:) = srcDots.Pos(Colo12idx(i), 2:3);
                end
                
                % Colocalized with set#1 but not #2
                Colo1not2idx = setdiff(Colo1idx, Colo2idx);
                Colo1not2PosXY = zeros(numel(Colo1not2idx), 2);
                Colo1not2PosYZ = zeros(numel(Colo1not2idx), 2);
                for i = 1:numel(Colo1not2idx)
                    Colo1not2PosXY(i,:) = srcDots.Pos(Colo1not2idx(i), 1:2);
                    Colo1not2PosYZ(i,:) = srcDots.Pos(Colo1not2idx(i), 2:3);
                end
                
                % Colocalized with set#2 but not #1
                Colo2not1idx = setdiff(Colo2idx, Colo1idx);
                Colo2not1PosXY = zeros(numel(Colo2not1idx), 2);
                Colo2not1PosYZ = zeros(numel(Colo2not1idx), 2);
                for i = 1:numel(Colo2not1idx)
                    Colo2not1PosXY(i,:) = srcDots.Pos(Colo2not1idx(i), 1:2);
                    Colo2not1PosYZ(i,:) = srcDots.Pos(Colo2not1idx(i), 2:3);
                end
                
                % Not colocalized with either
                Colonot1not2idx = intersect(NonColo1idx, NonColo2idx);
                Colonot1not2PosXY = zeros(numel(Colonot1not2idx), 2);
                Colonot1not2PosYZ = zeros(numel(Colonot1not2idx), 2);
                for i = 1:numel(Colonot1not2idx)
                    Colonot1not2PosXY(i,:) = srcDots.Pos(Colonot1not2idx(i), 1:2);
                    Colonot1not2PosYZ(i,:) = srcDots.Pos(Colonot1not2idx(i), 2:3);
                end
                
                % Plot position of colocalized objects in red, not-colocalized in blue
                figure('units', 'pixels', 'position', [0 0 srcDots.Settings.ImInfo.xNumVox srcDots.Settings.ImInfo.yNumVox]);
                t = tiledlayout(1,2,'TileSpacing','none', 'Padding','normal');
                title(t,[srcDots.Name ' colocalization with ' Name1 ' and ' Name2]);
                nexttile;
                hold on;
                scatter(Colonot1not2PosXY(:,1), Colonot1not2PosXY(:,2), 50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0.5 0.5 0.5]);
                scatter(Colo1not2PosXY(:,1),    Colo1not2PosXY(:,2),    50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[1 0 0]);
                scatter(Colo2not1PosXY(:,1),    Colo2not1PosXY(:,2),    50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0 1 1]);
                scatter(Colo12PosXY(:,1),       Colo12PosXY(:,2),       50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[1 1 0]);
                xlim([0 srcDots.Settings.ImInfo.xNumVox]);
                ylim([0 srcDots.Settings.ImInfo.yNumVox]);
                pbaspect([srcDots.Settings.ImInfo.yNumVox srcDots.Settings.ImInfo.yNumVox 1]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                title('en-face view');
                
                nexttile;
                hold on;
                scatter(Colonot1not2PosYZ(:,1), Colonot1not2PosYZ(:,2), 50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0.5 0.5 0.5]);
                scatter(Colo1not2PosYZ(:,1),    Colo1not2PosYZ(:,2),    50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[1 0 0]);
                scatter(Colo2not1PosYZ(:,1),    Colo2not1PosYZ(:,2),    50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[0 1 1]);
                scatter(Colo12PosYZ(:,1),       Colo12PosYZ(:,2),       50, 'o', 'MarkerEdgeColor', [0 0 0], "MarkerFaceColor",[1 1 0]);
                xlim([0 srcDots.Settings.ImInfo.xNumVox]);
                ylim([0 srcDots.Settings.ImInfo.zNumVox]);
                pbaspect([srcDots.Settings.ImInfo.yNumVox srcDots.Settings.ImInfo.yNumVox 1]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                title('side-view');
                lgd = legend({'Neither', ['Only ' Name1], ['Only ' Name2], 'Both'});
                title(lgd,'Colocalized with');
                
            end
        end

        % Button pushed function: btnColoDoneSave
        function btnColoDoneSaveButtonPushed(app, event)
            if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
            FileName = [pwd filesep 'results' filesep 'Colocalization_summary.xls'];
            try
                writetable(app.tblColoDone.Data, FileName);
            catch
                msgbox('Error saving the table.', 'Save Error', 'error');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if app.TabGroup.SelectedTab == app.ReportTab
                app.ReportObjectStats;
                
            elseif app.TabGroup.SelectedTab == app.SimulateTab
                app.RefreshSimulations;
                
            elseif app.TabGroup.SelectedTab == app.ColocalizeTab
                app.RefreshColoImages;
                app.RefreshColoDone;
            end
            
        end

        % Button pushed function: btnSaveReport
        function btnSaveReportButtonPushed(app, event)
            % Write summary report data to disk
            TableData = app.tblReport.Data;
            if isempty(TableData)
                return
            end
            
            if ~isfolder([pwd filesep 'results'])
                mkdir([pwd filesep 'results']);
            end
            
            FileName    = [pwd filesep 'results' filesep app.Dots.Name '_Report_summary.xls'];
            writetable(TableData, FileName);
        end

        % Button pushed function: btnColoRefreshObjs
        function btnColoRefreshObjsButtonPushed(app, event)
            app.lstColoObjects.Value = {};
            app.RefreshColoDone;
            app.lstColoObjectsValueChanged;
            app.lstColoImagesValueChanged;
            app.btnColoRefreshObjs.Enable = 'off';
        end

        % Button pushed function: btnColoRefreshImgs
        function btnColoRefreshImgsButtonPushed(app, event)
            app.lstColoImages.Value = {};
            app.lstColoImagesValueChanged;
            app.btnColoRefreshImgs.Enable = 'off';
        end

        % Button pushed function: btnNewExperiment
        function btnNewExperimentButtonPushed(app, event)
            if isempty(app.dlgNewExperiment) || ~isprop(app.dlgNewExperiment,'UIFigure')
                app.dlgNewExperiment = NewExperiment(app);
            end
            app.dlgNewExperiment.UIFigure.Visible    = 'on';
        end

        % Value changed function: chkAutoReportAll
        function chkAutoReportAllValueChanged(app, event)
            if app.chkAutoReportAll.Value
                app.AutoReportRefObjs.Enable = 'off';
            else
                app.AutoReportRefObjs.Enable = 'on';
            end
        end

        % Value changed function: AutoReportStatistic
        function AutoReportStatisticValueChanged(app, event)
            switch app.AutoReportStatistic.Value
                case {'Nearest Neighbor Distance', 'Nearest Neighbor Overlap'}
                    % Allow user to specify a second set of objects
                    app.AutoReportDstObjs.Enable    = 'on';
                    app.AutoReportBinSize.Enable    = 'on';
                    app.AutoReportBinNum.Enable     = 'on';
                    app.chkAutoReportAllDst.Enable  = 'on';
                    app.AutoReportDepthDir.Enable   = 'off';
                case {'Volume','Major Axis Length'}
                    % Allow user to specify custom bin size and number
                    app.chkAutoReportAllDst.Value   = false;
                    app.AutoReportDstObjs.Enable    = 'off';
                    app.AutoReportBinSize.Enable    = 'on';
                    app.AutoReportBinNum.Enable     = 'on';
                    app.chkAutoReportAllDst.Enable  = 'off';
                    app.AutoReportDepthDir.Enable   = 'off';
                case {'Density by depth', 'Volume by depth'}
                    app.chkAutoReportAllDst.Value   = false;
                    app.AutoReportDstObjs.Enable    = 'off';
                    app.AutoReportBinSize.Enable    = 'off';
                    app.AutoReportBinNum.Enable     = 'on';
                    app.chkAutoReportAllDst.Enable  = 'off';
                    app.AutoReportDepthDir.Enable   = 'on';
                otherwise
                    app.chkAutoReportAllDst.Value   = false;
                    app.AutoReportDstObjs.Enable    = 'off';
                    app.AutoReportBinSize.Enable    = 'off';
                    app.AutoReportBinNum.Enable     = 'off';
                    app.chkAutoReportAllDst.Enable  = 'off';
                    app.AutoReportDepthDir.Enable   = 'off';
            end
            
        end

        % Value changed function: chkAutoReportAllDst
        function chkAutoReportAllDstValueChanged(app, event)
            if app.chkAutoReportAllDst.Value
                app.AutoReportDstObjs.Enable = 'off';
            else
                app.AutoReportDstObjs.Enable = 'on';
            end
        end

        % Value changed function: cmbSkel
        function cmbSkelValueChanged(app, event)
            if isempty(app.cmbSkel.Value)
                app.btnPlotSkeleton.Enable = 'off';
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading skeleton...','Indeterminate', 'on');
            app.Skel = loadSkel(app.cmbSkel.Value);
            if ~isfield(app.Skel.FilStats, 'SkelPathLength2Soma')
                disp('calculating path length to soma');
                app.Skel = calcSkelPathLength(app.Skel, false);
            end
            app.btnPlotSkeleton.Enable = 'on';
            close(dlg);
        end

        % Button pushed function: btnSkelRename
        function btnSkelRenameButtonPushed(app, event)
            opts.Resize = 'off';
            opts.WindowStyle = 'modal';
            opts.Interpreter = 'none';
            answer = inputdlg('New name for selected skeleton','Rename skeleton', [1 40],{app.Skel.Name},opts);
            if ~isempty(answer)
                dlg = uiprogressdlg(app.UIFigure,'Title','Renaming skeleton...','Indeterminate', 'on');
                
                app.Skel.Name = answer{:};
                saveSkel(app.Skel);
                
                app.RefreshSkeletons;
                app.cmbSkel.Value = app.Skel.UID;
                app.cmbSkelValueChanged;
                close(dlg);
            end
        end

        % Button pushed function: btnSkelDelete
        function btnSkelDeleteButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Permanently delete selected skeleton?','Delete skeleton','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting skeleton...','Indeterminate', 'on');
                    delete([pwd filesep 'skeletons' filesep app.Skel.UID '.mat']);
                    app.Skel = [];
                    app.RefreshSkeletons;
                    app.cmbSkelValueChanged;
                    close(dlg);
            end
        end

        % Button pushed function: btnSkelImport
        function btnSkelImportButtonPushed(app, event)
            [FileName, PathName] = uigetfile({'*.traces;*.mat;*.tif','Supported formats (*.traces, *.tif, *.mat)'; '*.traces','Simple Neurite Tracer (*.traces)';'*.tif','Binary Mask (*.tif)';'*.mat','ObjectFinder skeletons (*.mat)'}, 'Select skeleton', 'MultiSelect', 'off');
            
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if FileName == 0
                return
            end
            
            [~, ~, fExt] = fileparts(FileName);
            switch fExt
                case '.traces'
                    % Read Simple Neurite Skeleton if available in the I folder
                    dlg = uiprogressdlg(app.UIFigure,'Title','Converting Simple Neurite Tracer skeleton...','Indeterminate', 'on');
                    app.Skel = convertSkelSNT2ml([PathName filesep], FileName);
                    dlg.Title = 'Calculating distance to soma...';
                    app.Skel = calcSkelDistToSoma(app.Skel);
                    app.Skel.UID = generateUID;
                    app.Skel.Name = FileName;
                    saveSkel(app.Skel);
                    close(dlg);
                case '.tif'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Skeletonizing binary mask...','Indeterminate', 'on');
                    app.Skel = skeletonizeMask(FileName);
                    dlg.Title = 'Calculating distance to soma...';
                    app.Skel = calcSkelPathLength(app.Skel, false);
                    app.Skel.UID = generateUID;
                    app.Skel.Name = FileName;
                    saveSkel(app.Skel);
                    close(dlg);
            end
            app.RefreshSkeletons;
        end

        % Button pushed function: btnImgInfo
        function btnImgInfoButtonPushed(app, event)
            app.loadCurrentImage;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Retrieving image properties...','Indeterminate', 'on');
            
            info        = {'\bf\fontsize{10}Image info:\rm'};
            info{end+1} = ['Filename   : ' app.Post.Name];
            info{end+1} = ['Image size : ' num2str(size(app.Post.I,1)) 'x' num2str(size(app.Post.I,2)) 'x' num2str(size(app.Post.I,3)) ' pixels'];
            info{end+1} = ['Voxel size : ' num2str(app.Post.ImRes(1)) 'x' num2str(app.Post.ImRes(2)) 'x' num2str(app.Post.ImRes(3)) ' microns'];
            
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            msgbox(info, 'Image info', CreateStruct);
            close(dlg);
        end

        % Button pushed function: btnMaskInfo
        function btnMaskInfoButtonPushed(app, event)
            app.loadCurrentMask;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Retrieving image properties...','Indeterminate', 'on');
            
            info        = {'\bf\fontsize{10}Mask info:\rm'};
            info{end+1} = ['Filename   : ' app.Mask.Name];
            info{end+1} = ['Image size : ' num2str(size(app.Mask.I,1)) 'x' num2str(size(app.Mask.I,2)) 'x' num2str(size(app.Mask.I,3)) ' pixels'];
            info{end+1} = ['Voxel size : ' num2str(app.Mask.ImRes(1)) 'x' num2str(app.Mask.ImRes(2)) 'x' num2str(app.Mask.ImRes(3)) ' microns'];
            
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            msgbox(info, 'Image info', CreateStruct);
            close(dlg);
        end

        % Button pushed function: btnImgRender
        function btnImgRenderButtonPushed(app, event)
            app.loadCurrentImage;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Rendering volume...','Indeterminate', 'on');
            
            VoxSize = [app.Settings.ImInfo.xyum, app.Settings.ImInfo.xyum, app.Settings.ImInfo.zum];
            VoxSizeRatio = VoxSize./VoxSize(1);
            figure('NumberTitle', 'off', 'Name', app.Settings.ImInfo.PostChName);
            if isempty(app.Dots)
                volshow(app.Post.I, 'BackGroundColor', [0.1 0.1 0.1], 'ScaleFactors', VoxSizeRatio, 'Renderer', 'MaximumIntensityProjection');
            else
                Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
                ObjsMask = zeros(size(app.Post.I), 'uint8');
                for i = 1:numel(Objs.Vox)
                    ObjsMask(Objs.Vox(i).Ind) = 1;
                end
                labelvolshow(ObjsMask, app.Post.I, 'BackGroundColor', [0.15 0.15 0.15], 'ScaleFactors', VoxSizeRatio);
            end
            close(dlg);
        end

        % Button pushed function: btnMaskRender
        function btnMaskRenderButtonPushed(app, event)
            app.loadCurrentMask;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Rendering masked volume...','Indeterminate', 'on');
            
            VoxSize = [app.Settings.ImInfo.xyum, app.Settings.ImInfo.xyum, app.Settings.ImInfo.zum];
            VoxSizeRatio = VoxSize./VoxSize(1);
            figure('NumberTitle', 'off', 'Name', app.Settings.ImInfo.MaskChName);
            volshow(app.Post.I .* (app.Mask.I/max(app.Mask.I(:))), 'BackGroundColor', [0.1 0.1 0.1], 'ScaleFactors', VoxSizeRatio, 'Renderer', 'MaximumIntensityProjection');
            
            close(dlg);
        end

        % Value changed function: cmbPost
        function cmbPostValueChanged(app, event)
            if isempty(app.cmbPost.Value)
                app.Post = [];
                app.Settings.ImInfo.PostChName = '';
                return
            end
        end

        % Value changed function: cmbMask
        function cmbMaskValueChanged(app, event)
            if isempty(app.cmbMask.Value)
                app.Mask = [];
                app.Settings.ImInfo.MaskChName  = '';
                app.btnMaskInfo.Enable          = 'off';
                app.btnMaskMIP.Enable           = 'off';
                app.btnMaskRender.Enable        = 'off';
                return
            else
                app.btnMaskInfo.Enable          = 'on';
                app.btnMaskMIP.Enable           = 'on';
                app.btnMaskRender.Enable        = 'on';
            end
        end

        % Button pushed function: btnImgMIP
        function btnImgMIPButtonPushed(app, event)
            app.loadCurrentImage;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Generating maximum intensity projection...','Indeterminate', 'on');
            
            MIP = squeeze(max(app.Post.I,[],3)); % Create a MIP of each image to display
            if numel(unique(MIP)) == 2
                % MIP is a binary mask, stretch from 0 to 255
                imshow(255*(MIP/max(MIP(:))));
            else
                % MIP is from a regular 8-bit image
                imshow(MIP);
            end
            title({app.Post.Name}, 'interpreter', 'none');
            set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
            daspect([size(MIP,1) size(MIP,2) 1]); % Maintain aspect ratio of original image
            colormap gray(256);
            close(dlg);
        end

        % Button pushed function: btnMaskMIP
        function btnMaskMIPButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Plot Mask or Masked image?','Plot options','Options', {'Mask', 'Masked Image'}, 'DefaultOption',1,'CancelOption',1)
                
                case 'Masked Image'
                    app.loadCurrentImage;
                    app.loadCurrentMask;
                    dlg = uiprogressdlg(app.UIFigure,'Title','Generating maximum intensity projection...','Indeterminate', 'on');
                    MIP = squeeze(max(app.Post.I .* (app.Mask.I/max(app.Mask.I(:))),[],3)); % Create a MIP of each image to display
                    if numel(unique(MIP)) == 2
                        % MIP is a binary mask, stretch from 0 to 255
                        imshow(255*(MIP/max(MIP(:))));
                    else
                        % MIP is from a regular 8-bit image
                        imshow(MIP);
                    end
                    title({['Masked ' app.Post.Name]}, 'interpreter', 'none');
                    set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
                    daspect([size(MIP,1) size(MIP,2) 1]); % Maintain aspect ratio of original image
                    colormap gray(256);
                    close(dlg);
                    
                case 'Mask'
                    app.loadCurrentMask;
                    dlg = uiprogressdlg(app.UIFigure,'Title','Generating maximum intensity projection...','Indeterminate', 'on');
                    MIP = squeeze(max(app.Mask.I,[],3)); % Create a MIP of each image to display
                    figure;
                    imshow(255*(MIP/max(MIP(:))));       % MIP is a binary mask, stretch from 0 to 255
                    title({app.Mask.Name}, 'interpreter', 'none');
                    set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
                    daspect([size(MIP,1) size(MIP,2) 1]); % Maintain aspect ratio of original image
                    colormap gray(256);
                    close(dlg);
            end
            
        end

        % Button pushed function: btnNetCreate
        function btnNetCreateButtonPushed(app, event)
            % Ask user what size images will be used to train
            dlg = uiprogressdlg(app.UIFigure,'Title','Creating neural network...','Indeterminate', 'on');
            S.Name = 'New NeuralNet';
            S = editStruct(S, 'Settings');
            if isempty(S)
                return
            end
            
            % Create either an empty network or a pretrained network for transfer learning
            try
                switch app.cmbNetModel.Value
                    case 'AlexNet',             net = eval('alexnet');
                    case 'VGG-16',              net = eval('vgg16');
                    case 'VGG-19',              net = eval('vgg19');
                    case 'SqueezeNet',          net = eval('squeezenet');
                    case 'GoogleNet',           net = eval('googlenet');
                    case 'Inception-v3',        net = eval('inceptionv3');
                    case 'ResNet-50',           net = eval('resnet50');
                    case 'ResNet-101',          net = eval('resnet101');
                    case 'Inception-ResNet-v2', net = eval('inceptionresnetv2');
                    otherwise,                  net = [];
                end
            catch
                msgbox([app.cmbNetModel.Value ' not available, please install it using the AddOn explorer'], 'NeuralNet Error', 'error');
                return
            end
            
            % Create or adjust the net layers
            numClasses = 2;
            
            % Retrieve net layers graph
            if isa(net,'SeriesNetwork')
                layers = layerGraph(net.Layers);
            else
                layers = layerGraph(net);
            end
            
            % Check whether last learnable layer is fully connected or convolutional
            learnableLayer = layers.Layers(end-2);
            if isa(learnableLayer,'nnet.cnn.layer.FullyConnectedLayer')
                newLearnableLayer = fullyConnectedLayer(numClasses, 'Name','FF_fc', 'WeightLearnRateFactor',20, 'BiasLearnRateFactor',20);
            elseif isa(learnableLayer,'nnet.cnn.layer.Convolution2DLayer')
                newLearnableLayer = convolution2dLayer(1,numClasses, 'Name','FF_conv', 'WeightLearnRateFactor',20, 'BiasLearnRateFactor',20);
            end
            
            % Replace and connect last 3 layers with a new clasification layer based on numClasses
            layers = removeLayers(layers, {layers.Layers(end-2:end).Name});
            layers = addLayers(layers, [newLearnableLayer, softmaxLayer('Name', 'FF_softmax'), classificationLayer('Name','FF_output')]);
            layers = connectLayers(layers, layers.Layers(end-3).Name, layers.Layers(end-2).Name);
            
            NN = app.generateNeuralNet(S.Name, app.cmbNetTarget.Value, app.cmbNetType.Value, app.cmbNetModel.Value, net, layers);
            NN.Trained = false;
            dlg.Title = 'Saving network to disk...';
            saveNetwork(NN);
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNets;
            close(dlg);
        end

        % Cell selection callback: tblNeuralNet
        function tblNeuralNetCellSelection(app, event)
            % Store the row selected by user
            app.NetSelected = event.Indices;
            if isempty(app.NetSelected)
                return
            end
            
            if app.tblNeuralNet.Data{app.NetSelected(:,1),5}
                NetTrained = 'on';
            else
                NetTrained = 'off';
            end
            
            app.btnNetDelete.Enable     = 'on';
            app.btnNetClone.Enable      = 'on';
            app.btnNetRename.Enable     = 'on';
            app.btnNetImport.Enable     = 'on';
            app.btnNetExport.Enable     = 'on';
            app.btnNetInfo.Enable       = 'on';
            app.btnNetTrain.Enable      = 'on';
            app.btnNetTest.Enable       = NetTrained;
            app.cmbNetTarget.Value      = app.tblNeuralNet.Data{app.NetSelected(end,1),2};
            app.cmbNetType.Value        = app.tblNeuralNet.Data{app.NetSelected(end,1),3};
            NetModel = app.tblNeuralNet.Data{app.NetSelected(:,1),4};
            NetModel = NetModel{:};
            if find(strcmp(app.cmbNetModel.Items, NetModel))
                app.cmbNetModel.Value   = NetModel;
            else
                %app.cmbNetModel.Value   = app.cmbNetModel.Items{1};
            end
        end

        % Button pushed function: btnNetDelete
        function btnNetDeleteButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                switch uiconfirm(app.UIFigure,'Do you really want to permanently remove selected NeuralNet?','Delete NeuralNet','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                    case 'Yes'
                        dlg = uiprogressdlg(app.UIFigure,'Title','Deleting network from disk...','Indeterminate', 'on');
                        NetUIDs  = app.tblNeuralNet.Data{app.NetSelected(:,1),end};
                        for i = 1:numel(NetUIDs)
                            deleteNetwork(NetUIDs{i}); % Delete saved training file matching UID
                        end
                        app.NetSelected = [];
                        dlg.Title = 'Refreshing networks table...';
                        app.RefreshNeuralNets;
                        close(dlg);
                end
            end
        end

        % Button pushed function: btnNetInfo
        function btnNetInfoButtonPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Inspecting neural network...','Indeterminate', 'on');
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNetwork(UID);
            try
                if NN.Trained
                    NN.trainOpts
                end
                analyzeNetwork(NN.Layers);
            catch
                close(dlg);
                uiconfirm(app.UIFigure,'Error retrieving NeuralNet properties','Error','Options',{'Ok'})
            end
            close(dlg);
        end

        % Button pushed function: btnNetExport
        function btnNetExportButtonPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNetwork(UID{:});
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Exporting network to disk...','Indeterminate', 'on');
            FileName = [dstFolder filesep NN.UID '.mat'];
            save(FileName, '-struct', 'NN');
            close(dlg);
        end

        % Button pushed function: btnNetClone
        function btnNetCloneButtonPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Cloning neural network...','Indeterminate', 'on');
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNetwork(UID);
            NN.UID = generateUID;
            NN.Name = [NN.Name ' (copy)'];
            saveNetwork(NN);
            app.NetSelected = [];
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNets;
            close(dlg);
        end

        % Button pushed function: btnNetRename
        function btnNetRenameButtonPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Renaming network...','Indeterminate', 'on');
            
            % Load selected NeuralNet
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNetwork(UID, {'UID', 'Name'});
            
            % Ask user what size images will be used to train
            S.Name = NN.Name;
            S = editStruct(S, 'Change name');
            if isempty(S)
                return
            end
            NN.Name = S.Name;
            saveNetwork(NN, 'Name');
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNets;
            close(dlg);
        end

        % Button pushed function: btnNetImport
        function btnNetImportButtonPushed(app, event)
            % Choose a neural network file to import
            [srcFile, srcPath] = uigetfile('*.mat', 'Select file to import');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if srcFile == 0
                return
            end
            
            % Copy the selected file into local Training repository
            dlg = uiprogressdlg(app.UIFigure,'Title','Importing network from disk...','Indeterminate', 'on');
            dstPath = [userpath filesep 'ObjectFinder' filesep 'NeuralNets'];
            copyfile(fullfile(srcPath, srcFile), fullfile(dstPath, srcFile));
            app.RefreshNeuralNets;
            close(dlg);
            
            % TODO import other formats
            %             % Import Keras-Tensorflow
            %             [file,path] = uigetfile({'*.h5'},'Select Keras Network');
            %             net = importONNXNetwork(fullfile(path, file), 'OutputLayerType', 'classification');
            %             % Import Caffe
            %             [fileProto,pathProto] = uigetfile({'*.prototxt'},  'Select Caffe Architecture file');
            %             [fileData, pathData ] = uigetfile({'*.caffemodel'},'Select Caffe Data file');
            %             net = importCaffeNetwork(fullfile(pathProto, fileProto), fullfile(pathData, fileData));
            %             % Import ONNX
            %             [file,path] = uigetfile({'*.onnx'},'Select ONNX Network');
            %             net = importONNXNetwork(fullfile(path, file), 'OutputLayerType', 'classification');
        end

        % Button pushed function: btnNetTest
        function btnNetTestButtonPushed(app, event)
            if isempty(app.NetSelected) || isempty(app.Dots)
                return
            end
            
            % Tests the selected trained network against current objects
            switch app.cmbNetType.Value
                case 'Object detector'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
                    UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                    NN = loadNetwork(UID);
                    Predicted = validateObjectWithNeuralNet(app.Dots, NN);
                    
                    % Calculate prediction accuracy, recall, miss rate
                    GTPositives    = find(app.Dots.Filter.passF);
                    NNPositives    = find(Predicted.passF);
                    TruePositives  = intersect(NNPositives,GTPositives);
                    FalsePositives = setdiff(NNPositives,GTPositives);
                    if numel(GTPositives) > 0
                        Precision  = numel(TruePositives)  / (numel(TruePositives)+numel(FalsePositives));
                        Recall     = numel(TruePositives)  / numel(GTPositives);
                    else
                        Precision  = 0;
                        Recall     = numel(TruePositives) == 0;
                    end
                    close(dlg);
            end
            
            % Display summery of NeuralNet performance
            info        = {'\bf\fontsize{10}Image info:\rm'};
            info{end+1} = ['Objects name  : ' app.Dots.Name];
            info{end+1} = ['Objects tested: ' num2str(numel(Predicted.passF))];
            info{end+1} = ['Neural Network: ' NN.Name];
            info{end+1} = ['Precision     : ' num2str(Precision)];
            info{end+1} = ['Recall        : ' num2str(Recall)];
            
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            msgbox(info, 'Image info', CreateStruct);
            
        end

        % Button pushed function: cmbSimRename
        function cmbSimRenameButtonPushed(app, event)
            opts.Resize = 'off';
            opts.WindowStyle = 'modal';
            opts.Interpreter = 'none';
            answer = inputdlg('New name for selected simulation','Rename simulation', [1 40],{app.Sim.Name},opts);
            if ~isempty(answer)
                dlg = uiprogressdlg(app.UIFigure,'Title','Renaming simulation...','Indeterminate', 'on');
                
                app.Sim.Name = answer{:};
                saveSimulation(app.Sim, 'Name');
                
                app.RefreshSimulations;
                app.lstSimObjs.Value = app.Sim.UID;
                app.lstSimObjsValueChanged;
                close(dlg);
            end
        end

        % Button pushed function: btnSimCopy
        function btnSimCopyButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Duplicating simulation...','Indeterminate', 'on');
            
            newName = [app.Sim.Name ' (copy)'];
            app.Sim.Name = newName;
            app.Sim.UID  = generateUID;
            saveSimulation(app.Sim);
            app.RefreshSimulations;
            app.lstSimObjs.Value = app.Sim.UID;
            app.lstSimObjsValueChanged;
            
            close(dlg);
        end

        % Button pushed function: btnMaskLink
        function btnMaskLinkButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Linking mask to objects','Indeterminate', 'on');
            dlg.Message = 'Loading mask...';
            app.loadCurrentMask;
            
            % Check that size of new mask and size of objects volume match
            ImSize = [app.Dots.Settings.ImInfo.yNumVox, app.Dots.Settings.ImInfo.xNumVox, app.Dots.Settings.ImInfo.zNumVox];
            if size(app.Mask.I,1) ~= ImSize(1) || size(app.Mask.I,2) ~= ImSize(2) || size(app.Mask.I,3) ~= ImSize(3)
                close(dlg);
                msgbox('Cannot apply mask to objects because they are of different size', 'Error', "error");
                return
            end
            
            dlg.Message = 'Saving objects...';
            app.Dots.Settings.ImInfo.MaskChName = app.Mask.Name;
            saveObjects(app.Dots);
            
            close(dlg)
        end

        % Button pushed function: btnPresetCreate
        function btnPresetCreateButtonPushed(app, event)
            if ~isempty(app.cmbPreset.Value)
                switch uiconfirm(app.UIFigure, 'Overwrite current preset or create a new one?','Delete preset','Options', {'Overwrite', 'Create New'}, 'DefaultOption', 2,'CancelOption',2)
                    case 'Overwrite'
                        OldPreset       = loadPreset(app.cmbPreset.Value);
                        NewPreset       = app.generatePreset;
                        NewPreset.Name  = OldPreset.Name;
                        NewPreset.Author = OldPreset.Author;
                        NewPreset.UID   = OldPreset.UID;
                        savePreset(NewPreset);
                        return
                end
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Creating preset...','Indeterminate', 'on');
            S.Name = 'MySettings';
            S.Author = 'MyName';
            S = editStruct(S, 'Settings');
            if isempty(S)
                return
            end
            
            Preset        = app.generatePreset;
            Preset.Name   = S.Name;
            Preset.Author = S.Author;
            Preset.Date   = datetime;
            savePreset(Preset);
            app.RefreshPresets;
            close(dlg);
        end

        % Value changed function: cmbPreset
        function cmbPresetValueChanged(app, event)
            if isempty(app.cmbPreset.Value)
                app.btnPresetDelete.Enable = 'off';
                app.btnPresetExport.Enable = 'off';
                app.btnPresetRename.Enable = 'off';
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading settings...','Indeterminate', 'on');
            Preset = loadPreset(app.cmbPreset.Value);
            app.applyPreset(Preset);
            app.btnPresetDelete.Enable = 'on';
            app.btnPresetExport.Enable = 'on';
            app.btnPresetRename.Enable = 'on';
            close(dlg);
        end

        % Button pushed function: btnPresetDelete
        function btnPresetDeleteButtonPushed(app, event)
            switch uiconfirm(app.UIFigure, 'Permanently delete selected preset?','Delete preset','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Deleting preset...','Indeterminate', 'on');
                    delete([userpath filesep 'ObjectFinder' filesep 'Presets' filesep app.cmbPreset.Value '.mat']);
                    app.RefreshPresets;
                    app.cmbPresetValueChanged;
                    close(dlg);
            end
        end

        % Button pushed function: btnPresetRename
        function btnPresetRenameButtonPushed(app, event)
            Preset = loadPreset(app.cmbPreset.Value);
            S.Name = Preset.Name;
            S.Author = Preset.Author;
            S = editStruct(S);
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if isempty(S)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Renaming skeleton...','Indeterminate', 'on');
            Preset.Name = S.Name;
            Preset.Author = S.Author;
            savePreset(Preset);
            app.RefreshPresets;
            app.cmbPreset.Value = Preset.UID;
            app.cmbPresetValueChanged;
            close(dlg);
        end

        % Button pushed function: btnPresetImport
        function btnPresetImportButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Supported formats (*.mat)'; '*.mat','ObjectFinder skeletons (*.mat)'}, 'Select preset', 'MultiSelect', 'off');
            
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if fName == 0
                return
            end
            
            % Read Simple Neurite Skeleton if available in the I folder
            dlg = uiprogressdlg(app.UIFigure,'Title','Importing preset...','Indeterminate', 'on');
            dlg.Title = fName;
            copyfile([PathName filesep fName], [userpath filesep 'ObjectFinder' filesep 'Presets' filesep fName]);
            app.RefreshPresets;
            app.cmbPresetValueChanged;
            close(dlg);
        end

        % Button pushed function: btnPresetExport
        function btnPresetExportButtonPushed(app, event)
            if isempty(app.cmbPreset.Value)
                return
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Exporting preset to disk...','Indeterminate', 'on');
            srcFolder = [userpath filesep 'ObjectFinder' filesep 'Presets'];
            copyfile([srcFolder filesep app.cmbPreset.Value '.mat'], [dstFolder filesep app.cmbPreset.Value '.mat']);
            close(dlg);
            
        end

        % Button pushed function: btnSimSimulate
        function btnSimSimulateButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title',['Simulating ' app.cmbSimStatistic.Value ' at different ' app.cmbSimParameter.Value],'Indeterminate', 'on');
            
            dlg.Message     = 'Loading reference objects...';
            srcDots = loadObjects(app.cmbSimTestObjects.Value);
            
            % Initialize a structure containing data of the simulation
            Simu             = struct;
            Simu.ObjUID      = srcDots.UID;
            Simu.ObjName     = srcDots.Name;
            Simu.ObjNum      = app.SimTestHowManyObjs.Value;
            Simu.Iterations  = app.SimIterations.Value;
            Simu.Parameter   = app.cmbSimParameter.Value;
            Simu.Statistic   = app.cmbSimStatistic.Value;
            Simu.ObjValue    = 0;
            Simu.SimValues   = zeros(app.SimIterations.Value,1);
            
            switch app.cmbSimParameter.Value
                case 'Overlap'
                    switch app.cmbSimTest2type.Value
                        case 'Objects'
                            dlg.Message     = 'Loading colocalizing objects...';
                            dstDots         = loadObjects(app.cmbSimTestObjects2.Value);
                            srcObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                            dstObjTest      = getFilteredObjects(dstDots, dstDots.Filter);
                            
                            Simu.Name        = [srcDots.Name ' colocalized with ' dstDots.Name ' at multiple overlap thresholds'];
                            Simu.Type       = 'Percentage';
                            Simu.Obj2UID    = dstDots.UID;
                            Simu.Obj2Name   = dstDots.Name;
                            
                            dlg.Message     = 'Calculating nearest neighbors properties...';
                            NN              = app.calcNNDistance(srcObjTest, dstObjTest, true);
                            Simu.ObjValue   = mean(NN.VoxOverlapPerc);
                            
                            dlg.Message     = 'Measuring colocalization rate at different overlap levels';
                            Simu.SimValues  = zeros(100,1);
                            for threshold = 1:100
                                OverlappingThreshold = find(NN.VoxOverlapPerc >= threshold);
                                if isempty(OverlappingThreshold)
                                    Simu.SimValues(threshold) = 0;
                                else
                                    Simu.SimValues(threshold) = numel(OverlappingThreshold)/srcObjTest.Num;
                                end
                            end
                            
                        case 'Mask'
                            dlg.Message     = 'Loading colocalizing Mask...';
                            dstMaskName     = app.cmbSimTestObjects2.Value;
                            dstMask         = loadImage(dstMaskName);
                            srcObjTest      = getFilteredObjects(srcDots, srcDots.Filter);
                            
                            Simu.Name       = [srcDots.Name ' colocalized with ' dstMaskName ' at multiple overlap thresholds'];
                            Simu.Type       = 'Percentage';
                            Simu.Obj2Name   = dstMaskName;
                            
                            dlg.Message     = 'Measuring colocalization rate at different overlap levels';
                            dlg.Indeterminate = 'off';
                            Simu.SimValues  = zeros(100,1);
                            for threshold = 1:100
                                dlg.Value = threshold / 100;
                                ColocAuto = colocAutoMask(srcObjTest, dstMask, dstMaskName, 0, threshold, false);
                                OverlappingThreshold = find(ColocAuto.ColocFlag == 1);
                                Simu.SimValues(threshold) = numel(OverlappingThreshold)/srcObjTest.Num;
                            end
                    end
                    
            end
            saveSimulation(Simu);
            app.RefreshSimulations;
            close(dlg);
        end

        % Button pushed function: btnCreateObjMaskColored
        function btnCreateObjMaskColoredButtonPushed(app, event)
            if isempty(app.Dots)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Creating a colored mask of objects','Indeterminate', 'on');
            dlg.Message = 'Generating random colors...';
            Objs = getFilteredObjects(app.Dots, app.Dots.Filter);
            ImInfo = Objs.Settings.ImInfo;
            Ir = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
            Ig = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
            Ib = zeros(ImInfo.yNumVox, ImInfo.xNumVox, ImInfo.zNumVox, 'uint8');
            for i = 1:numel(Objs.Vox)
                Ir(Objs.Vox(i).Ind) = randi([0 255]);
                Ig(Objs.Vox(i).Ind) = randi([0 255]);
                Ib(Objs.Vox(i).Ind) = randi([0 255]);
            end
            
            % Write into a 3D RGB Tiff fle
            dlg.Message = 'Creating 4D image...';
            opts.color = true;
            opts.compression = 'no';
            opts.overwrite = true;
            I = cat(4, Ir, Ig, Ib);
            I = permute(I, [1 2 4 3]);
            
            dlg.Message = 'Saving image...';
            saveastiff(I, [pwd filesep 'I' filesep app.Dots.Name '_objects_mask_colored.tif'], opts);
            close(dlg);
        end

        % Value changed function: cmbSimTest2type
        function cmbSimTest2typeValueChanged(app, event)
            switch app.cmbSimTest2type.Value
                case 'Objects'
                    % Populate list of available objects
                    [Names, UIDs] = listObjects;
                    app.cmbSimTestObjects2.Items = Names;
                    app.cmbSimTestObjects2.ItemsData = UIDs;
                case 'Mask'
                    % Populate list of available images
                    fileList = dir([pwd filesep 'I' filesep '*.tif']);
                    app.cmbSimTestObjects2.ItemsData = [];
                    app.cmbSimTestObjects2.Items = {fileList.name};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Object Finder';
            app.UIFigure.Icon = 'ObjectFinder.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DetectTab = uitab(app.TabGroup);
            app.DetectTab.Title = 'Detect';
            app.btnOpenExp = uibutton(app.DetectTab, 'push');
            app.btnOpenExp.ButtonPushedFcn = createCallbackFcn(app, @btnOpenExpPushed, true);
            app.btnOpenExp.Icon = '48px-HomeFolder.png';
            app.btnOpenExp.Tooltip = {'Open the main folder of an existing image analysis project'};
            app.btnOpenExp.Text = {'Open'; 'Project'};
            app.btnFindObjects = uibutton(app.DetectTab, 'push');
            app.btnFindObjects.ButtonPushedFcn = createCallbackFcn(app, @btnFindObjectsPushed, true);
            app.btnFindObjects.Icon = '48px-FindObjects.png';
            app.btnFindObjects.Enable = 'off';
            app.btnFindObjects.Tooltip = {'Automtically search for objects'};
            app.btnFindObjects.Text = {'Detect'; 'Objects'};
            app.btnValidateObjects = uibutton(app.DetectTab, 'push');
            app.btnValidateObjects.ButtonPushedFcn = createCallbackFcn(app, @btnValidateObjectsPushed, true);
            app.btnValidateObjects.Icon = '48px-Inspect.png';
            app.btnValidateObjects.Enable = 'off';
            app.btnValidateObjects.Tooltip = {'Validate current objects using selected tool'};
            app.btnValidateObjects.Text = {'Validate'; 'Objects'};
            app.ObjectDetectionSettingsPanel = uipanel(app.DetectTab);
            app.ObjectDetectionSettingsPanel.Title = 'Object Detection Settings';
            app.MaximumLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.MaximumLabel.Text = 'Maximum:';
            app.MaxSizeXY = uieditfield(app.ObjectDetectionSettingsPanel, 'numeric');
            app.MaxSizeXY.Limits = [0 Inf];
            app.MaxSizeXY.Tooltip = {'Maximum diameter of objects  expected in the image'};
            app.MaxSizeXY.Value = 5;
            app.Watershed = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.Watershed.Tooltip = {'Split objects with multiple intensity peaks using watershed segmentation'};
            app.Watershed.Text = 'Watershed';
            app.Watershed.Value = true;
            app.MinimumLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.MinimumLabel.Text = 'Minimum:';
            app.MinSizeXY = uieditfield(app.ObjectDetectionSettingsPanel, 'numeric');
            app.MinSizeXY.Tooltip = {'Minimum diameter of objects  expected in the image'};
            app.MinSizeXY.Value = 0.5;
            app.BlockSearch = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.BlockSearch.ValueChangedFcn = createCallbackFcn(app, @BlockSearchValueChanged, true);
            app.BlockSearch.Tooltip = {'Divide the image into multiple blocks in order to speed up objects detection'};
            app.BlockSearch.Text = 'Block search';
            app.BlockSearch.Value = true;
            app.Sphericity = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.Sphericity.Tooltip = {'Calculate shape properties of objects (i.e. sphericity, major axis length)'};
            app.Sphericity.Text = 'Shape Properties';
            app.ExpectedsizeofobjectsdiametermLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.ExpectedsizeofobjectsdiametermLabel.Text = 'Expected size of objects (diameter, µm)';
            app.MinObjectIntensityLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.MinObjectIntensityLabel.Text = 'Min. Object Intensity:';
            app.MinIntensity = uispinner(app.ObjectDetectionSettingsPanel);
            app.MinIntensity.Limits = [0 Inf];
            app.MinIntensity.Tooltip = {'Number of times the dimmest objects needs to be brighter than background'};
            app.MinIntensity.Value = 1;
            app.SingleZDotCut = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.SingleZDotCut.Text = 'On a Single plane';
            app.SingleZDotCut.Value = true;
            app.EdgeDotCut = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.EdgeDotCut.Text = 'Touching the edge';
            app.xyStableDots = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.xyStableDots.Text = 'With unstable centroid';
            app.ExcludeObjectsLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.ExcludeObjectsLabel.Text = 'Exclude Objects:';
            app.LocalNoise = uicheckbox(app.ObjectDetectionSettingsPanel);
            app.LocalNoise.Tooltip = {'Estimate noise level within each search block to take into account local background level'};
            app.LocalNoise.Text = 'Local background';
            app.LocalNoise.Value = true;
            app.DetectionAlgorithmLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.DetectionAlgorithmLabel.Text = 'Detection Algorithm:';
            app.cmbSegmentation = uidropdown(app.ObjectDetectionSettingsPanel);
            app.cmbSegmentation.Items = {'Iterative thresholding', 'Simple thresholding'};
            app.cmbSegmentation.Tooltip = {'Type of algorithm used to detect objects.'; 'Iterative Thresh: slower but most accurate'; 'Simple Thresh: faster but less accurate'};
            app.cmbSegmentation.Value = 'Iterative thresholding';
            app.BackgroundlevelLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.BackgroundlevelLabel.Text = 'Background level:';
            app.cmbNoiseEstimator = uidropdown(app.ObjectDetectionSettingsPanel);
            app.cmbNoiseEstimator.Items = {'Most common intensity', 'Standard deviation', 'Minimum intensity'};
            app.cmbNoiseEstimator.Value = 'Most common intensity';
            app.VoxelConnectivityLabel = uilabel(app.ObjectDetectionSettingsPanel);
            app.VoxelConnectivityLabel.Text = 'Voxel Connectivity:';
            app.cmbConnectedVoxN = uidropdown(app.ObjectDetectionSettingsPanel);
            app.cmbConnectedVoxN.Items = {'6', '18', '26'};
            app.cmbConnectedVoxN.Tooltip = {'Number of directions upon which to look for connected voxels'};
            app.cmbConnectedVoxN.Value = '6';
            app.btnCalcDiameter = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnCalcDiameter.ButtonPushedFcn = createCallbackFcn(app, @btnCalcDiameterButtonPushed, true);
            app.btnCalcDiameter.Icon = '48px-calculator.png';
            app.btnCalcDiameter.Enable = 'off';
            app.btnCalcDiameter.Tooltip = {'Diameter calculator'};
            app.btnCalcDiameter.Text = '';
            app.btnFilterObjs = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnFilterObjs.ButtonPushedFcn = createCallbackFcn(app, @btnFilterObjsPushed, true);
            app.btnFilterObjs.Icon = '48px-Filter.png';
            app.btnFilterObjs.Enable = 'off';
            app.btnFilterObjs.Tooltip = {'Filter objects again according to exclusion criteria'};
            app.btnFilterObjs.Text = '';
            app.cmbPreset = uidropdown(app.ObjectDetectionSettingsPanel);
            app.cmbPreset.Items = {};
            app.cmbPreset.ValueChangedFcn = createCallbackFcn(app, @cmbPresetValueChanged, true);
            app.cmbPreset.Tooltip = {'Current preset'};
            app.cmbPreset.Value = {};
            app.btnPresetRename = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnPresetRename.ButtonPushedFcn = createCallbackFcn(app, @btnPresetRenameButtonPushed, true);
            app.btnPresetRename.Icon = '48px-Text-x-generic_with_pencil.png';
            app.btnPresetRename.Enable = 'off';
            app.btnPresetRename.Tooltip = {'Rename selected preset'};
            app.btnPresetRename.Text = '';
            app.btnPresetDelete = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnPresetDelete.ButtonPushedFcn = createCallbackFcn(app, @btnPresetDeleteButtonPushed, true);
            app.btnPresetDelete.Icon = '48px-Edit-delete-2.png';
            app.btnPresetDelete.Enable = 'off';
            app.btnPresetDelete.Tooltip = {'Delete selected preset'};
            app.btnPresetDelete.Text = '';
            app.btnPresetImport = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnPresetImport.ButtonPushedFcn = createCallbackFcn(app, @btnPresetImportButtonPushed, true);
            app.btnPresetImport.Icon = '48px-import.png';
            app.btnPresetImport.Enable = 'off';
            app.btnPresetImport.Tooltip = {'Import preset from file'};
            app.btnPresetImport.Text = '';
            app.btnPresetExport = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnPresetExport.ButtonPushedFcn = createCallbackFcn(app, @btnPresetExportButtonPushed, true);
            app.btnPresetExport.Icon = '48px-export.png';
            app.btnPresetExport.Enable = 'off';
            app.btnPresetExport.Tooltip = {'Export preset to disk'};
            app.btnPresetExport.Text = '';
            app.btnPresetCreate = uibutton(app.ObjectDetectionSettingsPanel, 'push');
            app.btnPresetCreate.ButtonPushedFcn = createCallbackFcn(app, @btnPresetCreateButtonPushed, true);
            app.btnPresetCreate.Icon = '48px-save.png';
            app.btnPresetCreate.Tooltip = {'Store current settings into a preset'};
            app.btnPresetCreate.Text = '';
            app.CurrentObjectsPanel = uipanel(app.DetectTab);
            app.cmbObjsDetected = uidropdown(app.CurrentObjectsPanel);
            app.cmbObjsDetected.Items = {};
            app.cmbObjsDetected.ValueChangedFcn = createCallbackFcn(app, @cmbObjsDetectedValueChanged, true);
            app.cmbObjsDetected.Tooltip = {'Current objects'};
            app.cmbObjsDetected.Value = {};
            app.cmbSkel = uidropdown(app.CurrentObjectsPanel);
            app.cmbSkel.Items = {};
            app.cmbSkel.ValueChangedFcn = createCallbackFcn(app, @cmbSkelValueChanged, true);
            app.cmbSkel.Tooltip = {'Current digital skeleton'};
            app.cmbSkel.Value = {};
            app.cmbMask = uidropdown(app.CurrentObjectsPanel);
            app.cmbMask.Items = {};
            app.cmbMask.ValueChangedFcn = createCallbackFcn(app, @cmbMaskValueChanged, true);
            app.cmbMask.Tooltip = {'Binary mask'};
            app.cmbMask.Value = {};
            app.cmbPost = uidropdown(app.CurrentObjectsPanel);
            app.cmbPost.Items = {};
            app.cmbPost.ValueChangedFcn = createCallbackFcn(app, @cmbPostValueChanged, true);
            app.cmbPost.Tooltip = {'Source image file'};
            app.cmbPost.Value = {};
            app.btnRenameObjects = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnRenameObjects.ButtonPushedFcn = createCallbackFcn(app, @btnRenameObjectsButtonPushed, true);
            app.btnRenameObjects.Icon = '48px-Text-x-generic_with_pencil.png';
            app.btnRenameObjects.Enable = 'off';
            app.btnRenameObjects.Tooltip = {'Rename current objects'};
            app.btnRenameObjects.Text = '';
            app.btnCopyObjects = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnCopyObjects.ButtonPushedFcn = createCallbackFcn(app, @btnCopyObjectsButtonPushed, true);
            app.btnCopyObjects.Icon = '48px-Edit-copy.png';
            app.btnCopyObjects.Enable = 'off';
            app.btnCopyObjects.Tooltip = {'Duplicate current objects'};
            app.btnCopyObjects.Text = '';
            app.btnDeleteObjects = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnDeleteObjects.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteObjectsButtonPushed, true);
            app.btnDeleteObjects.Icon = '48px-Edit-delete-2.png';
            app.btnDeleteObjects.Enable = 'off';
            app.btnDeleteObjects.Tooltip = {'Delete current objects'};
            app.btnDeleteObjects.Text = '';
            app.btnSkelRename = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnSkelRename.ButtonPushedFcn = createCallbackFcn(app, @btnSkelRenameButtonPushed, true);
            app.btnSkelRename.Icon = '48px-Text-x-generic_with_pencil.png';
            app.btnSkelRename.Enable = 'off';
            app.btnSkelRename.Tooltip = {'Rename selected skeleton'};
            app.btnSkelRename.Text = '';
            app.btnSkelDelete = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnSkelDelete.ButtonPushedFcn = createCallbackFcn(app, @btnSkelDeleteButtonPushed, true);
            app.btnSkelDelete.Icon = '48px-Edit-delete-2.png';
            app.btnSkelDelete.Enable = 'off';
            app.btnSkelDelete.Tooltip = {'Delete selected skeleton'};
            app.btnSkelDelete.Text = '';
            app.btnSkelImport = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnSkelImport.ButtonPushedFcn = createCallbackFcn(app, @btnSkelImportButtonPushed, true);
            app.btnSkelImport.Icon = '48px-import.png';
            app.btnSkelImport.Enable = 'off';
            app.btnSkelImport.Tooltip = {'Import skeleton from file'};
            app.btnSkelImport.Text = '';
            app.btnImgInfo = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnImgInfo.ButtonPushedFcn = createCallbackFcn(app, @btnImgInfoButtonPushed, true);
            app.btnImgInfo.Icon = '48px-info.png';
            app.btnImgInfo.Enable = 'off';
            app.btnImgInfo.Tooltip = {'Image properties'};
            app.btnImgInfo.Text = '';
            app.btnMaskInfo = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnMaskInfo.ButtonPushedFcn = createCallbackFcn(app, @btnMaskInfoButtonPushed, true);
            app.btnMaskInfo.Icon = '48px-info.png';
            app.btnMaskInfo.Enable = 'off';
            app.btnMaskInfo.Tooltip = {'Mask properties'};
            app.btnMaskInfo.Text = '';
            app.btnImgRender = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnImgRender.ButtonPushedFcn = createCallbackFcn(app, @btnImgRenderButtonPushed, true);
            app.btnImgRender.Icon = '48px-render.png';
            app.btnImgRender.Enable = 'off';
            app.btnImgRender.Tooltip = {'Render current image & objects'};
            app.btnImgRender.Text = '';
            app.btnMaskRender = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnMaskRender.ButtonPushedFcn = createCallbackFcn(app, @btnMaskRenderButtonPushed, true);
            app.btnMaskRender.Icon = '48px-render.png';
            app.btnMaskRender.Enable = 'off';
            app.btnMaskRender.Tooltip = {'Render the masked volume'};
            app.btnMaskRender.Text = '';
            app.btnImgMIP = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnImgMIP.ButtonPushedFcn = createCallbackFcn(app, @btnImgMIPButtonPushed, true);
            app.btnImgMIP.Icon = '48px-MIP.png';
            app.btnImgMIP.Enable = 'off';
            app.btnImgMIP.Tooltip = {'Display the maximum intensity projection'};
            app.btnImgMIP.Text = '';
            app.btnMaskMIP = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnMaskMIP.ButtonPushedFcn = createCallbackFcn(app, @btnMaskMIPButtonPushed, true);
            app.btnMaskMIP.Icon = '48px-Paint-Mask.png';
            app.btnMaskMIP.Enable = 'off';
            app.btnMaskMIP.Tooltip = {'Display the maximum intensity projection of the mask'};
            app.btnMaskMIP.Text = '';
            app.imgObjects = uiimage(app.CurrentObjectsPanel);
            app.imgObjects.Tooltip = {'Detected Objects'};
            app.imgObjects.ImageSource = '48px-objects.png';
            app.imgPost = uiimage(app.CurrentObjectsPanel);
            app.imgPost.Tooltip = {'Raw image'};
            app.imgPost.ImageSource = '48px-image.png';
            app.imgMask = uiimage(app.CurrentObjectsPanel);
            app.imgMask.Tooltip = {'Binary mask'};
            app.imgMask.ImageSource = '48px-Masks.png';
            app.imgSkel = uiimage(app.CurrentObjectsPanel);
            app.imgSkel.Tooltip = {'Digital skeleton'};
            app.imgSkel.ImageSource = '48px-Fish-Bone.png';
            app.btnMaskLink = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnMaskLink.ButtonPushedFcn = createCallbackFcn(app, @btnMaskLinkButtonPushed, true);
            app.btnMaskLink.Icon = '48px-Link_Image.png';
            app.btnMaskLink.Enable = 'off';
            app.btnMaskLink.Tooltip = {'Link selected mask to current objects'};
            app.btnMaskLink.Text = '';
            app.btnPlotLocations = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnPlotLocations.ButtonPushedFcn = createCallbackFcn(app, @btnPlotLocationsPushed, true);
            app.btnPlotLocations.Icon = '48px-Map-Treaure.png';
            app.btnPlotLocations.Enable = 'off';
            app.btnPlotLocations.Tooltip = {'Visualize map of Objects'' positions'};
            app.btnPlotLocations.Text = '';
            app.btnPlotSkeleton = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnPlotSkeleton.ButtonPushedFcn = createCallbackFcn(app, @btnPlotSkeletonPushed, true);
            app.btnPlotSkeleton.Icon = '48px-Fish-Bone.png';
            app.btnPlotSkeleton.Enable = 'off';
            app.btnPlotSkeleton.Tooltip = {'Plot skeleton'};
            app.btnPlotSkeleton.Text = '';
            app.btnChooseImages = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnChooseImages.ButtonPushedFcn = createCallbackFcn(app, @btnChooseImagesPushed, true);
            app.btnChooseImages.Icon = '48px-Training-set.png';
            app.btnChooseImages.Enable = 'off';
            app.btnChooseImages.Tooltip = {'Choose image from gallery'};
            app.btnChooseImages.Text = '';
            app.cmbInspectorType = uidropdown(app.CurrentObjectsPanel);
            app.cmbInspectorType.Items = {'Sliced Volume Inspector', '3D Volume Inspector', 'Bitplane Imaris (7.2.3 or older)', 'Bitplane Imaris (7.2.4 or newer)', 'Neural Net'};
            app.cmbInspectorType.ValueChangedFcn = createCallbackFcn(app, @cmbInspectorTypeValueChanged, true);
            app.cmbInspectorType.Value = 'Sliced Volume Inspector';
            app.imgValidate = uiimage(app.CurrentObjectsPanel);
            app.imgValidate.Tooltip = {'Validation tool'};
            app.imgValidate.ImageSource = '48px-Inspect.png';
            app.cmbNeuralNet = uidropdown(app.CurrentObjectsPanel);
            app.cmbNeuralNet.Items = {};
            app.cmbNeuralNet.Enable = 'off';
            app.cmbNeuralNet.Tooltip = {'Neural network'};
            app.cmbNeuralNet.Value = {};
            app.btnPurgeInvalid = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnPurgeInvalid.ButtonPushedFcn = createCallbackFcn(app, @btnPurgeInvalidButtonPushed, true);
            app.btnPurgeInvalid.Icon = '48px-trash.png';
            app.btnPurgeInvalid.Enable = 'off';
            app.btnPurgeInvalid.Tooltip = {'Purge invalid objects from set'};
            app.btnPurgeInvalid.Text = '';
            app.btnNewExperiment = uibutton(app.DetectTab, 'push');
            app.btnNewExperiment.ButtonPushedFcn = createCallbackFcn(app, @btnNewExperimentButtonPushed, true);
            app.btnNewExperiment.Icon = '48px-new.png';
            app.btnNewExperiment.Tooltip = {'Create a new image analysis project'};
            app.btnNewExperiment.Text = {'New '; 'Project'};
            app.btnSimpleSettings = uibutton(app.DetectTab, 'push');
            app.btnSimpleSettings.ButtonPushedFcn = createCallbackFcn(app, @btnSimpleSettingsButtonPushed, true);
            app.btnSimpleSettings.Icon = '48px-settings.png';
            app.btnSimpleSettings.Enable = 'off';
            app.btnSimpleSettings.Tooltip = {'Choose detection settings'};
            app.btnSimpleSettings.Text = {'Choose'; 'Settings'};
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.pnlNearestNeighbor = uipanel(app.ReportTab);
            app.pnlNearestNeighbor.Title = 'Nearest neighbor analysis';
            app.NeighboringobjectsDropDownLabel = uilabel(app.pnlNearestNeighbor);
            app.NeighboringobjectsDropDownLabel.Text = 'Neighboring objects:';
            app.ReportSelObjectsNN = uidropdown(app.pnlNearestNeighbor);
            app.ReportSelObjectsNN.Items = {};
            app.ReportSelObjectsNN.ValueChangedFcn = createCallbackFcn(app, @ReportSelObjectsNNValueChanged, true);
            app.ReportSelObjectsNN.Value = {};
            app.btnCalculateNN = uibutton(app.pnlNearestNeighbor, 'push');
            app.btnCalculateNN.ButtonPushedFcn = createCallbackFcn(app, @btnCalculateNNButtonPushed, true);
            app.btnCalculateNN.Icon = '48px-Measure.png';
            app.btnCalculateNN.Text = 'Calculate';
            app.btnDeleteNN = uibutton(app.pnlNearestNeighbor, 'push');
            app.btnDeleteNN.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteNNPushed, true);
            app.btnDeleteNN.Icon = '48px-Edit-delete-2.png';
            app.btnDeleteNN.Text = '';
            app.lblNNdistance = uilabel(app.pnlNearestNeighbor);
            app.lblNNdistance.Text = 'Nearest neighbor distance: N/A';
            app.lblNNvoxelOverlap = uilabel(app.pnlNearestNeighbor);
            app.lblNNvoxelOverlap.Text = 'Percent voxels overlapping: N/A';
            app.btnPlotNNdistDist = uibutton(app.pnlNearestNeighbor, 'push');
            app.btnPlotNNdistDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotNNdistDistButtonPushed, true);
            app.btnPlotNNdistDist.Text = 'Distance distribution';
            app.btnPlotNNoverlapDist = uibutton(app.pnlNearestNeighbor, 'push');
            app.btnPlotNNoverlapDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotNNoverlapDistButtonPushed, true);
            app.btnPlotNNoverlapDist.Text = 'Overlap distribution';
            app.FittingreferencedistributionLabel = uilabel(app.pnlNearestNeighbor);
            app.FittingreferencedistributionLabel.Text = 'Fitting reference distribution:';
            app.ReportDistFitType = uidropdown(app.pnlNearestNeighbor);
            app.ReportDistFitType.Items = {'beta', 'burr', 'exponential', 'gamma', 'inversegaussian', 'logistic', 'loglogistic', 'lognormal', 'nakagami', 'negative binomial', 'normal', 'poisson', 'rayleigh', 'rician', 'weibull', 'kernel'};
            app.ReportDistFitType.Value = 'normal';
            app.tblReport = uitable(app.ReportTab);
            app.tblReport.ColumnName = {'Property'; 'Value'};
            app.tblReport.RowName = {};
            app.btnPlotDensityByDepth = uibutton(app.ReportTab, 'push');
            app.btnPlotDensityByDepth.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDensityByDepthPushed, true);
            app.btnPlotDensityByDepth.Icon = '48px-Whale-Tail.png';
            app.btnPlotDensityByDepth.Enable = 'off';
            app.btnPlotDensityByDepth.Text = {'Density'; 'by Depth'};
            app.btnPlotDensityLinear = uibutton(app.ReportTab, 'push');
            app.btnPlotDensityLinear.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDensityLinearPushed, true);
            app.btnPlotDensityLinear.Icon = '48px-Tentacle.png';
            app.btnPlotDensityLinear.Enable = 'off';
            app.btnPlotDensityLinear.Text = {'Density'; 'Along Skel'};
            app.btnPlotHeatmap = uibutton(app.ReportTab, 'push');
            app.btnPlotHeatmap.ButtonPushedFcn = createCallbackFcn(app, @btnPlotHeatmapPushed, true);
            app.btnPlotHeatmap.Icon = '48px-Heatmap.png';
            app.btnPlotHeatmap.Enable = 'off';
            app.btnPlotHeatmap.Text = {'Density'; 'Heatmap'};
            app.btnPlotSholl = uibutton(app.ReportTab, 'push');
            app.btnPlotSholl.ButtonPushedFcn = createCallbackFcn(app, @btnPlotShollPushed, true);
            app.btnPlotSholl.Icon = '48px-Sholl.png';
            app.btnPlotSholl.Enable = 'off';
            app.btnPlotSholl.Text = {'Sholl'; 'Analysis'};
            app.btnSaveReport = uibutton(app.ReportTab, 'push');
            app.btnSaveReport.ButtonPushedFcn = createCallbackFcn(app, @btnSaveReportButtonPushed, true);
            app.btnSaveReport.Icon = '48px-save.png';
            app.btnSaveReport.Enable = 'off';
            app.btnSaveReport.Text = {'Save'; 'Report'};
            app.btnPlotObjSizeDist = uibutton(app.ReportTab, 'push');
            app.btnPlotObjSizeDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjSizeDistButtonPushed, true);
            app.btnPlotObjSizeDist.Icon = '48px-Whale-Small-Fish.png';
            app.btnPlotObjSizeDist.Enable = 'off';
            app.btnPlotObjSizeDist.Text = {'Volume'; 'Distribution'};
            app.btnPlotObjBrightDist = uibutton(app.ReportTab, 'push');
            app.btnPlotObjBrightDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjBrightDistButtonPushed, true);
            app.btnPlotObjBrightDist.Icon = '48px-Angler-Fish.png';
            app.btnPlotObjBrightDist.Enable = 'off';
            app.btnPlotObjBrightDist.Text = {'Brightness'; 'Distribution'};
            app.btnPlotObjRoundDist = uibutton(app.ReportTab, 'push');
            app.btnPlotObjRoundDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjRoundDistButtonPushed, true);
            app.btnPlotObjRoundDist.Icon = '48px-Blow-Fish.png';
            app.btnPlotObjRoundDist.Enable = 'off';
            app.btnPlotObjRoundDist.Text = {'Sphericity'; 'Distribution'};
            app.btnPlotObjMajorAxisDist = uibutton(app.ReportTab, 'push');
            app.btnPlotObjMajorAxisDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjMajorAxisDistButtonPushed, true);
            app.btnPlotObjMajorAxisDist.Icon = '48px-Crab.png';
            app.btnPlotObjMajorAxisDist.Enable = 'off';
            app.btnPlotObjMajorAxisDist.Text = {'Major axis'; 'Distribution'};
            app.btnCreateObjMask = uibutton(app.ReportTab, 'push');
            app.btnCreateObjMask.ButtonPushedFcn = createCallbackFcn(app, @btnCreateObjMaskButtonPushed, true);
            app.btnCreateObjMask.Icon = '48px-Masks.png';
            app.btnCreateObjMask.Enable = 'off';
            app.btnCreateObjMask.Text = {'Binary '; 'Mask'};
            app.btnRecalculateSphericity = uibutton(app.ReportTab, 'push');
            app.btnRecalculateSphericity.ButtonPushedFcn = createCallbackFcn(app, @btnRecalculateSphericityButtonPushed, true);
            app.btnRecalculateSphericity.Icon = '48px-Blow-Fish.png';
            app.btnRecalculateSphericity.Enable = 'off';
            app.btnRecalculateSphericity.Tooltip = {'Measure shape'};
            app.btnRecalculateSphericity.Text = {'Measure'; 'Shape'};
            app.btnCreateObjMaskColored = uibutton(app.ReportTab, 'push');
            app.btnCreateObjMaskColored.ButtonPushedFcn = createCallbackFcn(app, @btnCreateObjMaskColoredButtonPushed, true);
            app.btnCreateObjMaskColored.Icon = '48px-MasksColored.png';
            app.btnCreateObjMaskColored.Enable = 'off';
            app.btnCreateObjMaskColored.Tooltip = {'Create a mask in which each objects has a random color'};
            app.btnCreateObjMaskColored.Text = {'Colred '; 'Mask'};
            app.AutomateTab = uitab(app.TabGroup);
            app.AutomateTab.Title = 'Automate';
            app.ProcessinglistPanel = uipanel(app.AutomateTab);
            app.ProcessinglistPanel.Title = 'Processing list';
            app.lstBatch = uilistbox(app.ProcessinglistPanel);
            app.lstBatch.Items = {};
            app.lstBatch.ValueChangedFcn = createCallbackFcn(app, @lstBatchValueChanged, true);
            app.lstBatch.Value = {};
            app.btnBatchAdd = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchAdd.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddButtonPushed, true);
            app.btnBatchAdd.Icon = '48px-List-add.png';
            app.btnBatchAdd.Tooltip = {'Add a new project folder to list'};
            app.btnBatchAdd.Text = '';
            app.btnBatchRemove = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchRemove.ButtonPushedFcn = createCallbackFcn(app, @btnBatchRemoveButtonPushed, true);
            app.btnBatchRemove.Icon = '48px-List-remove.png';
            app.btnBatchRemove.Enable = 'off';
            app.btnBatchRemove.Tooltip = {'Remove selected items from list'};
            app.btnBatchRemove.Text = '';
            app.btnBatchUp = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchUp.ButtonPushedFcn = createCallbackFcn(app, @btnBatchUpButtonPushed, true);
            app.btnBatchUp.Icon = '48px-Go-up.png';
            app.btnBatchUp.Enable = 'off';
            app.btnBatchUp.Tooltip = {'Bring selection ahead in the processing list'};
            app.btnBatchUp.Text = '';
            app.btnBatchDown = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchDown.ButtonPushedFcn = createCallbackFcn(app, @btnBatchDownButtonPushed, true);
            app.btnBatchDown.Icon = '48px-Go-down.png';
            app.btnBatchDown.Enable = 'off';
            app.btnBatchDown.Tooltip = {'Push selection behind in the processing list'};
            app.btnBatchDown.Text = '';
            app.btnBatchTop = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchTop.ButtonPushedFcn = createCallbackFcn(app, @btnBatchTopButtonPushed, true);
            app.btnBatchTop.Icon = '48px-Go-top.png';
            app.btnBatchTop.Enable = 'off';
            app.btnBatchTop.Tooltip = {'Bring selection to top of the list'};
            app.btnBatchTop.Text = '';
            app.btnBatchBottom = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchBottom.ButtonPushedFcn = createCallbackFcn(app, @btnBatchBottomButtonPushed, true);
            app.btnBatchBottom.Icon = '48px-Go-bottom.png';
            app.btnBatchBottom.Enable = 'off';
            app.btnBatchBottom.Tooltip = {'Push selection all the way to the end'};
            app.btnBatchBottom.Text = '';
            app.btnBatchClear = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchClear.ButtonPushedFcn = createCallbackFcn(app, @btnBatchClearButtonPushed, true);
            app.btnBatchClear.Icon = '48px-Edit-clear.png';
            app.btnBatchClear.Enable = 'off';
            app.btnBatchClear.Tooltip = {'Clear processing list'};
            app.btnBatchClear.Text = '';
            app.btnBatchAddMultiple = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchAddMultiple.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddMultipleButtonPushed, true);
            app.btnBatchAddMultiple.Icon = '48px-Add-Folder.png';
            app.btnBatchAddMultiple.Tooltip = {'Add every subfolder of selected folder to list'};
            app.btnBatchAddMultiple.Text = '';
            app.btnBatchStart = uibutton(app.AutomateTab, 'push');
            app.btnBatchStart.ButtonPushedFcn = createCallbackFcn(app, @btnBatchStartButtonPushed, true);
            app.btnBatchStart.Icon = '72px-Batch-process.png';
            app.btnBatchStart.Text = {'Batch'; 'Process'};
            app.BatchLog = uitextarea(app.AutomateTab);
            app.BatchLog.Value = {'Activity log:'};
            app.AutomateOperation = uitabgroup(app.AutomateTab);
            app.AutoSearchTab = uitab(app.AutomateOperation);
            app.AutoSearchTab.Title = 'Search';
            app.BatchWatershed = uicheckbox(app.AutoSearchTab);
            app.BatchWatershed.Text = 'Watershed';
            app.BatchWatershed.Value = true;
            app.BatchBlockSearch = uicheckbox(app.AutoSearchTab);
            app.BatchBlockSearch.ValueChangedFcn = createCallbackFcn(app, @BatchBlockSearchValueChanged, true);
            app.BatchBlockSearch.Text = 'Block search';
            app.BatchBlockSearch.Value = true;
            app.BatchSphericity = uicheckbox(app.AutoSearchTab);
            app.BatchSphericity.Text = 'Shape info';
            app.ExpecteddiameterrangeofobjectsmLabel = uilabel(app.AutoSearchTab);
            app.ExpecteddiameterrangeofobjectsmLabel.Text = 'Expected diameter range of objects (µm)';
            app.BatchSingleZDotCut = uicheckbox(app.AutoSearchTab);
            app.BatchSingleZDotCut.Text = 'Single Z plane';
            app.BatchSingleZDotCut.Value = true;
            app.BatchEdgeDotCut = uicheckbox(app.AutoSearchTab);
            app.BatchEdgeDotCut.Text = 'On mask edge';
            app.BatchxyStableDots = uicheckbox(app.AutoSearchTab);
            app.BatchxyStableDots.Text = 'Unstable centroids';
            app.ExcludeifLabel_2 = uilabel(app.AutoSearchTab);
            app.ExcludeifLabel_2.Text = 'Exclude if:';
            app.BatchLocalNoise = uicheckbox(app.AutoSearchTab);
            app.BatchLocalNoise.Text = 'Local noise';
            app.BatchLocalNoise.Value = true;
            app.MaxLabel_4 = uilabel(app.AutoSearchTab);
            app.MaxLabel_4.Text = 'Max';
            app.BatchMaxSizeXY = uieditfield(app.AutoSearchTab, 'numeric');
            app.BatchMaxSizeXY.Limits = [0 Inf];
            app.BatchMaxSizeXY.Value = 5;
            app.MinLabel_4 = uilabel(app.AutoSearchTab);
            app.MinLabel_4.Text = 'Min';
            app.BatchMinSizeXY = uieditfield(app.AutoSearchTab, 'numeric');
            app.BatchMinSizeXY.Limits = [0 Inf];
            app.BatchMinSizeXY.Value = 0.5;
            app.txtBatchThreshValue = uieditfield(app.AutoSearchTab, 'numeric');
            app.MinimumintensityofobjectstimesthenoiselevelLabel = uilabel(app.AutoSearchTab);
            app.MinimumintensityofobjectstimesthenoiselevelLabel.Text = 'Minimum intensity of objects (# times the noise level):';
            app.BatchMinIntensity = uispinner(app.AutoSearchTab);
            app.BatchMinIntensity.Limits = [0 Inf];
            app.BatchMinIntensity.Value = 1;
            app.cmbBatchThreshParam = uidropdown(app.AutoSearchTab);
            app.cmbBatchThreshParam.Items = {'None', 'Score', 'Size', 'Brightness', 'Roundness', 'Major Axis Length', 'Z Position'};
            app.cmbBatchThreshParam.Value = 'None';
            app.SearchLabel = uilabel(app.AutoSearchTab);
            app.SearchLabel.Text = 'Search:';
            app.cmbBatchSegmentation = uidropdown(app.AutoSearchTab);
            app.cmbBatchSegmentation.Items = {'Iterative thresholding', 'Simple thresholding'};
            app.cmbBatchSegmentation.Value = 'Iterative thresholding';
            app.NoiseLabel = uilabel(app.AutoSearchTab);
            app.NoiseLabel.Text = 'Noise:';
            app.cmbBatchNoiseEstimator = uidropdown(app.AutoSearchTab);
            app.cmbBatchNoiseEstimator.Items = {'Most common intensity', 'Standard deviation'};
            app.cmbBatchNoiseEstimator.Value = 'Most common intensity';
            app.cmbBatchThresh2Param = uidropdown(app.AutoSearchTab);
            app.cmbBatchThresh2Param.Items = {'None', 'Score', 'Size', 'Brightness', 'Roundness', 'Major Axis Length', 'Z Position'};
            app.cmbBatchThresh2Param.Value = 'None';
            app.txtBatchThresh2Value = uieditfield(app.AutoSearchTab, 'numeric');
            app.cmbBatchThreshDir = uidropdown(app.AutoSearchTab);
            app.cmbBatchThreshDir.Items = {'>=', '<='};
            app.cmbBatchThreshDir.Value = '>=';
            app.cmbBatchThresh2Dir = uidropdown(app.AutoSearchTab);
            app.cmbBatchThresh2Dir.Items = {'>=', '<='};
            app.cmbBatchThresh2Dir.Value = '>=';
            app.ValidateifLabel = uilabel(app.AutoSearchTab);
            app.ValidateifLabel.Text = 'Validate if:';
            app.ANDLabel = uilabel(app.AutoSearchTab);
            app.ANDLabel.Text = 'AND:';
            app.cmbAutoDetectImage = uidropdown(app.AutoSearchTab);
            app.cmbAutoDetectImage.Items = {};
            app.cmbAutoDetectImage.Value = {};
            app.lblAutoDetectImage = uilabel(app.AutoSearchTab);
            app.lblAutoDetectImage.Text = 'Image stack:';
            app.lblAutoDetectMask = uilabel(app.AutoSearchTab);
            app.lblAutoDetectMask.Text = 'Mask:';
            app.cmbAutoDetectMask = uidropdown(app.AutoSearchTab);
            app.cmbAutoDetectMask.Items = {};
            app.cmbAutoDetectMask.Value = {};
            app.AutoColocalizeTab = uitab(app.AutomateOperation);
            app.AutoColocalizeTab.Title = 'Colocalize';
            app.ReferenceObjectsDropDownLabel = uilabel(app.AutoColocalizeTab);
            app.ReferenceObjectsDropDownLabel.Text = 'Reference Objects:';
            app.AutoColoRefObjs = uidropdown(app.AutoColocalizeTab);
            app.AutoColoRefObjs.Items = {};
            app.AutoColoRefObjs.Value = {};
            app.AutoColoChannel = uidropdown(app.AutoColocalizeTab);
            app.AutoColoChannel.Items = {};
            app.AutoColoChannel.Value = {};
            app.TocolocalizewithSwitchLabel = uilabel(app.AutoColocalizeTab);
            app.TocolocalizewithSwitchLabel.Text = 'To colocalize with:';
            app.AutoColoMaskOrObjs = uiswitch(app.AutoColocalizeTab, 'slider');
            app.AutoColoMaskOrObjs.Items = {'Mask', 'Objects'};
            app.AutoColoMaskOrObjs.ValueChangedFcn = createCallbackFcn(app, @AutoColoMaskOrObjsValueChanged, true);
            app.AutoColoMaskOrObjs.Value = 'Mask';
            app.MustshareatleastSpinnerLabel = uilabel(app.AutoColocalizeTab);
            app.MustshareatleastSpinnerLabel.Text = 'Must share at least:';
            app.AutoColoVoxNum = uispinner(app.AutoColocalizeTab);
            app.AutoColoSwitchVoxPerc = uiswitch(app.AutoColocalizeTab, 'slider');
            app.AutoColoSwitchVoxPerc.Items = {'Voxels', 'Percent'};
            app.AutoColoSwitchVoxPerc.Value = 'Voxels';
            app.chkAutoColoAll = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoColoAll.ValueChangedFcn = createCallbackFcn(app, @chkAutoColoAllValueChanged, true);
            app.chkAutoColoAll.Text = 'Process all available object sets';
            app.chkAutoColoCenterMustOverlap = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoColoCenterMustOverlap.Text = 'Centroid must overlap';
            app.WithinmLabel_2 = uilabel(app.AutoColocalizeTab);
            app.WithinmLabel_2.Text = 'Within (µm)';
            app.cmbAutoColoThresholdDist = uispinner(app.AutoColocalizeTab);
            app.cmbAutoColoThresholdDist.Limits = [0 Inf];
            app.cmbAutoColoThresholdDist.ValueDisplayFormat = '%.0f';
            app.cmbAutoColoThresholdDist.Enable = 'off';
            app.cmbAutoColoThresholdDist.Value = Inf;
            app.chkAutoSaveColoObjs = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoSaveColoObjs.Text = 'Save colocalized objects';
            app.chkAutoSaveNotColoObjs = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoSaveNotColoObjs.Text = 'Save not colocalized objects';
            app.chkAutoRecalculateNN = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoRecalculateNN.Text = 'Recalculate nearest neighbor distance if present';
            app.AutoExportMaskTab = uitab(app.AutomateOperation);
            app.AutoExportMaskTab.Title = 'Export mask';
            app.chkAutoColoExportAll = uicheckbox(app.AutoExportMaskTab);
            app.chkAutoColoExportAll.ValueChangedFcn = createCallbackFcn(app, @chkAutoColoExportAllValueChanged, true);
            app.chkAutoColoExportAll.Text = 'Process all available object sets';
            app.ReferenceObjectsDropDownLabel_2 = uilabel(app.AutoExportMaskTab);
            app.ReferenceObjectsDropDownLabel_2.Text = 'Reference Objects:';
            app.AutoColoExportRefObjs = uidropdown(app.AutoExportMaskTab);
            app.AutoColoExportRefObjs.Items = {};
            app.AutoColoExportRefObjs.Value = {};
            app.MasktypeLabel = uilabel(app.AutoExportMaskTab);
            app.MasktypeLabel.Text = 'Mask type:';
            app.cmbAutoExportMaskType = uidropdown(app.AutoExportMaskTab);
            app.cmbAutoExportMaskType.Items = {'Binary Mask', 'Colored Mask'};
            app.cmbAutoExportMaskType.Value = 'Binary Mask';
            app.AutoReportTab = uitab(app.AutomateOperation);
            app.AutoReportTab.Title = 'Report';
            app.chkAutoReportAll = uicheckbox(app.AutoReportTab);
            app.chkAutoReportAll.ValueChangedFcn = createCallbackFcn(app, @chkAutoReportAllValueChanged, true);
            app.chkAutoReportAll.Text = 'Process all available object sets';
            app.ReferenceObjectsDropDownLabel_3 = uilabel(app.AutoReportTab);
            app.ReferenceObjectsDropDownLabel_3.Text = 'Reference Objects:';
            app.AutoReportRefObjs = uidropdown(app.AutoReportTab);
            app.AutoReportRefObjs.Items = {};
            app.AutoReportRefObjs.Value = {};
            app.ReportstatisticLabel = uilabel(app.AutoReportTab);
            app.ReportstatisticLabel.Text = 'Report statistic:';
            app.AutoReportStatistic = uidropdown(app.AutoReportTab);
            app.AutoReportStatistic.Items = {'Summary statistics', 'Colocalization rate', 'Density by depth', 'Volume by depth', 'Volume', 'Brightness', 'Sphericity', 'Major Axis Length', 'Nearest Neighbor Distance', 'Nearest Neighbor Overlap'};
            app.AutoReportStatistic.ValueChangedFcn = createCallbackFcn(app, @AutoReportStatisticValueChanged, true);
            app.AutoReportStatistic.Value = 'Summary statistics';
            app.DestinationObjectsLabel = uilabel(app.AutoReportTab);
            app.DestinationObjectsLabel.Enable = 'off';
            app.DestinationObjectsLabel.Text = 'Destination Objects:';
            app.AutoReportDstObjs = uidropdown(app.AutoReportTab);
            app.AutoReportDstObjs.Items = {};
            app.AutoReportDstObjs.Enable = 'off';
            app.AutoReportDstObjs.Value = {};
            app.chkAutoReportAllDst = uicheckbox(app.AutoReportTab);
            app.chkAutoReportAllDst.ValueChangedFcn = createCallbackFcn(app, @chkAutoReportAllDstValueChanged, true);
            app.chkAutoReportAllDst.Enable = 'off';
            app.chkAutoReportAllDst.Text = 'Process all available object sets';
            app.BinningWidthLabel = uilabel(app.AutoReportTab);
            app.BinningWidthLabel.Enable = 'off';
            app.BinningWidthLabel.Text = 'Binning Width:';
            app.AutoReportBinSize = uispinner(app.AutoReportTab);
            app.AutoReportBinSize.Step = 0.5;
            app.AutoReportBinSize.Limits = [0 Inf];
            app.AutoReportBinSize.Enable = 'off';
            app.AutoReportBinSize.Value = 0.5;
            app.BinsLabel = uilabel(app.AutoReportTab);
            app.BinsLabel.Enable = 'off';
            app.BinsLabel.Text = '# Bins:';
            app.AutoReportBinNum = uispinner(app.AutoReportTab);
            app.AutoReportBinNum.Limits = [0 Inf];
            app.AutoReportBinNum.Enable = 'off';
            app.AutoReportBinNum.Value = 10;
            app.DepthLabel = uilabel(app.AutoReportTab);
            app.DepthLabel.Enable = 'off';
            app.DepthLabel.Text = 'Depth:';
            app.AutoReportDepthDir = uidropdown(app.AutoReportTab);
            app.AutoReportDepthDir.Items = {'Z', 'Y'};
            app.AutoReportDepthDir.Enable = 'off';
            app.AutoReportDepthDir.Value = 'Z';
            app.BatchScheduledTime = uiknob(app.AutomateTab, 'continuous');
            app.BatchScheduledTime.Limits = [1 24];
            app.BatchScheduledTime.Enable = 'off';
            app.BatchScheduledTime.Value = 19;
            app.BatchScheduler = uicheckbox(app.AutomateTab);
            app.BatchScheduler.ValueChangedFcn = createCallbackFcn(app, @BatchSchedulerValueChanged, true);
            app.BatchScheduler.Text = '';
            app.lblBatchScheduler = uilabel(app.AutomateTab);
            app.lblBatchScheduler.Enable = 'off';
            app.lblBatchScheduler.Text = 'Start at a custiom time:';
            app.chkAutoSaveTable = uicheckbox(app.AutomateTab);
            app.chkAutoSaveTable.Text = 'Save batch results into an excel spreadsheet in root folder';
            app.chkAutoSaveTable.Value = true;
            app.ColocalizeTab = uitab(app.TabGroup);
            app.ColocalizeTab.Title = 'Colocalize';
            app.ReviewcompletedanalysisPanel = uipanel(app.ColocalizeTab);
            app.ReviewcompletedanalysisPanel.Title = 'Review completed analysis';
            app.lstColoDone = uilistbox(app.ReviewcompletedanalysisPanel);
            app.lstColoDone.Items = {};
            app.lstColoDone.Multiselect = 'on';
            app.lstColoDone.ValueChangedFcn = createCallbackFcn(app, @lstColoDoneValueChanged, true);
            app.lstColoDone.Value = {};
            app.btnColoDoneRemove = uibutton(app.ReviewcompletedanalysisPanel, 'push');
            app.btnColoDoneRemove.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneRemoveButtonPushed, true);
            app.btnColoDoneRemove.Icon = '48px-List-remove.png';
            app.btnColoDoneRemove.Enable = 'off';
            app.btnColoDoneRemove.Tooltip = {'Delete selected colocalization analysis'};
            app.btnColoDoneRemove.Text = '';
            app.btnColoDoneClear = uibutton(app.ReviewcompletedanalysisPanel, 'push');
            app.btnColoDoneClear.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneClearButtonPushed, true);
            app.btnColoDoneClear.Icon = '48px-Edit-clear.png';
            app.btnColoDoneClear.Tooltip = {'Delete all completed colocalization analysis'};
            app.btnColoDoneClear.Text = '';
            app.btnColoRedoValid = uibutton(app.ReviewcompletedanalysisPanel, 'push');
            app.btnColoRedoValid.ButtonPushedFcn = createCallbackFcn(app, @btnColoRedoValidButtonPushed, true);
            app.btnColoRedoValid.Icon = 'Fruit-2cherries-check.png';
            app.btnColoRedoValid.Enable = 'off';
            app.btnColoRedoValid.Tooltip = {'Manually screen colocalized objects'};
            app.btnColoRedoValid.Text = {'Check '; 'colocalized'};
            app.btnColoRedoDiscarded = uibutton(app.ReviewcompletedanalysisPanel, 'push');
            app.btnColoRedoDiscarded.ButtonPushedFcn = createCallbackFcn(app, @btnColoRedoDiscardedButtonPushed, true);
            app.btnColoRedoDiscarded.Icon = '48px-Fruit-2cherries-cross.png';
            app.btnColoRedoDiscarded.Enable = 'off';
            app.btnColoRedoDiscarded.Tooltip = {'Manually screen non-colocalized objects'};
            app.btnColoRedoDiscarded.Text = {'Check not'; 'colocalized'};
            app.btnColoDonePlotDist = uibutton(app.ReviewcompletedanalysisPanel, 'push');
            app.btnColoDonePlotDist.ButtonPushedFcn = createCallbackFcn(app, @btnColoDonePlotDistButtonPushed, true);
            app.btnColoDonePlotDist.Icon = '48px-BarPlot.png';
            app.btnColoDonePlotDist.Enable = 'off';
            app.btnColoDonePlotDist.Tooltip = {'Plot distribution of colocalized objects'};
            app.btnColoDonePlotDist.Text = '';
            app.tblColoDone = uitable(app.ReviewcompletedanalysisPanel);
            app.tblColoDone.ColumnName = {'Parameter'; 'Value'};
            app.tblColoDone.RowName = {};
            app.btnColoDoneSave = uibutton(app.ReviewcompletedanalysisPanel, 'push');
            app.btnColoDoneSave.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneSaveButtonPushed, true);
            app.btnColoDoneSave.Icon = '48px-save.png';
            app.btnColoDoneSave.Enable = 'off';
            app.btnColoDoneSave.Tooltip = {'Save colocalization summary'};
            app.btnColoDoneSave.Text = '';
            app.PerformanewcolocalizationanalysisPanel = uipanel(app.ColocalizeTab);
            app.PerformanewcolocalizationanalysisPanel.Title = 'Perform a new colocalization analysis';
            app.lstColoObjects = uilistbox(app.PerformanewcolocalizationanalysisPanel);
            app.lstColoObjects.Items = {};
            app.lstColoObjects.Multiselect = 'on';
            app.lstColoObjects.ValueChangedFcn = createCallbackFcn(app, @lstColoObjectsValueChanged, true);
            app.lstColoObjects.Value = {};
            app.lstColoImages = uilistbox(app.PerformanewcolocalizationanalysisPanel);
            app.lstColoImages.Items = {};
            app.lstColoImages.Multiselect = 'on';
            app.lstColoImages.ValueChangedFcn = createCallbackFcn(app, @lstColoImagesValueChanged, true);
            app.lstColoImages.Value = {};
            app.btnColocalizeManual = uibutton(app.PerformanewcolocalizationanalysisPanel, 'push');
            app.btnColocalizeManual.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeManualButtonPushed, true);
            app.btnColocalizeManual.Icon = '48px-Fruit-3cherries.png';
            app.btnColocalizeManual.Enable = 'off';
            app.btnColocalizeManual.Tooltip = {'Manually choose whether objects are colocalized or not'};
            app.btnColocalizeManual.Text = {'Colocalize'; 'Manually'};
            app.btnColocalizeMask = uibutton(app.PerformanewcolocalizationanalysisPanel, 'push');
            app.btnColocalizeMask.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeMaskButtonPushed, true);
            app.btnColocalizeMask.Icon = '48px-Masks.png';
            app.btnColocalizeMask.Enable = 'off';
            app.btnColocalizeMask.Tooltip = {'Automatic colocalize objects overlapping with mask above threshold'};
            app.btnColocalizeMask.Text = {'Colocalize'; 'Binary Mask'};
            app.btnColocalizeNN = uibutton(app.PerformanewcolocalizationanalysisPanel, 'push');
            app.btnColocalizeNN.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeNNButtonPushed, true);
            app.btnColocalizeNN.Icon = '48px-Measure.png';
            app.btnColocalizeNN.Enable = 'off';
            app.btnColocalizeNN.Tooltip = {'Automatic colocalize nearest neighbor objects overlapping above threshold'};
            app.btnColocalizeNN.Text = {'Nearest'; 'Neighbor'};
            app.btnColocalizeEngulf = uibutton(app.PerformanewcolocalizationanalysisPanel, 'push');
            app.btnColocalizeEngulf.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeEngulfButtonPushed, true);
            app.btnColocalizeEngulf.Icon = '48px-PacMan.png';
            app.btnColocalizeEngulf.Enable = 'off';
            app.btnColocalizeEngulf.Tooltip = {'Count how many test objects are within each reference object'};
            app.btnColocalizeEngulf.Text = {'Engulfed'; 'Objects'};
            app.ColoSwitchVoxPerc = uiswitch(app.PerformanewcolocalizationanalysisPanel, 'slider');
            app.ColoSwitchVoxPerc.Items = {'Vox', '%'};
            app.ColoSwitchVoxPerc.Enable = 'off';
            app.ColoSwitchVoxPerc.Value = '%';
            app.WithinmLabel = uilabel(app.PerformanewcolocalizationanalysisPanel);
            app.WithinmLabel.Text = 'Within (µm)';
            app.ColoThresholdDist = uispinner(app.PerformanewcolocalizationanalysisPanel);
            app.ColoThresholdDist.Limits = [0 Inf];
            app.ColoThresholdDist.ValueDisplayFormat = '%.0f';
            app.ColoThresholdDist.Enable = 'off';
            app.ColoThresholdDist.Value = Inf;
            app.ObjectssharingatleastLabel = uilabel(app.PerformanewcolocalizationanalysisPanel);
            app.ObjectssharingatleastLabel.Text = 'Objects sharing at least';
            app.ColoSharedVoxNum = uispinner(app.PerformanewcolocalizationanalysisPanel);
            app.ColoSharedVoxNum.Limits = [1 Inf];
            app.ColoSharedVoxNum.ValueDisplayFormat = '%.0f';
            app.ColoSharedVoxNum.Enable = 'off';
            app.ColoSharedVoxNum.Value = 50;
            app.chkColoSaveAsNewObjects = uicheckbox(app.PerformanewcolocalizationanalysisPanel);
            app.chkColoSaveAsNewObjects.Text = 'Save colocalized objects';
            app.btnColoRefreshObjs = uibutton(app.PerformanewcolocalizationanalysisPanel, 'push');
            app.btnColoRefreshObjs.ButtonPushedFcn = createCallbackFcn(app, @btnColoRefreshObjsButtonPushed, true);
            app.btnColoRefreshObjs.Icon = '48px-View-refresh.png';
            app.btnColoRefreshObjs.Enable = 'off';
            app.btnColoRefreshObjs.Tooltip = {'Refresh list of available objects'};
            app.btnColoRefreshObjs.Text = 'Objects';
            app.btnColoRefreshImgs = uibutton(app.PerformanewcolocalizationanalysisPanel, 'push');
            app.btnColoRefreshImgs.ButtonPushedFcn = createCallbackFcn(app, @btnColoRefreshImgsButtonPushed, true);
            app.btnColoRefreshImgs.Icon = '48px-View-refresh.png';
            app.btnColoRefreshImgs.Enable = 'off';
            app.btnColoRefreshImgs.Tooltip = {'Refresh list of available images'};
            app.btnColoRefreshImgs.Text = 'Images';
            app.chkColoCenterOverlap = uicheckbox(app.PerformanewcolocalizationanalysisPanel);
            app.chkColoCenterOverlap.Enable = 'off';
            app.chkColoCenterOverlap.Text = 'Centroid must overlap';
            app.chkNotColoSaveAsNewObjects = uicheckbox(app.PerformanewcolocalizationanalysisPanel);
            app.chkNotColoSaveAsNewObjects.Text = 'Save not colocalized objects';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.CompletedSimulationsPanel = uipanel(app.SimulateTab);
            app.CompletedSimulationsPanel.Title = 'Completed Simulations';
            app.lstSimObjs = uilistbox(app.CompletedSimulationsPanel);
            app.lstSimObjs.Items = {};
            app.lstSimObjs.ValueChangedFcn = createCallbackFcn(app, @lstSimObjsValueChanged, true);
            app.lstSimObjs.Value = {};
            app.btnSimListRemove = uibutton(app.CompletedSimulationsPanel, 'push');
            app.btnSimListRemove.ButtonPushedFcn = createCallbackFcn(app, @btnSimListRemoveButtonPushed, true);
            app.btnSimListRemove.Icon = '48px-List-remove.png';
            app.btnSimListRemove.Enable = 'off';
            app.btnSimListRemove.Text = '';
            app.btnSimListClear = uibutton(app.CompletedSimulationsPanel, 'push');
            app.btnSimListClear.ButtonPushedFcn = createCallbackFcn(app, @btnSimListClearButtonPushed, true);
            app.btnSimListClear.Icon = '48px-Edit-clear.png';
            app.btnSimListClear.Enable = 'off';
            app.btnSimListClear.Text = '';
            app.btnSimPlotDist = uibutton(app.CompletedSimulationsPanel, 'push');
            app.btnSimPlotDist.ButtonPushedFcn = createCallbackFcn(app, @btnSimPlotDistButtonPushed, true);
            app.btnSimPlotDist.Icon = '48px-Histogram.png';
            app.btnSimPlotDist.Enable = 'off';
            app.btnSimPlotDist.Text = {'Plot'; 'Distribution'};
            app.FittingdistributionLabel_2 = uilabel(app.CompletedSimulationsPanel);
            app.FittingdistributionLabel_2.Text = 'Fitting distribution:';
            app.cmbSimFittingDist = uidropdown(app.CompletedSimulationsPanel);
            app.cmbSimFittingDist.Items = {'beta', 'burr', 'exponential', 'gamma', 'inversegaussian', 'logistic', 'loglogistic', 'lognormal', 'nakagami', 'negative binomial', 'normal', 'poisson', 'rayleigh', 'rician', 'weibull', 'kernel'};
            app.cmbSimFittingDist.Value = 'normal';
            app.cmbSimRename = uibutton(app.CompletedSimulationsPanel, 'push');
            app.cmbSimRename.ButtonPushedFcn = createCallbackFcn(app, @cmbSimRenameButtonPushed, true);
            app.cmbSimRename.Icon = '48px-Text-x-generic_with_pencil.png';
            app.cmbSimRename.Enable = 'off';
            app.cmbSimRename.Tooltip = {'Rename current objects'};
            app.cmbSimRename.Text = '';
            app.btnSimCopy = uibutton(app.CompletedSimulationsPanel, 'push');
            app.btnSimCopy.ButtonPushedFcn = createCallbackFcn(app, @btnSimCopyButtonPushed, true);
            app.btnSimCopy.Icon = '48px-Edit-copy.png';
            app.btnSimCopy.Enable = 'off';
            app.btnSimCopy.Tooltip = {'Duplicate current objects'};
            app.btnSimCopy.Text = '';
            app.GenerateasimulationPanel = uipanel(app.SimulateTab);
            app.GenerateasimulationPanel.Title = 'Generate a simulation';
            app.btnSimScramble = uibutton(app.GenerateasimulationPanel, 'push');
            app.btnSimScramble.ButtonPushedFcn = createCallbackFcn(app, @btnSimScrambleButtonPushed, true);
            app.btnSimScramble.Icon = '48px-dice.png';
            app.btnSimScramble.Text = 'Rearrange';
            app.NumberofiterationsSpinnerLabel = uilabel(app.GenerateasimulationPanel);
            app.NumberofiterationsSpinnerLabel.Text = 'Number of iterations';
            app.SimIterations = uispinner(app.GenerateasimulationPanel);
            app.SimIterations.Step = 10;
            app.SimIterations.Limits = [1 Inf];
            app.SimIterations.Value = 2000;
            app.TestedparameterLabel = uilabel(app.GenerateasimulationPanel);
            app.TestedparameterLabel.Text = 'Tested parameter';
            app.cmbSimParameter = uidropdown(app.GenerateasimulationPanel);
            app.cmbSimParameter.Items = {'Position', 'Size', 'Overlap'};
            app.cmbSimParameter.ValueChangedFcn = createCallbackFcn(app, @cmbSimParameterValueChanged, true);
            app.cmbSimParameter.Value = 'Position';
            app.SetoftestobjectsDropDownLabel = uilabel(app.GenerateasimulationPanel);
            app.SetoftestobjectsDropDownLabel.Text = 'Set of test objects';
            app.cmbSimTestObjects = uidropdown(app.GenerateasimulationPanel);
            app.cmbSimTestObjects.Items = {};
            app.cmbSimTestObjects.ValueChangedFcn = createCallbackFcn(app, @cmbSimTestObjectsValueChanged, true);
            app.cmbSimTestObjects.Value = {};
            app.ObjectstoprocessSpinnerLabel = uilabel(app.GenerateasimulationPanel);
            app.ObjectstoprocessSpinnerLabel.Text = '# Objects to process';
            app.SimTestHowManyObjs = uispinner(app.GenerateasimulationPanel);
            app.SimTestHowManyObjs.Step = 10;
            app.SimTestHowManyObjs.Limits = [0 Inf];
            app.btnSimAdd = uibutton(app.GenerateasimulationPanel, 'push');
            app.btnSimAdd.ButtonPushedFcn = createCallbackFcn(app, @btnSimAddButtonPushed, true);
            app.btnSimAdd.Icon = '48px-dice-add.png';
            app.btnSimAdd.Text = 'Insert';
            app.btnSimRemove = uibutton(app.GenerateasimulationPanel, 'push');
            app.btnSimRemove.ButtonPushedFcn = createCallbackFcn(app, @btnSimRemoveButtonPushed, true);
            app.btnSimRemove.Icon = '48px-dice-remove.png';
            app.btnSimRemove.Text = 'Remove';
            app.ComputedstatisticLabel = uilabel(app.GenerateasimulationPanel);
            app.ComputedstatisticLabel.Text = 'Computed statistic';
            app.cmbSimStatistic = uidropdown(app.GenerateasimulationPanel);
            app.cmbSimStatistic.Items = {'Nearest Neighbor Distance'};
            app.cmbSimStatistic.Value = 'Nearest Neighbor Distance';
            app.SecondsetoftestobjectsLabel = uilabel(app.GenerateasimulationPanel);
            app.SecondsetoftestobjectsLabel.Enable = 'off';
            app.SecondsetoftestobjectsLabel.Text = 'Second set of test objects';
            app.cmbSimTestObjects2 = uidropdown(app.GenerateasimulationPanel);
            app.cmbSimTestObjects2.Items = {};
            app.cmbSimTestObjects2.Enable = 'off';
            app.cmbSimTestObjects2.Value = {};
            app.btnSimSimulate = uibutton(app.GenerateasimulationPanel, 'push');
            app.btnSimSimulate.ButtonPushedFcn = createCallbackFcn(app, @btnSimSimulateButtonPushed, true);
            app.btnSimSimulate.Icon = '72px-Batch-process.png';
            app.btnSimSimulate.Text = 'Simulate';
            app.TypeofsecondtestsetSwitchLabel = uilabel(app.GenerateasimulationPanel);
            app.TypeofsecondtestsetSwitchLabel.Enable = 'off';
            app.TypeofsecondtestsetSwitchLabel.Text = 'Type of second test set';
            app.cmbSimTest2type = uiswitch(app.GenerateasimulationPanel, 'slider');
            app.cmbSimTest2type.Items = {'Objects', 'Mask'};
            app.cmbSimTest2type.ValueChangedFcn = createCallbackFcn(app, @cmbSimTest2typeValueChanged, true);
            app.cmbSimTest2type.Enable = 'off';
            app.cmbSimTest2type.Value = 'Objects';
            app.HypothesistestingPanel = uipanel(app.SimulateTab);
            app.HypothesistestingPanel.Title = 'Hypothesis testing';
            app.SignificancelevelSpinnerLabel = uilabel(app.HypothesistestingPanel);
            app.SignificancelevelSpinnerLabel.Text = 'Significance level';
            app.cmbSimSignificance = uispinner(app.HypothesistestingPanel);
            app.cmbSimSignificance.Step = 0.01;
            app.cmbSimSignificance.Limits = [0 1];
            app.cmbSimSignificance.Value = 0.05;
            app.NullhypothesisDropDownLabel = uilabel(app.HypothesistestingPanel);
            app.NullhypothesisDropDownLabel.Text = 'Null hypothesis';
            app.cmbSimNull = uidropdown(app.HypothesistestingPanel);
            app.cmbSimNull.Items = {'Empirical value is drawn from sampled random distribution', 'Empirical value is drawn from normal distribution fitting data'};
            app.cmbSimNull.Value = 'Empirical value is drawn from sampled random distribution';
            app.btnSimTest = uibutton(app.HypothesistestingPanel, 'push');
            app.btnSimTest.ButtonPushedFcn = createCallbackFcn(app, @btnSimTestButtonPushed, true);
            app.btnSimTest.Icon = '48px-scales.png';
            app.btnSimTest.Enable = 'off';
            app.btnSimTest.Text = 'Test';
            app.chkSimTestTwoTailed = uicheckbox(app.HypothesistestingPanel);
            app.chkSimTestTwoTailed.Text = 'Two tailed test';
            app.chkSimTestTwoTailed.Value = true;
            app.TrainNetTab = uitab(app.TabGroup);
            app.TrainNetTab.Title = 'Train Net';
            app.TrainingDatasetPanel = uipanel(app.TrainNetTab);
            app.TrainingDatasetPanel.Title = 'Projects to use as training data';
            app.lstTraining = uilistbox(app.TrainingDatasetPanel);
            app.lstTraining.Items = {};
            app.lstTraining.ValueChangedFcn = createCallbackFcn(app, @lstTrainingValueChanged, true);
            app.lstTraining.Value = {};
            app.btnTrainingAdd = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingAdd.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingAddButtonPushed, true);
            app.btnTrainingAdd.Icon = '48px-List-add.png';
            app.btnTrainingAdd.Tooltip = {'Add selected project'};
            app.btnTrainingAdd.Text = '';
            app.btnTrainingRemove = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingRemoveButtonPushed, true);
            app.btnTrainingRemove.Icon = '48px-List-remove.png';
            app.btnTrainingRemove.Enable = 'off';
            app.btnTrainingRemove.Tooltip = {'Remove item from list'};
            app.btnTrainingRemove.Text = '';
            app.btnTrainingUp = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingUp.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingUpButtonPushed, true);
            app.btnTrainingUp.Icon = '48px-Go-up.png';
            app.btnTrainingUp.Enable = 'off';
            app.btnTrainingUp.Text = '';
            app.btnTrainingDown = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingDown.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingDownButtonPushed, true);
            app.btnTrainingDown.Icon = '48px-Go-down.png';
            app.btnTrainingDown.Enable = 'off';
            app.btnTrainingDown.Text = '';
            app.btnTrainingTop = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingTop.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingTopButtonPushed, true);
            app.btnTrainingTop.Icon = '48px-Go-top.png';
            app.btnTrainingTop.Enable = 'off';
            app.btnTrainingTop.Text = '';
            app.btnTrainingBottom = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingBottom.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingBottomButtonPushed, true);
            app.btnTrainingBottom.Icon = '48px-Go-bottom.png';
            app.btnTrainingBottom.Enable = 'off';
            app.btnTrainingBottom.Text = '';
            app.btnTrainingClear = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingClear.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingClearButtonPushed, true);
            app.btnTrainingClear.Icon = '48px-Edit-clear.png';
            app.btnTrainingClear.Enable = 'off';
            app.btnTrainingClear.Tooltip = {'Clear all list items'};
            app.btnTrainingClear.Text = '';
            app.btnTrainingAddMulti = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingAddMulti.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingAddMultiButtonPushed, true);
            app.btnTrainingAddMulti.Icon = '48px-Add-Folder.png';
            app.btnTrainingAddMulti.Tooltip = {'Add all projects contained within selected folder'};
            app.btnTrainingAddMulti.Text = '';
            app.ObjectsLabel = uilabel(app.TrainingDatasetPanel);
            app.ObjectsLabel.Text = 'Objects:';
            app.cmbTrainingObjects = uidropdown(app.TrainingDatasetPanel);
            app.cmbTrainingObjects.Items = {};
            app.cmbTrainingObjects.Value = {};
            app.chkTrainingObjectsAll = uicheckbox(app.TrainingDatasetPanel);
            app.chkTrainingObjectsAll.ValueChangedFcn = createCallbackFcn(app, @chkTrainingObjectsAllValueChanged, true);
            app.chkTrainingObjectsAll.Text = 'Use all available object sets';
            app.TrainingsettingsPanel = uipanel(app.TrainNetTab);
            app.TrainingsettingsPanel.Title = 'Training settings';
            app.TrainingTestingratioLabel = uilabel(app.TrainingsettingsPanel);
            app.TrainingTestingratioLabel.Text = 'Training/Testing ratio';
            app.TrainSplitRatio = uispinner(app.TrainingsettingsPanel);
            app.TrainSplitRatio.Step = 5;
            app.TrainSplitRatio.Limits = [0 100];
            app.TrainSplitRatio.Value = 70;
            app.MiniBatchSizeLabel = uilabel(app.TrainingsettingsPanel);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size';
            app.TrainMiniBatchSize = uieditfield(app.TrainingsettingsPanel, 'numeric');
            app.TrainMiniBatchSize.Limits = [10 Inf];
            app.TrainMiniBatchSize.Value = 200;
            app.MaxEpochsLabel = uilabel(app.TrainingsettingsPanel);
            app.MaxEpochsLabel.Text = 'Max Epochs';
            app.TrainMaxEpochs = uieditfield(app.TrainingsettingsPanel, 'numeric');
            app.TrainMaxEpochs.Limits = [10 Inf];
            app.TrainMaxEpochs.Value = 20;
            app.MomentumLabel = uilabel(app.TrainingsettingsPanel);
            app.MomentumLabel.Text = 'Momentum';
            app.TrainMomentum = uispinner(app.TrainingsettingsPanel);
            app.TrainMomentum.Step = 5;
            app.TrainMomentum.Limits = [0 100];
            app.TrainMomentum.Value = 90;
            app.LearningrateLabel = uilabel(app.TrainingsettingsPanel);
            app.LearningrateLabel.Text = 'Learning rate';
            app.TrainInitialLearningRate = uispinner(app.TrainingsettingsPanel);
            app.TrainInitialLearningRate.Step = 0.01;
            app.TrainInitialLearningRate.Limits = [0.01 1];
            app.TrainInitialLearningRate.Value = 0.01;
            app.AugmenttrainingdataLabel = uilabel(app.TrainingsettingsPanel);
            app.AugmenttrainingdataLabel.Text = 'Augment training data';
            app.TrainingAugment = uiswitch(app.TrainingsettingsPanel, 'slider');
            app.ShuffletrainingdataLabel = uilabel(app.TrainingsettingsPanel);
            app.ShuffletrainingdataLabel.Text = 'Shuffle training data:';
            app.cmbNetShuffle = uidropdown(app.TrainingsettingsPanel);
            app.cmbNetShuffle.Items = {'once', 'never', 'every-epoch'};
            app.cmbNetShuffle.Value = 'once';
            app.ExecutionEnvironmentLabel = uilabel(app.TrainingsettingsPanel);
            app.ExecutionEnvironmentLabel.Text = 'Execution Environment:';
            app.cmbNetExecEnvironment = uidropdown(app.TrainingsettingsPanel);
            app.cmbNetExecEnvironment.Items = {'auto', 'cpu', 'gpu', 'multi-gpu', 'parallel'};
            app.cmbNetExecEnvironment.Value = 'auto';
            app.btnNetTrain = uibutton(app.TrainNetTab, 'push');
            app.btnNetTrain.ButtonPushedFcn = createCallbackFcn(app, @btnNetTrainPushed, true);
            app.btnNetTrain.Icon = '48px-monkey.png';
            app.btnNetTrain.Enable = 'off';
            app.btnNetTrain.Text = {'Train'; 'Network'};
            app.GenerateTrainingSetButton = uibutton(app.TrainNetTab, 'push');
            app.GenerateTrainingSetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrainingSetButtonPushed, true);
            app.GenerateTrainingSetButton.Icon = '48px-Training-set.png';
            app.GenerateTrainingSetButton.Enable = 'off';
            app.GenerateTrainingSetButton.Text = {'Generate'; 'Training Set'};
            app.NeuralNetworksPanel = uipanel(app.TrainNetTab);
            app.NeuralNetworksPanel.Title = 'Neural Networks';
            app.tblNeuralNet = uitable(app.NeuralNetworksPanel);
            app.tblNeuralNet.ColumnName = {'Net Name'; 'Target'; 'Type'; 'Model'; 'Trained'; 'Unique ID'};
            app.tblNeuralNet.RowName = {};
            app.tblNeuralNet.CellSelectionCallback = createCallbackFcn(app, @tblNeuralNetCellSelection, true);
            app.TargetLabel = uilabel(app.NeuralNetworksPanel);
            app.TargetLabel.Text = 'Target:';
            app.cmbNetTarget = uidropdown(app.NeuralNetworksPanel);
            app.cmbNetTarget.Items = {'Objects'};
            app.cmbNetTarget.Value = 'Objects';
            app.btnNetCreate = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetCreate.ButtonPushedFcn = createCallbackFcn(app, @btnNetCreateButtonPushed, true);
            app.btnNetCreate.Icon = '48px-new.png';
            app.btnNetCreate.Tooltip = {'Generate a new network of selected model'};
            app.btnNetCreate.Text = {'Create'; 'Network'};
            app.btnNetDelete = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetDelete.ButtonPushedFcn = createCallbackFcn(app, @btnNetDeleteButtonPushed, true);
            app.btnNetDelete.Icon = '48px-Delete.png';
            app.btnNetDelete.Enable = 'off';
            app.btnNetDelete.Tooltip = {'Delete selected network'};
            app.btnNetDelete.Text = '';
            app.btnNetClone = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetClone.ButtonPushedFcn = createCallbackFcn(app, @btnNetCloneButtonPushed, true);
            app.btnNetClone.Icon = '48px-copy.png';
            app.btnNetClone.Enable = 'off';
            app.btnNetClone.Tooltip = {'Duplicate selected network'};
            app.btnNetClone.Text = '';
            app.btnNetRename = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetRename.ButtonPushedFcn = createCallbackFcn(app, @btnNetRenameButtonPushed, true);
            app.btnNetRename.Icon = '48px-Edit.png';
            app.btnNetRename.Enable = 'off';
            app.btnNetRename.Tooltip = {'Rename selected network'};
            app.btnNetRename.Text = '';
            app.btnNetInfo = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetInfo.ButtonPushedFcn = createCallbackFcn(app, @btnNetInfoButtonPushed, true);
            app.btnNetInfo.Icon = '48px-info.png';
            app.btnNetInfo.Enable = 'off';
            app.btnNetInfo.Tooltip = {'Visualize network architecture'};
            app.btnNetInfo.Text = '';
            app.btnNetImport = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetImport.ButtonPushedFcn = createCallbackFcn(app, @btnNetImportButtonPushed, true);
            app.btnNetImport.Icon = '48px-import.png';
            app.btnNetImport.Tooltip = {'Import network from file'};
            app.btnNetImport.Text = '';
            app.btnNetExport = uibutton(app.NeuralNetworksPanel, 'push');
            app.btnNetExport.ButtonPushedFcn = createCallbackFcn(app, @btnNetExportButtonPushed, true);
            app.btnNetExport.Icon = '48px-export.png';
            app.btnNetExport.Enable = 'off';
            app.btnNetExport.Tooltip = {'Save a network copy to custom folder'};
            app.btnNetExport.Text = '';
            app.NetTypeDropDownLabel = uilabel(app.NeuralNetworksPanel);
            app.NetTypeDropDownLabel.Text = 'Net Type:';
            app.cmbNetType = uidropdown(app.NeuralNetworksPanel);
            app.cmbNetType.Items = {'Object classifier'};
            app.cmbNetType.Value = 'Object classifier';
            app.NetModelDropDownLabel = uilabel(app.NeuralNetworksPanel);
            app.NetModelDropDownLabel.Text = 'Net Model:';
            app.cmbNetModel = uidropdown(app.NeuralNetworksPanel);
            app.cmbNetModel.Items = {'AlexNet', 'ResNet-50'};
            app.cmbNetModel.Value = 'AlexNet';
            app.btnNetTest = uibutton(app.TrainNetTab, 'push');
            app.btnNetTest.ButtonPushedFcn = createCallbackFcn(app, @btnNetTestButtonPushed, true);
            app.btnNetTest.Icon = '48px-unblock-granted.png';
            app.btnNetTest.Enable = 'off';
            app.btnNetTest.Text = {'Test'; 'Network'};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.lblObjectFinder = uilabel(app.AboutTab);
            app.lblObjectFinder.Text = 'Object Finder';
            app.lblCopyright = uilabel(app.AboutTab);
            app.lblCopyright.Text = '© 2016-2020 Luca Della Santina';
            app.lblVersion = uilabel(app.AboutTab);
            app.lblVersion.Text = 'Version xx.yy.zz';
            app.txtLicense = uitextarea(app.AboutTab);

            app.txtLicense.Value = {'GNU GENERAL PUBLIC LICENSE'; 'Version 3'; ' 29 June 2007'; ''; 'Copyright © 2007 Free Software Foundation'; ' Inc. <https://fsf.org/>'; ''; 'Everyone is permitted to copy and distribute verbatim copies of this license document'; ' but changing it is not allowed.'; ''; 'Preamble'; 'The GNU General Public License is a free'; ' copyleft license for software and other kinds of works.'; ''; 'The licenses for most software and other practical works are designed to take away your freedom to share and change the works. By contrast'; ' the GNU General Public License is intended to guarantee your freedom to share and change all versions of a program--to make sure it remains free software for all its users. We'; ' the Free Software Foundation'; ' use the GNU General Public License for most of our software'; ' it applies also to any other work released this way by its authors. You can apply it to your programs too.'; ''; 'When we speak of free software'; ' we are referring to freedom'; ' not price. Our General Public Licenses are designed to make sure that you have the freedom to distribute copies of free software (and charge for them if you wish)'; ' that you receive source code or can get it if you want it'; ' that you can change the software or use pieces of it in new free programs'; ' and that you know you can do these things.'; ''; 'To protect your rights'; ' we need to prevent others from denying you these rights or asking you to surrender the rights. Therefore'; ' you have certain responsibilities if you distribute copies of the software'; ' or if you modify it: responsibilities to respect the freedom of others.'; ''; 'For example'; ' if you distribute copies of such a program'; ' whether gratis or for a fee'; ' you must pass on to the recipients the same freedoms that you received. You must make sure that they'; ' too'; ' receive or can get the source code. And you must show them these terms so they know their rights.'; ''; 'Developers that use the GNU GPL protect your rights with two steps: (1) assert copyright on the software'; ' and (2) offer you this License giving you legal permission to copy'; ' distribute and/or modify it.'; ''; 'For the developers'' and authors'' protection'; ' the GPL clearly explains that there is no warranty for this free software. For both users'' and authors'' sake'; ' the GPL requires that modified versions be marked as changed'; ' so that their problems will not be attributed erroneously to authors of previous versions.'; ''; 'Some devices are designed to deny users access to install or run modified versions of the software inside them'; ' although the manufacturer can do so. This is fundamentally incompatible with the aim of protecting users'' freedom to change the software. The systematic pattern of such abuse occurs in the area of products for individuals to use'; ' which is precisely where it is most unacceptable. Therefore'; ' we have designed this version of the GPL to prohibit the practice for those products. If such problems arise substantially in other domains'; ' we stand ready to extend this provision to those domains in future versions of the GPL'; ' as needed to protect the freedom of users.'; ''; 'Finally'; ' every program is threatened constantly by software patents. States should not allow patents to restrict development and use of software on general-purpose computers'; ' but in those that do'; ' we wish to avoid the special danger that patents applied to a free program could make it effectively proprietary. To prevent this'; ' the GPL assures that patents cannot be used to render the program non-free.'; ''; 'The precise terms and conditions for copying'; ' distribution and modification follow.'; ''; 'TERMS AND CONDITIONS'; '0. Definitions.'; '“This License” refers to version 3 of the GNU General Public License.'; ''; '“Copyright” also means copyright-like laws that apply to other kinds of works'; ' such as semiconductor masks.'; ''; '“The Program” refers to any copyrightable work licensed under this License. Each licensee is addressed as “you”. “Licensees” and “recipients” may be individuals or organizations.'; ''; 'To “modify” a work means to copy from or adapt all or part of the work in a fashion requiring copyright permission'; ' other than the making of an exact copy. The resulting work is called a “modified version” of the earlier work or a work “based on” the earlier work.'; ''; 'A “covered work” means either the unmodified Program or a work based on the Program.'; ''; 'To “propagate” a work means to do anything with it that'; ' without permission'; ' would make you directly or secondarily liable for infringement under applicable copyright law'; ' except executing it on a computer or modifying a private copy. Propagation includes copying'; ' distribution (with or without modification)'; ' making available to the public'; ' and in some countries other activities as well.'; ''; 'To “convey” a work means any kind of propagation that enables other parties to make or receive copies. Mere interaction with a user through a computer network'; ' with no transfer of a copy'; ' is not conveying.'; ''; 'An interactive user interface displays “Appropriate Legal Notices” to the extent that it includes a convenient and prominently visible feature that (1) displays an appropriate copyright notice'; ' and (2) tells the user that there is no warranty for the work (except to the extent that warranties are provided)'; ' that licensees may convey the work under this License'; ' and how to view a copy of this License. If the interface presents a list of user commands or options'; ' such as a menu'; ' a prominent item in the list meets this criterion.'; ''; '1. Source Code.'; 'The “source code” for a work means the preferred form of the work for making modifications to it. “Object code” means any non-source form of a work.'; ''; 'A “Standard Interface” means an interface that either is an official standard defined by a recognized standards body'; ' or'; ' in the case of interfaces specified for a particular programming language'; ' one that is widely used among developers working in that language.'; ''; 'The “System Libraries” of an executable work include anything'; ' other than the work as a whole'; ' that (a) is included in the normal form of packaging a Major Component'; ' but which is not part of that Major Component'; ' and (b) serves only to enable use of the work with that Major Component'; ' or to implement a Standard Interface for which an implementation is available to the public in source code form. A “Major Component”'; ' in this context'; ' means a major essential component (kernel'; ' window system'; ' and so on) of the specific operating system (if any) on which the executable work runs'; ' or a compiler used to produce the work'; ' or an object code interpreter used to run it.'; ''; 'The “Corresponding Source” for a work in object code form means all the source code needed to generate'; ' install'; ' and (for an executable work) run the object code and to modify the work'; ' including scripts to control those activities. However'; ' it does not include the work''s System Libraries'; ' or general-purpose tools or generally available free programs which are used unmodified in performing those activities but which are not part of the work. For example'; ' Corresponding Source includes interface definition files associated with source files for the work'; ' and the source code for shared libraries and dynamically linked subprograms that the work is specifically designed to require'; ' such as by intimate data communication or control flow between those subprograms and other parts of the work.'; ''; 'The Corresponding Source need not include anything that users can regenerate automatically from other parts of the Corresponding Source.'; ''; 'The Corresponding Source for a work in source code form is that same work.'; ''; '2. Basic Permissions.'; 'All rights granted under this License are granted for the term of copyright on the Program'; ' and are irrevocable provided the stated conditions are met. This License explicitly affirms your unlimited permission to run the unmodified Program. The output from running a covered work is covered by this License only if the output'; ' given its content'; ' constitutes a covered work. This License acknowledges your rights of fair use or other equivalent'; ' as provided by copyright law.'; ''; 'You may make'; ' run and propagate covered works that you do not convey'; ' without conditions so long as your license otherwise remains in force. You may convey covered works to others for the sole purpose of having them make modifications exclusively for you'; ' or provide you with facilities for running those works'; ' provided that you comply with the terms of this License in conveying all material for which you do not control copyright. Those thus making or running the covered works for you must do so exclusively on your behalf'; ' under your direction and control'; ' on terms that prohibit them from making any copies of your copyrighted material outside their relationship with you.'; ''; 'Conveying under any other circumstances is permitted solely under the conditions stated below. Sublicensing is not allowed'; ' section 10 makes it unnecessary.'; ''; '3. Protecting Users'' Legal Rights From Anti-Circumvention Law.'; 'No covered work shall be deemed part of an effective technological measure under any applicable law fulfilling obligations under article 11 of the WIPO copyright treaty adopted on 20 December 1996'; ' or similar laws prohibiting or restricting circumvention of such measures.'; ''; 'When you convey a covered work'; ' you waive any legal power to forbid circumvention of technological measures to the extent such circumvention is effected by exercising rights under this License with respect to the covered work'; ' and you disclaim any intention to limit operation or modification of the work as a means of enforcing'; ' against the work''s users'; ' your or third parties'' legal rights to forbid circumvention of technological measures.'; ''; '4. Conveying Verbatim Copies.'; 'You may convey verbatim copies of the Program''s source code as you receive it'; ' in any medium'; ' provided that you conspicuously and appropriately publish on each copy an appropriate copyright notice'; ' keep intact all notices stating that this License and any non-permissive terms added in accord with section 7 apply to the code'; ' keep intact all notices of the absence of any warranty'; ' and give all recipients a copy of this License along with the Program.'; ''; 'You may charge any price or no price for each copy that you convey'; ' and you may offer support or warranty protection for a fee.'; ''; '5. Conveying Modified Source Versions.'; 'You may convey a work based on the Program'; ' or the modifications to produce it from the Program'; ' in the form of source code under the terms of section 4'; ' provided that you also meet all of these conditions:'; ''; 'a) The work must carry prominent notices stating that you modified it'; ' and giving a relevant date.'; 'b) The work must carry prominent notices stating that it is released under this License and any conditions added under section 7. This requirement modifies the requirement in section 4 to “keep intact all notices”.'; 'c) You must license the entire work'; ' as a whole'; ' under this License to anyone who comes into possession of a copy. This License will therefore apply'; ' along with any applicable section 7 additional terms'; ' to the whole of the work'; ' and all its parts'; ' regardless of how they are packaged. This License gives no permission to license the work in any other way'; ' but it does not invalidate such permission if you have separately received it.'; 'd) If the work has interactive user interfaces'; ' each must display Appropriate Legal Notices'; ' however'; ' if the Program has interactive interfaces that do not display Appropriate Legal Notices'; ' your work need not make them do so.'; 'A compilation of a covered work with other separate and independent works'; ' which are not by their nature extensions of the covered work'; ' and which are not combined with it such as to form a larger program'; ' in or on a volume of a storage or distribution medium'; ' is called an “aggregate” if the compilation and its resulting copyright are not used to limit the access or legal rights of the compilation''s users beyond what the individual works permit. Inclusion of a covered work in an aggregate does not cause this License to apply to the other parts of the aggregate.'; ''; '6. Conveying Non-Source Forms.'; 'You may convey a covered work in object code form under the terms of sections 4 and 5'; ' provided that you also convey the machine-readable Corresponding Source under the terms of this License'; ' in one of these ways:'; ''; 'a) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by the Corresponding Source fixed on a durable physical medium customarily used for software interchange.'; 'b) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by a written offer'; ' valid for at least three years and valid for as long as you offer spare parts or customer support for that product model'; ' to give anyone who possesses the object code either (1) a copy of the Corresponding Source for all the software in the product that is covered by this License'; ' on a durable physical medium customarily used for software interchange'; ' for a price no more than your reasonable cost of physically performing this conveying of source'; ' or (2) access to copy the Corresponding Source from a network server at no charge.'; 'c) Convey individual copies of the object code with a copy of the written offer to provide the Corresponding Source. This alternative is allowed only occasionally and noncommercially'; ' and only if you received the object code with such an offer'; ' in accord with subsection 6b.'; 'd) Convey the object code by offering access from a designated place (gratis or for a charge)'; ' and offer equivalent access to the Corresponding Source in the same way through the same place at no further charge. You need not require recipients to copy the Corresponding Source along with the object code. If the place to copy the object code is a network server'; ' the Corresponding Source may be on a different server (operated by you or a third party) that supports equivalent copying facilities'; ' provided you maintain clear directions next to the object code saying where to find the Corresponding Source. Regardless of what server hosts the Corresponding Source'; ' you remain obligated to ensure that it is available for as long as needed to satisfy these requirements.'; 'e) Convey the object code using peer-to-peer transmission'; ' provided you inform other peers where the object code and Corresponding Source of the work are being offered to the general public at no charge under subsection 6d.'; 'A separable portion of the object code'; ' whose source code is excluded from the Corresponding Source as a System Library'; ' need not be included in conveying the object code work.'; ''; 'A “User Product” is either (1) a “consumer product”'; ' which means any tangible personal property which is normally used for personal'; ' family'; ' or household purposes'; ' or (2) anything designed or sold for incorporation into a dwelling. In determining whether a product is a consumer product'; ' doubtful cases shall be resolved in favor of coverage. For a particular product received by a particular user'; ' “normally used” refers to a typical or common use of that class of product'; ' regardless of the status of the particular user or of the way in which the particular user actually uses'; ' or expects or is expected to use'; ' the product. A product is a consumer product regardless of whether the product has substantial commercial'; ' industrial or non-consumer uses'; ' unless such uses represent the only significant mode of use of the product.'; ''; '“Installation Information” for a User Product means any methods'; ' procedures'; ' authorization keys'; ' or other information required to install and execute modified versions of a covered work in that User Product from a modified version of its Corresponding Source. The information must suffice to ensure that the continued functioning of the modified object code is in no case prevented or interfered with solely because modification has been made.'; ''; 'If you convey an object code work under this section in'; ' or with'; ' or specifically for use in'; ' a User Product'; ' and the conveying occurs as part of a transaction in which the right of possession and use of the User Product is transferred to the recipient in perpetuity or for a fixed term (regardless of how the transaction is characterized)'; ' the Corresponding Source conveyed under this section must be accompanied by the Installation Information. But this requirement does not apply if neither you nor any third party retains the ability to install modified object code on the User Product (for example'; ' the work has been installed in ROM).'; ''; 'The requirement to provide Installation Information does not include a requirement to continue to provide support service'; ' warranty'; ' or updates for a work that has been modified or installed by the recipient'; ' or for the User Product in which it has been modified or installed. Access to a network may be denied when the modification itself materially and adversely affects the operation of the network or violates the rules and protocols for communication across the network.'; ''; 'Corresponding Source conveyed'; ' and Installation Information provided'; ' in accord with this section must be in a format that is publicly documented (and with an implementation available to the public in source code form)'; ' and must require no special password or key for unpacking'; ' reading or copying.'; ''; '7. Additional Terms.'; '“Additional permissions” are terms that supplement the terms of this License by making exceptions from one or more of its conditions. Additional permissions that are applicable to the entire Program shall be treated as though they were included in this License'; ' to the extent that they are valid under applicable law. If additional permissions apply only to part of the Program'; ' that part may be used separately under those permissions'; ' but the entire Program remains governed by this License without regard to the additional permissions.'; ''; 'When you convey a copy of a covered work'; ' you may at your option remove any additional permissions from that copy'; ' or from any part of it. (Additional permissions may be written to require their own removal in certain cases when you modify the work.) You may place additional permissions on material'; ' added by you to a covered work'; ' for which you have or can give appropriate copyright permission.'; ''; 'Notwithstanding any other provision of this License'; ' for material you add to a covered work'; ' you may (if authorized by the copyright holders of that material) supplement the terms of this License with terms:'; ''; 'a) Disclaiming warranty or limiting liability differently from the terms of sections 15 and 16 of this License'; ' or'; 'b) Requiring preservation of specified reasonable legal notices or author attributions in that material or in the Appropriate Legal Notices displayed by works containing it'; ' or'; 'c) Prohibiting misrepresentation of the origin of that material'; ' or requiring that modified versions of such material be marked in reasonable ways as different from the original version'; ' or'; 'd) Limiting the use for publicity purposes of names of licensors or authors of the material'; ' or'; 'e) Declining to grant rights under trademark law for use of some trade names'; ' trademarks'; ' or service marks'; ' or'; 'f) Requiring indemnification of licensors and authors of that material by anyone who conveys the material (or modified versions of it) with contractual assumptions of liability to the recipient'; ' for any liability that these contractual assumptions directly impose on those licensors and authors.'; 'All other non-permissive additional terms are considered “further restrictions” within the meaning of section 10. If the Program as you received it'; ' or any part of it'; ' contains a notice stating that it is governed by this License along with a term that is a further restriction'; ' you may remove that term. If a license document contains a further restriction but permits relicensing or conveying under this License'; ' you may add to a covered work material governed by the terms of that license document'; ' provided that the further restriction does not survive such relicensing or conveying.'; ''; 'If you add terms to a covered work in accord with this section'; ' you must place'; ' in the relevant source files'; ' a statement of the additional terms that apply to those files'; ' or a notice indicating where to find the applicable terms.'; ''; 'Additional terms'; ' permissive or non-permissive'; ' may be stated in the form of a separately written license'; ' or stated as exceptions'; ' the above requirements apply either way.'; ''; '8. Termination.'; 'You may not propagate or modify a covered work except as expressly provided under this License. Any attempt otherwise to propagate or modify it is void'; ' and will automatically terminate your rights under this License (including any patent licenses granted under the third paragraph of section 11).'; ''; 'However'; ' if you cease all violation of this License'; ' then your license from a particular copyright holder is reinstated (a) provisionally'; ' unless and until the copyright holder explicitly and finally terminates your license'; ' and (b) permanently'; ' if the copyright holder fails to notify you of the violation by some reasonable means prior to 60 days after the cessation.'; ''; 'Moreover'; ' your license from a particular copyright holder is reinstated permanently if the copyright holder notifies you of the violation by some reasonable means'; ' this is the first time you have received notice of violation of this License (for any work) from that copyright holder'; ' and you cure the violation prior to 30 days after your receipt of the notice.'; ''; 'Termination of your rights under this section does not terminate the licenses of parties who have received copies or rights from you under this License. If your rights have been terminated and not permanently reinstated'; ' you do not qualify to receive new licenses for the same material under section 10.'; ''; '9. Acceptance Not Required for Having Copies.'; 'You are not required to accept this License in order to receive or run a copy of the Program. Ancillary propagation of a covered work occurring solely as a consequence of using peer-to-peer transmission to receive a copy likewise does not require acceptance. However'; ' nothing other than this License grants you permission to propagate or modify any covered work. These actions infringe copyright if you do not accept this License. Therefore'; ' by modifying or propagating a covered work'; ' you indicate your acceptance of this License to do so.'; ''; '10. Automatic Licensing of Downstream Recipients.'; 'Each time you convey a covered work'; ' the recipient automatically receives a license from the original licensors'; ' to run'; ' modify and propagate that work'; ' subject to this License. You are not responsible for enforcing compliance by third parties with this License.'; ''; 'An “entity transaction” is a transaction transferring control of an organization'; ' or substantially all assets of one'; ' or subdividing an organization'; ' or merging organizations. If propagation of a covered work results from an entity transaction'; ' each party to that transaction who receives a copy of the work also receives whatever licenses to the work the party''s predecessor in interest had or could give under the previous paragraph'; ' plus a right to possession of the Corresponding Source of the work from the predecessor in interest'; ' if the predecessor has it or can get it with reasonable efforts.'; ''; 'You may not impose any further restrictions on the exercise of the rights granted or affirmed under this License. For example'; ' you may not impose a license fee'; ' royalty'; ' or other charge for exercise of rights granted under this License'; ' and you may not initiate litigation (including a cross-claim or counterclaim in a lawsuit) alleging that any patent claim is infringed by making'; ' using'; ' selling'; ' offering for sale'; ' or importing the Program or any portion of it.'; ''; '11. Patents.'; 'A “contributor” is a copyright holder who authorizes use under this License of the Program or a work on which the Program is based. The work thus licensed is called the contributor''s “contributor version”.'; ''; 'A contributor''s “essential patent claims” are all patent claims owned or controlled by the contributor'; ' whether already acquired or hereafter acquired'; ' that would be infringed by some manner'; ' permitted by this License'; ' of making'; ' using'; ' or selling its contributor version'; ' but do not include claims that would be infringed only as a consequence of further modification of the contributor version. For purposes of this definition'; ' “control” includes the right to grant patent sublicenses in a manner consistent with the requirements of this License.'; ''; 'Each contributor grants you a non-exclusive'; ' worldwide'; ' royalty-free patent license under the contributor''s essential patent claims'; ' to make'; ' use'; ' sell'; ' offer for sale'; ' import and otherwise run'; ' modify and propagate the contents of its contributor version.'; ''; 'In the following three paragraphs'; ' a “patent license” is any express agreement or commitment'; ' however denominated'; ' not to enforce a patent (such as an express permission to practice a patent or covenant not to sue for patent infringement). To “grant” such a patent license to a party means to make such an agreement or commitment not to enforce a patent against the party.'; ''; 'If you convey a covered work'; ' knowingly relying on a patent license'; ' and the Corresponding Source of the work is not available for anyone to copy'; ' free of charge and under the terms of this License'; ' through a publicly available network server or other readily accessible means'; ' then you must either (1) cause the Corresponding Source to be so available'; ' or (2) arrange to deprive yourself of the benefit of the patent license for this particular work'; ' or (3) arrange'; ' in a manner consistent with the requirements of this License'; ' to extend the patent license to downstream recipients. “Knowingly relying” means you have actual knowledge that'; ' but for the patent license'; ' your conveying the covered work in a country'; ' or your recipient''s use of the covered work in a country'; ' would infringe one or more identifiable patents in that country that you have reason to believe are valid.'; ''; 'If'; ' pursuant to or in connection with a single transaction or arrangement'; ' you convey'; ' or propagate by procuring conveyance of'; ' a covered work'; ' and grant a patent license to some of the parties receiving the covered work authorizing them to use'; ' propagate'; ' modify or convey a specific copy of the covered work'; ' then the patent license you grant is automatically extended to all recipients of the covered work and works based on it.'; ''; 'A patent license is “discriminatory” if it does not include within the scope of its coverage'; ' prohibits the exercise of'; ' or is conditioned on the non-exercise of one or more of the rights that are specifically granted under this License. You may not convey a covered work if you are a party to an arrangement with a third party that is in the business of distributing software'; ' under which you make payment to the third party based on the extent of your activity of conveying the work'; ' and under which the third party grants'; ' to any of the parties who would receive the covered work from you'; ' a discriminatory patent license (a) in connection with copies of the covered work conveyed by you (or copies made from those copies)'; ' or (b) primarily for and in connection with specific products or compilations that contain the covered work'; ' unless you entered into that arrangement'; ' or that patent license was granted'; ' prior to 28 March 2007.'; ''; 'Nothing in this License shall be construed as excluding or limiting any implied license or other defenses to infringement that may otherwise be available to you under applicable patent law.'; ''; '12. No Surrender of Others'' Freedom.'; 'If conditions are imposed on you (whether by court order'; ' agreement or otherwise) that contradict the conditions of this License'; ' they do not excuse you from the conditions of this License. If you cannot convey a covered work so as to satisfy simultaneously your obligations under this License and any other pertinent obligations'; ' then as a consequence you may not convey it at all. For example'; ' if you agree to terms that obligate you to collect a royalty for further conveying from those to whom you convey the Program'; ' the only way you could satisfy both those terms and this License would be to refrain entirely from conveying the Program.'; ''; '13. Use with the GNU Affero General Public License.'; 'Notwithstanding any other provision of this License'; ' you have permission to link or combine any covered work with a work licensed under version 3 of the GNU Affero General Public License into a single combined work'; ' and to convey the resulting work. The terms of this License will continue to apply to the part which is the covered work'; ' but the special requirements of the GNU Affero General Public License'; ' section 13'; ' concerning interaction through a network will apply to the combination as such.'; ''; '14. Revised Versions of this License.'; 'The Free Software Foundation may publish revised and/or new versions of the GNU General Public License from time to time. Such new versions will be similar in spirit to the present version'; ' but may differ in detail to address new problems or concerns.'; ''; 'Each version is given a distinguishing version number. If the Program specifies that a certain numbered version of the GNU General Public License “or any later version” applies to it'; ' you have the option of following the terms and conditions either of that numbered version or of any later version published by the Free Software Foundation. If the Program does not specify a version number of the GNU General Public License'; ' you may choose any version ever published by the Free Software Foundation.'; ''; 'If the Program specifies that a proxy can decide which future versions of the GNU General Public License can be used'; ' that proxy''s public statement of acceptance of a version permanently authorizes you to choose that version for the Program.'; ''; 'Later license versions may give you additional or different permissions. However'; ' no additional obligations are imposed on any author or copyright holder as a result of your choosing to follow a later version.'; ''; '15. Disclaimer of Warranty.'; 'THERE IS NO WARRANTY FOR THE PROGRAM'; ' TO THE EXTENT PERMITTED BY APPLICABLE LAW. EXCEPT WHEN OTHERWISE STATED IN WRITING THE COPYRIGHT HOLDERS AND/OR OTHER PARTIES PROVIDE THE PROGRAM “AS IS” WITHOUT WARRANTY OF ANY KIND'; ' EITHER EXPRESSED OR IMPLIED'; ' INCLUDING'; ' BUT NOT LIMITED TO'; ' THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE. THE ENTIRE RISK AS TO THE QUALITY AND PERFORMANCE OF THE PROGRAM IS WITH YOU. SHOULD THE PROGRAM PROVE DEFECTIVE'; ' YOU ASSUME THE COST OF ALL NECESSARY SERVICING'; ' REPAIR OR CORRECTION.'; ''; '16. Limitation of Liability.'; 'IN NO EVENT UNLESS REQUIRED BY APPLICABLE LAW OR AGREED TO IN WRITING WILL ANY COPYRIGHT HOLDER'; ' OR ANY OTHER PARTY WHO MODIFIES AND/OR CONVEYS THE PROGRAM AS PERMITTED ABOVE'; ' BE LIABLE TO YOU FOR DAMAGES'; ' INCLUDING ANY GENERAL'; ' SPECIAL'; ' INCIDENTAL OR CONSEQUENTIAL DAMAGES ARISING OUT OF THE USE OR INABILITY TO USE THE PROGRAM (INCLUDING BUT NOT LIMITED TO LOSS OF DATA OR DATA BEING RENDERED INACCURATE OR LOSSES SUSTAINED BY YOU OR THIRD PARTIES OR A FAILURE OF THE PROGRAM TO OPERATE WITH ANY OTHER PROGRAMS)'; ' EVEN IF SUCH HOLDER OR OTHER PARTY HAS BEEN ADVISED OF THE POSSIBILITY OF SUCH DAMAGES.'; ''; '17. Interpretation of Sections 15 and 16.'; 'If the disclaimer of warranty and limitation of liability provided above cannot be given local legal effect according to their terms'; ' reviewing courts shall apply local law that most closely approximates an absolute waiver of all civil liability in connection with the Program'; ' unless a warranty or assumption of liability accompanies a copy of the Program in return for a fee.'; ''; 'END OF TERMS AND CONDITIONS'; ''; 'How to Apply These Terms to Your New Programs'; 'If you develop a new program'; ' and you want it to be of the greatest possible use to the public'; ' the best way to achieve this is to make it free software which everyone can redistribute and change under these terms.'; ''; 'To do so'; ' attach the following notices to the program. It is safest to attach them to the start of each source file to most effectively state the exclusion of warranty'; ' and each file should have at least the “copyright” line and a pointer to where the full notice is found.'; ''; '    <one line to give the program''s name and a brief idea of what it does.>'; '    Copyright (C) <year>  <name of author>'; ''; '    This program is free software: you can redistribute it and/or modify'; '    it under the terms of the GNU General Public License as published by'; '    the Free Software Foundation'; ' either version 3 of the License'; ' or'; '    (at your option) any later version.'; ''; '    This program is distributed in the hope that it will be useful'; ''; '    but WITHOUT ANY WARRANTY'; ' without even the implied warranty of'; '    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; '    GNU General Public License for more details.'; ''; '    You should have received a copy of the GNU General Public License'; '    along with this program.  If not'; ' see <https://www.gnu.org/licenses/>.'; 'Also add information on how to contact you by electronic and paper mail.'; ''; 'If the program does terminal interaction'; ' make it output a short notice like this when it starts in an interactive mode:'; ''; '    <program>  Copyright (C) <year>  <name of author>'; '    This program comes with ABSOLUTELY NO WARRANTY'; ' for details type `show w''.'; '    This is free software'; ' and you are welcome to redistribute it'; '    under certain conditions'; ' type `show c'' for details.'; 'The hypothetical commands `show w'' and `show c'' should show the appropriate parts of the General Public License. Of course'; ' your program''s commands might be different'; ' for a GUI interface'; ' you would use an “about box”.'; ''; 'You should also get your employer (if you work as a programmer) or school'; ' if any'; ' to sign a “copyright disclaimer” for the program'; ' if necessary. For more information on this'; ' and how to apply and follow the GNU GPL'; ' see <https://www.gnu.org/licenses/>.'; ''; 'The GNU General Public License does not permit incorporating your program into proprietary programs. If your program is a subroutine library'; ' you may consider it more useful to permit linking proprietary applications with the library. If this is what you want to do'; ' use the GNU Lesser General Public License instead of this License. But first'; ' please read <https://www.gnu.org/licenses/why-not-lgpl.html>.'};
            app.btnReportBug = uibutton(app.AboutTab, 'push');
            app.btnReportBug.ButtonPushedFcn = createCallbackFcn(app, @btnReportBugButtonPushed, true);
            app.btnReportBug.Icon = 'bug.png';
            app.btnReportBug.Text = 'Report an issue';
            app.btnHomePage = uibutton(app.AboutTab, 'push');
            app.btnHomePage.ButtonPushedFcn = createCallbackFcn(app, @btnHomePageButtonPushed, true);
            app.btnHomePage.Icon = '48px-web-browser.png';
            app.btnHomePage.Text = 'Visit home page';
            app.btnUserManual = uibutton(app.AboutTab, 'push');
            app.btnUserManual.ButtonPushedFcn = createCallbackFcn(app, @btnUserManualButtonPushed, true);
            app.btnUserManual.Icon = '48px-Manual.png';
            app.btnUserManual.Text = 'User Manual';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'app_icon_big.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjectFinder

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end